classdef yoosh_signal_testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        GainEditFieldLabel       matlab.ui.control.Label
        GainEditField            matlab.ui.control.NumericEditField
        FrequancyEditFieldLabel  matlab.ui.control.Label
        FrequancyEditField       matlab.ui.control.NumericEditField
        StartButton              matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        SignalButtonGroup        matlab.ui.container.ButtonGroup
        sinButton                matlab.ui.control.RadioButton
        cosButton                matlab.ui.control.RadioButton
        AttenuationKnobLabel     matlab.ui.control.Label
        AttenuationKnob          matlab.ui.control.Knob
        SelectedEditFieldLabel   matlab.ui.control.Label
        text                     matlab.ui.control.EditField
        attenuationText          matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            A = app.GainEditField.Value;
            f = app.FrequancyEditField.Value;
            attenuation = app.AttenuationKnob.Value;
            signal = app.text.Value;
            
            t = 0 : 0.01 : 2*pi;
            w = 2 * pi * f;
     
            if(signal == 'sin signal')
            y1 = A*sin(w*t)
            y2 = attenuation*A*sin(w*t)
            plot(app.UIAxes,t,y1)
            plot(app.UIAxes2,t,y2)
            
            elseif(signal == 'cos signal')
            y1 = A*cos(w*t)
            y2 = attenuation*A*cos(w*t)
            plot(app.UIAxes,t,y1)
            plot(app.UIAxes2,t,y2)
            end
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.GainEditField.Value = 0;
            app.FrequancyEditField.Value = 0;
            app.AttenuationKnob.Value = 1;
            app.attenuationText.Value = 1;
            
            A = app.GainEditField.Value;
            f = app.FrequancyEditField.Value;
            t = 0 : 0.01 : 2*pi;
            w = 2 * pi * f;
     
            y1 = A*sin(w*t)
            y2 = 1/2*A*sin(w*t)
            plot(app.UIAxes,t,y1)
            plot(app.UIAxes2,t,y2)
        end

        % Selection changed function: SignalButtonGroup
        function SignalButtonGroupSelectionChanged(app, event)
            selectedButton = app.SignalButtonGroup.SelectedObject;
            value = selectedButton.Text 
            
            switch value
                case 'sin'
                    app.text.Value = 'sin signal'
                case 'cos'
                    app.text.Value = 'cos signal'
            end
        end

        % Value changed function: attenuationText
        function attenuationTextValueChanged(app, event)
            value = app.attenuationText.Value;
            app.AttenuationKnob.Value = value
        end

        % Value changed function: AttenuationKnob
        function AttenuationKnobValueChanged(app, event)
            value = app.AttenuationKnob.Value;
            app.attenuationText.Value = value
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmit signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Received signal')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequancyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequancyEditFieldLabel.Text = 'Frequancy';
            app.FrequancyEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SignalButtonGroup = uibuttongroup(app.UIFigure);
            app.SignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignalButtonGroupSelectionChanged, true);
            app.SignalButtonGroup.Title = 'Signal';
            app.sinButton = uiradiobutton(app.SignalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.SignalButtonGroup);
            app.cosButton.Text = 'cos';
            app.AttenuationKnobLabel = uilabel(app.UIFigure);
            app.AttenuationKnobLabel.Text = 'Attenuation';
            app.AttenuationKnob = uiknob(app.UIFigure, 'continuous');
            app.AttenuationKnob.Limits = [0 1];
            app.AttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @AttenuationKnobValueChanged, true);
            app.AttenuationKnob.Value = 1;
            app.SelectedEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.text = uieditfield(app.UIFigure, 'text');
            app.attenuationText = uieditfield(app.UIFigure, 'numeric');
            app.attenuationText.ValueChangedFcn = createCallbackFcn(app, @attenuationTextValueChanged, true);
            app.attenuationText.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = yoosh_signal_testapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end