classdef Correlation_final_190722 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        SignalPanel                  matlab.ui.container.Panel
        SinusoidalSignalButtonGroup  matlab.ui.container.ButtonGroup
        sinButton                    matlab.ui.control.RadioButton
        cosButton                    matlab.ui.control.RadioButton
        GainEditFieldLabel           matlab.ui.control.Label
        GainEditField                matlab.ui.control.NumericEditField
        FrequancyEditFieldLabel      matlab.ui.control.Label
        FrequancyEditField           matlab.ui.control.NumericEditField
        SelectedEditFieldLabel       matlab.ui.control.Label
        text                         matlab.ui.control.EditField
        phaseText                    matlab.ui.control.NumericEditField
        PhaseKnobLabel               matlab.ui.control.Label
        PhaseKnob                    matlab.ui.control.Knob
        attenuationText              matlab.ui.control.NumericEditField
        AttenuationKnobLabel         matlab.ui.control.Label
        AttenuationKnob              matlab.ui.control.Knob
        NoisePanel                   matlab.ui.container.Panel
        StandarddeviationKnobLabel   matlab.ui.control.Label
        StandarddeviationKnob        matlab.ui.control.Knob
        noiseSD                      matlab.ui.control.NumericEditField
        AmplitudeKnobLabel           matlab.ui.control.Label
        AmplitudeKnob                matlab.ui.control.Knob
        noiseAmpText                 matlab.ui.control.NumericEditField
        TypeKnobLabel                matlab.ui.control.Label
        TypeKnob                     matlab.ui.control.DiscreteKnob
        type                         matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        SignalDropDownLabel          matlab.ui.control.Label
        SignalDropDown               matlab.ui.control.DropDown
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        AttenuationEditFieldLabel    matlab.ui.control.Label
        AttenuationEditField         matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            t = 0 : 0.01 : 2*pi;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.AmplitudeEditField.Value;
            attenuation = app.AttenuationEditField.Value;
%************************sin/cos 구분*************************************        
            if isequal(app.SignalDropDown.Value, 'sin')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.SignalDropDown.Value, 'cos')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            end
%*************************************************************************                
            r = [y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t) 
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
            plot(app.UIAxes4, (0:length(c)-1)*0.01, c)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % start button
            A = app.GainEditField.Value;
            f = app.FrequancyEditField.Value;
            attenuation = app.AttenuationKnob.Value;
            signal = app.text.Value;
            phase = app.PhaseKnob.Value;
            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
   
            noiseAmp = app.AmplitudeKnob.Value;
            SD = app.StandarddeviationKnob.Value;
            
            t = 0 : 0.01 : 2*pi;
            w = 2 * pi * f;
            L = length(t);
%t=0:0.01:2*pi;
%L=length(t);
%f=2;
%sd=1; % standard deviation
%s=cos(2*pi*f*t); % 원래 신호
%r=[s , zeros(1,2*L), 0.5*s, zeros(1,L) ];
%r=r+ randn(1,length(r))*sd ; % 잡음섞인신호
%ct1=length(r)-L;

%for a=0:ct1
%    c(a+1)=r(a+1:a+L)*s';
%end

%subplot(211), plot((0:length(r)-1)*0.01, r);
%subplot(212), plot((0:length(c)-1)*0.01, c); 

%************************************* sin signal *************************************     

            if isequal(signal, 'sin signal')
                    y1 = A*sin(w*t);
                    y2 = attenuation*A*sin(w*t+(phase*2*pi/360));
                    plot(app.UIAxes,t,y1)
                    plot(app.UIAxes2,t,y2)
                    
                    r = [y1 , zeros(1,2*L), y2, zeros(1,L) ];
                    r = r+ randn(1,length(r))*SD*noiseAmp*number;
                    ct1 = length(r)-L;
                    
                    for a = 0:ct1
                        c(a+1) = r(a+1:a+L)*y1';
                    end
                    plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
                    plot(app.UIAxes4, (0:length(c)-1)*0.01, c)
               
            
%************************************** cos signal *************************************            
          
            elseif isequal(signal,'cos signal')
                        y1 = A*cos(w*t);
                        y2 = attenuation*A*cos(w*t+(phase*2*pi/360));
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        
                        r = [y1 , zeros(1,2*L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*noiseAmp*number;
                        ct1 = length(r)-L;
                        
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                        plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
                        plot(app.UIAxes4, (0:length(c)-1)*0.01, c) 
                    
            end % final if end
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % reset button
            app.GainEditField.Value = 0;
            app.FrequancyEditField.Value = 0;
            app.AttenuationKnob.Value = 1;
            app.attenuationText.Value = 1;
            app.PhaseKnob.Value = 0;
            app.phaseText.Value = 0;
            app.AmplitudeKnob.Value = 0;
            app.noiseAmpText.Value = 0;
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD.Value = 1;
            app.TypeKnob.Value = 'Off';
            app.type.Value = 'Off';
           
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            t = 0 : 0.01 : 2*pi;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.AmplitudeEditField.Value;
            attenuation = app.AttenuationEditField.Value;
%************************sin/cos 구분*************************************        
            if isequal(app.SignalDropDown.Value, 'sin')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.SignalDropDown.Value, 'cos')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            end
%*************************************************************************                
            r = [y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t) 
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
            plot(app.UIAxes4, (0:length(c)-1)*0.01, c)
        end

        % Selection changed function: SinusoidalSignalButtonGroup
        function SinusoidalSignalButtonGroupSelectionChanged(app, event)
          % choose signal type
            selectedButton = app.SinusoidalSignalButtonGroup.SelectedObject;
            value = selectedButton.Text 
            
            switch value
                case 'sin'
                    app.text.Value = 'sin signal'
                case 'cos'
                    app.text.Value = 'cos signal'
            end
        end

        % Value changed function: attenuationText
        function attenuationTextValueChanged(app, event)
            value = app.attenuationText.Value;
            app.AttenuationKnob.Value = value
        end

        % Value changed function: AttenuationKnob
        function AttenuationKnobValueChanged(app, event)
            value = app.AttenuationKnob.Value;
            app.attenuationText.Value = value
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            value = app.PhaseKnob.Value;
            app.phaseText.Value = value;
        end

        % Value changed function: phaseText
        function phaseTextValueChanged(app, event)
            value = app.phaseText.Value;
            app.PhaseKnob.Value = value;
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
            app.noiseAmpText.Value = value;
        end

        % Value changed function: noiseAmpText
        function noiseAmpTextValueChanged(app, event)
            value = app.noiseAmpText.Value;
            app.AmplitudeKnob.Value = value;
        end

        % Value changed function: StandarddeviationKnob
        function StandarddeviationKnobValueChanged(app, event)
            value = app.StandarddeviationKnob.Value;
            app.noiseSD.Value = value;
        end

        % Value changed function: noiseSD
        function noiseSDValueChanged(app, event)
            value = app.noiseSD.Value;
            app.StandarddeviationKnob.Value = value;
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            value = app.type.Value;
            app.TypeKnob.Value = value;
        end

        % Value changed function: TypeKnob
        function TypeKnobValueChanged(app, event)
            value = app.TypeKnob.Value;
            switch value
                case 'Off'
                    app.type.Value = value;
                case 'Gaussian'
                    app.type.Value = value;
            end
               
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end

        % Value changed function: AttenuationEditField
        function AttenuationEditFieldValueChanged(app, event)
            value = app.AttenuationEditField.Value;
            
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            value = app.SignalDropDown.Value;
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmit signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Received signal')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Total signal')
            xlabel(app.UIAxes3, 'Wave')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Correlation')
            xlabel(app.UIAxes4, 'Wave')
            ylabel(app.UIAxes4, 'Amplitude')
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.SinusoidalSignalButtonGroup = uibuttongroup(app.SignalPanel);
            app.SinusoidalSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinusoidalSignalButtonGroupSelectionChanged, true);
            app.SinusoidalSignalButtonGroup.Title = 'Sinusoidal Signal';
            app.sinButton = uiradiobutton(app.SinusoidalSignalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.SinusoidalSignalButtonGroup);
            app.cosButton.Text = 'cos';
            app.GainEditFieldLabel = uilabel(app.SignalPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.SignalPanel, 'numeric');
            app.FrequancyEditFieldLabel = uilabel(app.SignalPanel);
            app.FrequancyEditFieldLabel.Text = 'Frequancy';
            app.FrequancyEditField = uieditfield(app.SignalPanel, 'numeric');
            app.SelectedEditFieldLabel = uilabel(app.SignalPanel);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.text = uieditfield(app.SignalPanel, 'text');
            app.text.Value = 'sin signal';
            app.phaseText = uieditfield(app.SignalPanel, 'numeric');
            app.phaseText.ValueChangedFcn = createCallbackFcn(app, @phaseTextValueChanged, true);
            app.PhaseKnobLabel = uilabel(app.SignalPanel);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.SignalPanel, 'continuous');
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            app.attenuationText = uieditfield(app.SignalPanel, 'numeric');
            app.attenuationText.ValueChangedFcn = createCallbackFcn(app, @attenuationTextValueChanged, true);
            app.attenuationText.Value = 1;
            app.AttenuationKnobLabel = uilabel(app.SignalPanel);
            app.AttenuationKnobLabel.Text = 'Attenuation';
            app.AttenuationKnob = uiknob(app.SignalPanel, 'continuous');
            app.AttenuationKnob.Limits = [0 1];
            app.AttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @AttenuationKnobValueChanged, true);
            app.AttenuationKnob.Value = 1;
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.StandarddeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandarddeviationKnobLabel.Text = 'Standard deviation';
            app.StandarddeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandarddeviationKnob.Limits = [0 10];
            app.StandarddeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationKnobValueChanged, true);
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD = uieditfield(app.NoisePanel, 'numeric');
            app.noiseSD.ValueChangedFcn = createCallbackFcn(app, @noiseSDValueChanged, true);
            app.noiseSD.Value = 1;
            app.AmplitudeKnobLabel = uilabel(app.NoisePanel);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.NoisePanel, 'continuous');
            app.AmplitudeKnob.Limits = [0 10];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.noiseAmpText = uieditfield(app.NoisePanel, 'numeric');
            app.noiseAmpText.ValueChangedFcn = createCallbackFcn(app, @noiseAmpTextValueChanged, true);
            app.TypeKnobLabel = uilabel(app.NoisePanel);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.NoisePanel, 'discrete');
            app.TypeKnob.Items = {'Off', 'Gaussian'};
            app.TypeKnob.ValueChangedFcn = createCallbackFcn(app, @TypeKnobValueChanged, true);
            app.type = uieditfield(app.NoisePanel, 'text');
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.type.Value = 'Off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '초기값 설정';
            app.SignalDropDownLabel = uilabel(app.UIFigure);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.UIFigure);
            app.SignalDropDown.Items = {'sin', 'cos'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = 'sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1;
            app.AttenuationEditFieldLabel = uilabel(app.UIFigure);
            app.AttenuationEditFieldLabel.Text = 'Attenuation';
            app.AttenuationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttenuationEditField.ValueChangedFcn = createCallbackFcn(app, @AttenuationEditFieldValueChanged, true);
            app.AttenuationEditField.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Correlation_final_190722

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end