classdef Correlation_final_190804 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        SignalPanel                  matlab.ui.container.Panel
        SinusoidalSignalButtonGroup  matlab.ui.container.ButtonGroup
        sinButton                    matlab.ui.control.RadioButton
        cosButton                    matlab.ui.control.RadioButton
        GainEditFieldLabel           matlab.ui.control.Label
        GainEditField                matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        SelectedEditFieldLabel       matlab.ui.control.Label
        text                         matlab.ui.control.EditField
        phaseText                    matlab.ui.control.NumericEditField
        PhaseKnobLabel               matlab.ui.control.Label
        PhaseKnob                    matlab.ui.control.Knob
        attenuationText              matlab.ui.control.NumericEditField
        AttenuationKnobLabel         matlab.ui.control.Label
        AttenuationKnob              matlab.ui.control.Knob
        NoisePanel                   matlab.ui.container.Panel
        StandarddeviationKnobLabel   matlab.ui.control.Label
        StandarddeviationKnob        matlab.ui.control.Knob
        noiseSD                      matlab.ui.control.NumericEditField
        TypeKnobLabel                matlab.ui.control.Label
        TypeKnob                     matlab.ui.control.DiscreteKnob
        type                         matlab.ui.control.EditField
        DistanceEditFieldLabel       matlab.ui.control.Label
        DistanceEditField            matlab.ui.control.NumericEditField
        TransmittimeEditFieldLabel   matlab.ui.control.Label
        TransmittimeEditField        matlab.ui.control.NumericEditField
        ReceivedtimeEditFieldLabel   matlab.ui.control.Label
        ReceivedtimeEditField        matlab.ui.control.NumericEditField
        mLabel_2                     matlab.ui.control.Label
        sLabel                       matlab.ui.control.Label
        sLabel_2                     matlab.ui.control.Label
        DistancehatEditFieldLabel    matlab.ui.control.Label
        DistancehatEditField         matlab.ui.control.NumericEditField
        K1EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        mLabel                       matlab.ui.control.Label
        sampleLabel                  matlab.ui.control.Label
        sampleLabel_2                matlab.ui.control.Label
        ErrorrateEditFieldLabel      matlab.ui.control.Label
        ErrorrateEditField           matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            t = 0 : 0.01 : 2*pi;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value;
            attenuation = app.attenuationText.Value;
            SD = app.noiseSD.Value;
            app.TypeKnob.Value = 'Gaussian';
            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
%************************sin/cos 구분*************************************    

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            end
                            
            r = [zeros(1,L), y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t)
            r = r+ randn(1,length(r))*SD*number;
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
            plot(app.UIAxes4, (0:length(c)-1)*0.01, c)
            
%**************************** 거리계산 **************************************            
            [m,k1] = max(c);
            c2 = c(length(t)*2 : end);
            [n,k2] = max(c2);
            time_diff = ((length(t)*2+k2-k1))*0.01; % delta_t = 0.01
            D = (time_diff/2)*3*10^8*10^-6; % micro second로 단위변환
            app.DistancehatEditField.Value = D;
            
%************************** 거리 입력, 오차, 도착시간 *********************************
            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % start button
            A = app.GainEditField.Value;
            f = app.FrequencyEditField.Value;
            attenuation = app.AttenuationKnob.Value;
            signal = app.text.Value;
            phase = app.PhaseKnob.Value;
            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
            SD = app.StandarddeviationKnob.Value;
            
            t = 0 : 0.01 : 2*pi;
            w = 2 * pi * f;
            L = length(t);
            D_in = app.DistanceEditField.Value;
            TL = D_in/150; % 시간차
            PTP = TL/0.01; % Peak to Peak
           
%%************************************* sin signal *************************************     

            if isequal(signal, 'sin signal')
                    y1 = A*sin(w*t);
                    y2 = attenuation*A*sin(w*t+(phase*2*pi/360));
                    plot(app.UIAxes,t,y1)
                    plot(app.UIAxes2,t,y2)
                    
            %***************************** k2 - k1 > L ********************************
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                  
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*0.01; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*0.01, r)
                        plot(app.UIAxes4,(0:length(c)-1)*0.01, c)
    
                    %************************** K2 - K1 < L ***************************    
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                          
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*0.01; % sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*0.01, r)
                        plot(app.UIAxes4,(0:length(c)-1)*0.01, c)
                        
                end
                    
%%************************************** cos signal *************************************            
          
            elseif isequal(signal,'cos signal')
                        y1 = A*cos(w*t);
                        y2 = attenuation*A*cos(w*t+(phase*2*pi/360));
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        
            %***************************** k2 - k1 > L ********************************
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*0.01; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*0.01, r)
                        plot(app.UIAxes4,(0:length(c)-1)*0.01, c)
    
             %******************************* K2 - K1 < L ******************************    
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*0.01; % sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*0.01, r)
                        plot(app.UIAxes4,(0:length(c)-1)*0.01, c)
                        
                    end
            end
       
%******************************** 거리 입력, 오차, 도착시간 ****************************
          
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D_out)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
      
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % reset button
            app.GainEditField.Value = 1;
            app.FrequencyEditField.Value = 1;
            app.AttenuationKnob.Value = 0.5;
            app.attenuationText.Value = 0.5;
            app.PhaseKnob.Value = 0;
            app.phaseText.Value = 0;
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD.Value = 1;
            app.TypeKnob.Value = 'Gaussian'
            app.type.Value = 'Gaussian';
             
            SD = app.noiseSD.Value;            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
           
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            t = 0 : 0.01 : 2*pi;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value;
            attenuation = app.attenuationText.Value;
            
%************************sin/cos 구분*************************************      

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            end
            
%*************************************************************************     

            r = [zeros(1,L), y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t)
            r = r+ randn(1,length(r))*SD*number;
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*0.01, r)
            plot(app.UIAxes4, (0:length(c)-1)*0.01, c)
            
%********************************** 거리계산 ********************************

            [m,k1] = max(c);
            c2 = c(length(t)*2 : end);
            [n,k2] = max(c2);
            time_diff = ((length(t)*2+k2-k1))*0.01; % delta_t = 0.01
            D = (time_diff/2)*3*10^8*10^-6; % micro second로 단위변환
            app.DistancehatEditField.Value = D; 
            
%***************************** 거리 입력, 오차, 도착시간 ******************************

            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;

%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
           
        end

        % Selection changed function: SinusoidalSignalButtonGroup
        function SinusoidalSignalButtonGroupSelectionChanged(app, event)
          % choose signal type
            selectedButton = app.SinusoidalSignalButtonGroup.SelectedObject;
            value = selectedButton.Text 
            
            switch value
                case 'sin'
                    app.text.Value = 'sin signal'
                case 'cos'
                    app.text.Value = 'cos signal'
            end
        end

        % Value changed function: attenuationText
        function attenuationTextValueChanged(app, event)
            value = app.attenuationText.Value;
            app.AttenuationKnob.Value = value
        end

        % Value changed function: AttenuationKnob
        function AttenuationKnobValueChanged(app, event)
            value = app.AttenuationKnob.Value;
            app.attenuationText.Value = value
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            value = app.PhaseKnob.Value;
            app.phaseText.Value = value;
        end

        % Value changed function: phaseText
        function phaseTextValueChanged(app, event)
            value = app.phaseText.Value;
            app.PhaseKnob.Value = value;
        end

        % Value changed function: StandarddeviationKnob
        function StandarddeviationKnobValueChanged(app, event)
            value = app.StandarddeviationKnob.Value;
            app.noiseSD.Value = value;
        end

        % Value changed function: noiseSD
        function noiseSDValueChanged(app, event)
            value = app.noiseSD.Value;
            app.StandarddeviationKnob.Value = value;
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            value = app.type.Value;
            app.TypeKnob.Value = value;
        end

        % Value changed function: TypeKnob
        function TypeKnobValueChanged(app, event)
            value = app.TypeKnob.Value;
            switch value
                case 'Off'
                    app.type.Value = value;
                case 'Gaussian'
                    app.type.Value = value;
            end
               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmit signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Received signal')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Total signal')
            xlabel(app.UIAxes3, 'Wave')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Correlation')
            xlabel(app.UIAxes4, 'Wave')
            ylabel(app.UIAxes4, 'Amplitude')
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.SinusoidalSignalButtonGroup = uibuttongroup(app.SignalPanel);
            app.SinusoidalSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinusoidalSignalButtonGroupSelectionChanged, true);
            app.SinusoidalSignalButtonGroup.Title = 'Sinusoidal Signal';
            app.sinButton = uiradiobutton(app.SinusoidalSignalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.SinusoidalSignalButtonGroup);
            app.cosButton.Text = 'cos';
            app.GainEditFieldLabel = uilabel(app.SignalPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.SignalPanel, 'numeric');
            app.GainEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.SignalPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SignalPanel, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.SelectedEditFieldLabel = uilabel(app.SignalPanel);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.text = uieditfield(app.SignalPanel, 'text');
            app.text.Value = 'sin signal';
            app.phaseText = uieditfield(app.SignalPanel, 'numeric');
            app.phaseText.ValueChangedFcn = createCallbackFcn(app, @phaseTextValueChanged, true);
            app.PhaseKnobLabel = uilabel(app.SignalPanel);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.SignalPanel, 'continuous');
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            app.attenuationText = uieditfield(app.SignalPanel, 'numeric');
            app.attenuationText.ValueChangedFcn = createCallbackFcn(app, @attenuationTextValueChanged, true);
            app.attenuationText.Value = 0.5;
            app.AttenuationKnobLabel = uilabel(app.SignalPanel);
            app.AttenuationKnobLabel.Text = 'Attenuation';
            app.AttenuationKnob = uiknob(app.SignalPanel, 'continuous');
            app.AttenuationKnob.Limits = [0 1];
            app.AttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @AttenuationKnobValueChanged, true);
            app.AttenuationKnob.Value = 0.5;
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.StandarddeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandarddeviationKnobLabel.Text = 'Standard deviation';
            app.StandarddeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandarddeviationKnob.Limits = [0 10];
            app.StandarddeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationKnobValueChanged, true);
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD = uieditfield(app.NoisePanel, 'numeric');
            app.noiseSD.ValueChangedFcn = createCallbackFcn(app, @noiseSDValueChanged, true);
            app.noiseSD.Value = 1;
            app.TypeKnobLabel = uilabel(app.NoisePanel);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.NoisePanel, 'discrete');
            app.TypeKnob.Items = {'Off', 'Gaussian'};
            app.TypeKnob.ValueChangedFcn = createCallbackFcn(app, @TypeKnobValueChanged, true);
            app.TypeKnob.Value = 'Gaussian';
            app.type = uieditfield(app.NoisePanel, 'text');
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.type.Value = 'Gaussian';
            app.DistanceEditFieldLabel = uilabel(app.NoisePanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.NoisePanel, 'numeric');
            app.DistanceEditField.Value = 3000;
            app.TransmittimeEditFieldLabel = uilabel(app.NoisePanel);
            app.TransmittimeEditFieldLabel.Text = 'Transmit time';
            app.TransmittimeEditField = uieditfield(app.NoisePanel, 'numeric');
            app.ReceivedtimeEditFieldLabel = uilabel(app.NoisePanel);
            app.ReceivedtimeEditFieldLabel.Text = 'Received time';
            app.ReceivedtimeEditField = uieditfield(app.NoisePanel, 'numeric');
            app.mLabel_2 = uilabel(app.NoisePanel);
            app.mLabel_2.Text = '[m]';
            app.sLabel = uilabel(app.NoisePanel);
            app.sLabel.Text = '[μs]';
            app.sLabel_2 = uilabel(app.NoisePanel);
            app.sLabel_2.Text = '[μs]';
            app.DistancehatEditFieldLabel = uilabel(app.UIFigure);
            app.DistancehatEditFieldLabel.Text = 'Distance(hat)';
            app.DistancehatEditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditFieldLabel = uilabel(app.UIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            app.sampleLabel = uilabel(app.UIFigure);
            app.sampleLabel.Text = '[sample]';
            app.sampleLabel_2 = uilabel(app.UIFigure);
            app.sampleLabel_2.Text = '[sample]';
            app.ErrorrateEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorrateEditFieldLabel.Text = 'Error rate';
            app.ErrorrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '[%]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Correlation_final_190804

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end