classdef Correlation_final_190806 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        NoisePanel                  matlab.ui.container.Panel
        StandarddeviationKnobLabel  matlab.ui.control.Label
        StandarddeviationKnob       matlab.ui.control.Knob
        noiseSD                     matlab.ui.control.NumericEditField
        TypeKnobLabel               matlab.ui.control.Label
        TypeKnob                    matlab.ui.control.DiscreteKnob
        type                        matlab.ui.control.EditField
        DistanceEditFieldLabel      matlab.ui.control.Label
        DistanceEditField           matlab.ui.control.NumericEditField
        TransmittimeEditFieldLabel  matlab.ui.control.Label
        TransmittimeEditField       matlab.ui.control.NumericEditField
        ReceivedtimeEditFieldLabel  matlab.ui.control.Label
        ReceivedtimeEditField       matlab.ui.control.NumericEditField
        mLabel_2                    matlab.ui.control.Label
        sLabel                      matlab.ui.control.Label
        sLabel_2                    matlab.ui.control.Label
        DistancehatEditFieldLabel   matlab.ui.control.Label
        DistancehatEditField        matlab.ui.control.NumericEditField
        K1EditFieldLabel            matlab.ui.control.Label
        K1EditField                 matlab.ui.control.NumericEditField
        K2EditFieldLabel            matlab.ui.control.Label
        K2EditField                 matlab.ui.control.NumericEditField
        mLabel                      matlab.ui.control.Label
        sampleLabel                 matlab.ui.control.Label
        sampleLabel_2               matlab.ui.control.Label
        ErrorrateEditFieldLabel     matlab.ui.control.Label
        ErrorrateEditField          matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        ChooseSignalButtonGroup     matlab.ui.container.ButtonGroup
        sinButton                   matlab.ui.control.RadioButton
        cosButton                   matlab.ui.control.RadioButton
        pulseButton                 matlab.ui.control.RadioButton
        chirpButton                 matlab.ui.control.RadioButton
        SelectedEditFieldLabel      matlab.ui.control.Label
        text                        matlab.ui.control.EditField
        SinusoidalsignalPanel       matlab.ui.container.Panel
        phaseText                   matlab.ui.control.NumericEditField
        attenuationText             matlab.ui.control.NumericEditField
        AttenuationKnobLabel        matlab.ui.control.Label
        AttenuationKnob             matlab.ui.control.Knob
        PhaseKnobLabel              matlab.ui.control.Label
        PhaseKnob                   matlab.ui.control.Knob
        FrequencyEditFieldLabel     matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        ChirpsignalPanel            matlab.ui.container.Panel
        FrequencyChangeKnobLabel    matlab.ui.control.Label
        ChirpFrequencyKnob          matlab.ui.control.Knob
        ChirpAttenuationKnobLabel   matlab.ui.control.Label
        ChirpAttenuationKnob        matlab.ui.control.Knob
        Chirp_AttenText             matlab.ui.control.NumericEditField
        Chirp_FreText               matlab.ui.control.NumericEditField
        TimeChangeKnobLabel         matlab.ui.control.Label
        ChirpTimeKnob               matlab.ui.control.Knob
        Chirp_TimeText              matlab.ui.control.NumericEditField
        PulsesignalPanel            matlab.ui.container.Panel
        PulseWidthKnobLabel         matlab.ui.control.Label
        PulseWidthKnob              matlab.ui.control.Knob
        PulseAttenuationKnobLabel   matlab.ui.control.Label
        PulseAttenuationKnob        matlab.ui.control.Knob
        Pulse_WidthText             matlab.ui.control.NumericEditField
        Pulse_AttenText             matlab.ui.control.NumericEditField
        Delta_tEditFieldLabel       matlab.ui.control.Label
        Delta_tEditField            matlab.ui.control.NumericEditField
        Length_tEditFieldLabel      matlab.ui.control.Label
        Length_tEditField           matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%******************* 초기 신호값 A = 1, F = 1, attenuation = 0.5 ******************          
            
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Length_tEditField.Value;
            t = 0 : Delta_t : Length_t;
            
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value;
            attenuation = app.attenuationText.Value;
            SD = app.noiseSD.Value;
            
            impulse = 0 : 1/f : L;
            pulse_width = app.Pulse_WidthText.Value;
            pulse_attenuation = app.Pulse_AttenText.Value;
            
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            chirp_attenuation = app.Chirp_AttenText.Value;
            
            app.TypeKnob.Value = 'Gaussian';
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
%********************************** signal 구분 ***********************************    

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
                    
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
                
            elseif isequal(app.text.Value, 'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',pulse_width); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = pulse_attenuation*A*pulstran(t,impulse,'rectpuls',pulse_width);
            
            elseif isequal(app.text.Value, 'chirp signal')
                y1 = A*chirp(t,1,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = chirp_attenuation*A*chirp(t,f,chirp_t,chirp_f);
            end
            
%****************************** correlation *************************************

            r = [zeros(1,L), y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t)
            r = r+ randn(1,length(r))*SD*number;
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*Delta_t, r)
            plot(app.UIAxes4, (0:length(c)-1)*Delta_t, c)
            
%********************************* 거리계산 **************************************

            [m,k1] = max(c);
            c2 = c(length(t)*2 : end);
            [n,k2] = max(c2);
            time_diff = ((length(t)*2+k2-k1))*Delta_t; % delta_t = 0.01
            D = (time_diff/2)*3*10^8*10^-6; % micro second로 단위변환
            app.DistancehatEditField.Value = D;
            
%************************** 거리 입력, 오차, 도착시간 *******************************

            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 *********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            % start button
            
            A = app.GainEditField.Value;
            f = app.FrequencyEditField.Value;
            attenuation = app.AttenuationKnob.Value;
            signal = app.text.Value;
            phase = app.PhaseKnob.Value;
            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
            SD = app.StandarddeviationKnob.Value;
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Length_tEditField.Value;
            t = 0 : Delta_t : Length_t;
            w = 2 * pi * f;
            L = length(t);
            D_in = app.DistanceEditField.Value;
            TL = D_in/150; % 시간차
            PTP = TL/0.01; % Peak to Peak
            
            impulse = 0 : 1/f : L;
            pulse_width = app.Pulse_WidthText.Value;
            pulse_attenuation = app.Pulse_AttenText.Value;
            
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            chirp_attenuation = app.Chirp_AttenText.Value;
           
%%************************************* sin signal *************************************     

            if isequal(signal, 'sin signal')
                    y1 = A*sin(w*t);
                    y2 = attenuation*A*sin(w*t+(phase*2*pi/360));
                    plot(app.UIAxes,t,y1)
                    plot(app.UIAxes2,t,y2)
                    
            %***************************** k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                  
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
            %***************************** K2 - K1 < L *********************************  
            
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                          
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % delta_t = 0.01일 때 sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                end
                    
%%************************************** cos signal *************************************            
          
            elseif isequal(signal,'cos signal')
                        y1 = A*cos(w*t);
                        y2 = attenuation*A*cos(w*t+(phase*2*pi/360));
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        
            %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end
                    
%%*********************************** pulse signal *************************************

            elseif isequal(signal,'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',pulse_width); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = pulse_attenuation*A*pulstran(t,impulse,'rectpuls',pulse_width);
                plot(app.UIAxes,t,y1)
                plot(app.UIAxes2,t,y2) 
                
           %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end
                    
%%***************************************** chirp signal *************************************

            elseif isequal(signal, 'chirp signal')
                y1 = A*chirp(t,1,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = chirp_attenuation*A*chirp(t,f,chirp_t,chirp_f);
                plot(app.UIAxes,t,y1)
                plot(app.UIAxes2,t,y2)
                
             %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [s], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end   

            end % final if
       
%******************************** 거리 입력, 오차, 도착시간 ****************************
          
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D_out)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
      
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % reset button
            
            app.GainEditField.Value = 1;
            app.FrequencyEditField.Value = 1;
            app.AttenuationKnob.Value = 0.5;
            app.attenuationText.Value = 0.5;
            app.PhaseKnob.Value = 0;
            app.phaseText.Value = 0;
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD.Value = 1;
            app.TypeKnob.Value = 'Gaussian'
            app.type.Value = 'Gaussian';
            
            app.Pulse_WidthText.Value = 0.1;
            app.PulseWidthKnob.Value = 0.1;
            app.Pulse_AttenText.Value = 0.5;
            app.PulseAttenuationKnob.Value = 0.5;
            
            app.Chirp_FreText.Value = 2;
            app.ChirpFrequencyKnob.Value = 2;
            app.Chirp_AttenText.Value = 0.5;
            app.ChirpAttenuationKnob.Value = 0.5;
             
            SD = app.noiseSD.Value;            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
           
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            app.Delta_tEditField.Value = 0.01;            
            app.Length_tEditField.Value = 2*pi;
            
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Length_tEditField.Value;
            t = 0 : Delta_t : Length_t;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value;
            attenuation = app.attenuationText.Value;
            
            SD = app.StandarddeviationKnob.Value;
            D_in = app.DistanceEditField.Value;
            TL = D_in/150; % 시간차
            PTP = TL/0.01; % Peak to Peak
            
            impulse = 0 : 1/f : L;
            pulse_width = app.Pulse_WidthText.Value;
            pulse_attenuation = app.Pulse_AttenText.Value;
            
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            chirp_attenuation = app.Chirp_AttenText.Value;
            
%***************************** signal 구분 *************************************      

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            elseif isequal(app.text.Value, 'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',pulse_width); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = pulse_attenuation*A*pulstran(t,impulse,'rectpuls',pulse_width);
            
            elseif isequal(app.text.Value, 'chirp signal')
                y1 = A*chirp(t,1,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = chirp_attenuation*A*chirp(t,f,chirp_t,chirp_f);
            end
            
%******************************* correlation ************************************     

            r = [zeros(1,L), y1 , zeros(1,2*L), y2, zeros(1,L) ]; % 초기 r(t)
            r = r+ randn(1,length(r))*SD*number;
            ct1 = length(r)-L;
                    
            for a = 0:ct1
               c(a+1) = r(a+1:a+L)*y1'; % 초기 신호 Correlation 함수
            end
            
            plot(app.UIAxes, t, y1)
            plot(app.UIAxes2, t, y2)
            plot(app.UIAxes3, (0:length(r)-1)*Delta_t, r)
            plot(app.UIAxes4, (0:length(c)-1)*Delta_t, c)
            
%********************************** 거리계산 ********************************

            [m,k1] = max(c);
            c2 = c(length(t)*2 : end);
            [n,k2] = max(c2);
            time_diff = ((length(t)*2+k2-k1))*Delta_t; % delta_t = 0.01
            D = (time_diff/2)*3*10^8*10^-6; % micro second로 단위변환
            app.DistancehatEditField.Value = D; 
            
%***************************** 거리 입력, 오차, 도착시간 ******************************

            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;

%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
           
        end

        % Selection changed function: ChooseSignalButtonGroup
        function ChooseSignalButtonGroupSelectionChanged(app, event)
          % choose signal type
            selectedButton = app.ChooseSignalButtonGroup.SelectedObject;
            value = selectedButton.Text 
            
            switch value
                case 'sin'
                    app.text.Value = 'sin signal'
                case 'cos'
                    app.text.Value = 'cos signal'
                case 'pulse'
                    app.text.Value = 'pulse signal'
                case 'chirp'
                    app.text.Value = 'chirp signal'
            end
        end

        % Value changed function: attenuationText
        function attenuationTextValueChanged(app, event)
            value = app.attenuationText.Value;
            app.AttenuationKnob.Value = value
        end

        % Value changed function: AttenuationKnob
        function AttenuationKnobValueChanged(app, event)
            value = app.AttenuationKnob.Value;
            app.attenuationText.Value = value
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            value = app.PhaseKnob.Value;
            app.phaseText.Value = value;
        end

        % Value changed function: phaseText
        function phaseTextValueChanged(app, event)
            value = app.phaseText.Value;
            app.PhaseKnob.Value = value;
        end

        % Value changed function: StandarddeviationKnob
        function StandarddeviationKnobValueChanged(app, event)
            value = app.StandarddeviationKnob.Value;
            app.noiseSD.Value = value;
        end

        % Value changed function: noiseSD
        function noiseSDValueChanged(app, event)
            value = app.noiseSD.Value;
            app.StandarddeviationKnob.Value = value;
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            value = app.type.Value;
            app.TypeKnob.Value = value;
        end

        % Value changed function: TypeKnob
        function TypeKnobValueChanged(app, event)
            value = app.TypeKnob.Value;
            switch value
                case 'Off'
                    app.type.Value = value;
                case 'Gaussian'
                    app.type.Value = value;
            end
               
        end

        % Value changed function: PulseWidthKnob
        function PulseWidthKnobValueChanged(app, event)
            value = app.PulseWidthKnob.Value;
            app.Pulse_WidthText.Value = value;
        end

        % Value changed function: Pulse_WidthText
        function Pulse_WidthTextValueChanged(app, event)
            value = app.Pulse_WidthText.Value;
            app.PulseWidthKnob.Value = value;
        end

        % Value changed function: PulseAttenuationKnob
        function PulseAttenuationKnobValueChanged(app, event)
            value = app.PulseAttenuationKnob.Value;
            app.Pulse_AttenText.Value = value;
        end

        % Value changed function: Pulse_AttenText
        function Pulse_AttenTextValueChanged(app, event)
            value = app.Pulse_AttenText.Value;
            app.PulseAttenuationKnob.Value = value;
        end

        % Value changed function: ChirpFrequencyKnob
        function ChirpFrequencyKnobValueChanged(app, event)
            value = app.ChirpFrequencyKnob.Value;
            app.Chirp_FreText.Value = value;
        end

        % Value changed function: Chirp_FreText
        function Chirp_FreTextValueChanged(app, event)
            value = app.Chirp_FreText.Value;
            app.ChirpFrequencyKnob.Value = value;
        end

        % Value changed function: ChirpAttenuationKnob
        function ChirpAttenuationKnobValueChanged(app, event)
            value = app.ChirpAttenuationKnob.Value;
            app.Chirp_AttenText.Value = value;
        end

        % Value changed function: Chirp_AttenText
        function Chirp_AttenTextValueChanged(app, event)
            value = app.Chirp_AttenText.Value;
            app.ChirpAttenuationKnob.Value = value;
        end

        % Value changed function: ChirpTimeKnob
        function ChirpTimeKnobValueChanged(app, event)
            value = app.ChirpTimeKnob.Value;
            app.Chirp_TimeText.Value = value;
        end

        % Value changed function: Chirp_TimeText
        function Chirp_TimeTextValueChanged(app, event)
            value = app.Chirp_TimeText.Value;
            app.ChirpTimeKnob.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmit signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Received signal')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Total signal')
            xlabel(app.UIAxes3, 'Wave')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Correlation')
            xlabel(app.UIAxes4, 'Wave')
            ylabel(app.UIAxes4, 'Amplitude')
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.StandarddeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandarddeviationKnobLabel.Text = 'Standard deviation';
            app.StandarddeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandarddeviationKnob.Limits = [0 10];
            app.StandarddeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationKnobValueChanged, true);
            app.StandarddeviationKnob.Value = 1;
            app.noiseSD = uieditfield(app.NoisePanel, 'numeric');
            app.noiseSD.ValueChangedFcn = createCallbackFcn(app, @noiseSDValueChanged, true);
            app.noiseSD.Value = 1;
            app.TypeKnobLabel = uilabel(app.NoisePanel);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.NoisePanel, 'discrete');
            app.TypeKnob.Items = {'Off', 'Gaussian'};
            app.TypeKnob.ValueChangedFcn = createCallbackFcn(app, @TypeKnobValueChanged, true);
            app.TypeKnob.Value = 'Gaussian';
            app.type = uieditfield(app.NoisePanel, 'text');
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.type.Value = 'Gaussian';
            app.DistanceEditFieldLabel = uilabel(app.NoisePanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.NoisePanel, 'numeric');
            app.DistanceEditField.Value = 3000;
            app.TransmittimeEditFieldLabel = uilabel(app.NoisePanel);
            app.TransmittimeEditFieldLabel.Text = 'Transmit time';
            app.TransmittimeEditField = uieditfield(app.NoisePanel, 'numeric');
            app.ReceivedtimeEditFieldLabel = uilabel(app.NoisePanel);
            app.ReceivedtimeEditFieldLabel.Text = 'Received time';
            app.ReceivedtimeEditField = uieditfield(app.NoisePanel, 'numeric');
            app.mLabel_2 = uilabel(app.NoisePanel);
            app.mLabel_2.Text = '[m]';
            app.sLabel = uilabel(app.NoisePanel);
            app.sLabel.Text = '[μs]';
            app.sLabel_2 = uilabel(app.NoisePanel);
            app.sLabel_2.Text = '[μs]';
            app.DistancehatEditFieldLabel = uilabel(app.UIFigure);
            app.DistancehatEditFieldLabel.Text = 'Distance(hat)';
            app.DistancehatEditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditFieldLabel = uilabel(app.UIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            app.sampleLabel = uilabel(app.UIFigure);
            app.sampleLabel.Text = '[sample]';
            app.sampleLabel_2 = uilabel(app.UIFigure);
            app.sampleLabel_2.Text = '[sample]';
            app.ErrorrateEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorrateEditFieldLabel.Text = 'Error rate';
            app.ErrorrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '[%]';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ChooseSignalButtonGroup = uibuttongroup(app.Panel_2);
            app.ChooseSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseSignalButtonGroupSelectionChanged, true);
            app.ChooseSignalButtonGroup.Title = 'Choose Signal';
            app.sinButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.cosButton.Text = 'cos';
            app.pulseButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.pulseButton.Text = 'pulse';
            app.chirpButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.chirpButton.Text = 'chirp';
            app.SelectedEditFieldLabel = uilabel(app.Panel_2);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.text = uieditfield(app.Panel_2, 'text');
            app.text.Value = 'sin signal';
            app.SinusoidalsignalPanel = uipanel(app.Panel_2);
            app.SinusoidalsignalPanel.Title = 'Sinusoidal signal';
            app.phaseText = uieditfield(app.SinusoidalsignalPanel, 'numeric');
            app.phaseText.ValueChangedFcn = createCallbackFcn(app, @phaseTextValueChanged, true);
            app.attenuationText = uieditfield(app.SinusoidalsignalPanel, 'numeric');
            app.attenuationText.ValueChangedFcn = createCallbackFcn(app, @attenuationTextValueChanged, true);
            app.attenuationText.Value = 0.5;
            app.AttenuationKnobLabel = uilabel(app.SinusoidalsignalPanel);
            app.AttenuationKnobLabel.Text = 'Attenuation';
            app.AttenuationKnob = uiknob(app.SinusoidalsignalPanel, 'continuous');
            app.AttenuationKnob.Limits = [0 1];
            app.AttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @AttenuationKnobValueChanged, true);
            app.AttenuationKnob.Value = 0.5;
            app.PhaseKnobLabel = uilabel(app.SinusoidalsignalPanel);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.SinusoidalsignalPanel, 'continuous');
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.Panel_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel_2, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.GainEditFieldLabel = uilabel(app.Panel_2);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.Panel_2, 'numeric');
            app.GainEditField.Value = 1;
            app.ChirpsignalPanel = uipanel(app.Panel_2);
            app.ChirpsignalPanel.Title = 'Chirp signal';
            app.FrequencyChangeKnobLabel = uilabel(app.ChirpsignalPanel);
            app.FrequencyChangeKnobLabel.Text = 'Frequency Change';
            app.ChirpFrequencyKnob = uiknob(app.ChirpsignalPanel, 'continuous');
            app.ChirpFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @ChirpFrequencyKnobValueChanged, true);
            app.ChirpFrequencyKnob.Value = 2;
            app.ChirpAttenuationKnobLabel = uilabel(app.ChirpsignalPanel);
            app.ChirpAttenuationKnobLabel.Text = 'Chirp Attenuation';
            app.ChirpAttenuationKnob = uiknob(app.ChirpsignalPanel, 'continuous');
            app.ChirpAttenuationKnob.Limits = [0 1];
            app.ChirpAttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @ChirpAttenuationKnobValueChanged, true);
            app.ChirpAttenuationKnob.Value = 0.5;
            app.Chirp_AttenText = uieditfield(app.ChirpsignalPanel, 'numeric');
            app.Chirp_AttenText.ValueChangedFcn = createCallbackFcn(app, @Chirp_AttenTextValueChanged, true);
            app.Chirp_AttenText.Value = 0.5;
            app.Chirp_FreText = uieditfield(app.ChirpsignalPanel, 'numeric');
            app.Chirp_FreText.ValueChangedFcn = createCallbackFcn(app, @Chirp_FreTextValueChanged, true);
            app.Chirp_FreText.Value = 2;
            app.TimeChangeKnobLabel = uilabel(app.ChirpsignalPanel);
            app.TimeChangeKnobLabel.Text = 'Time Change';
            app.ChirpTimeKnob = uiknob(app.ChirpsignalPanel, 'continuous');
            app.ChirpTimeKnob.ValueChangedFcn = createCallbackFcn(app, @ChirpTimeKnobValueChanged, true);
            app.ChirpTimeKnob.Value = 1;
            app.Chirp_TimeText = uieditfield(app.ChirpsignalPanel, 'numeric');
            app.Chirp_TimeText.ValueChangedFcn = createCallbackFcn(app, @Chirp_TimeTextValueChanged, true);
            app.Chirp_TimeText.Value = 1;
            app.PulsesignalPanel = uipanel(app.Panel_2);
            app.PulsesignalPanel.Title = 'Pulse signal';
            app.PulseWidthKnobLabel = uilabel(app.PulsesignalPanel);
            app.PulseWidthKnobLabel.Text = 'Pulse Width';
            app.PulseWidthKnob = uiknob(app.PulsesignalPanel, 'continuous');
            app.PulseWidthKnob.Limits = [0 10];
            app.PulseWidthKnob.ValueChangedFcn = createCallbackFcn(app, @PulseWidthKnobValueChanged, true);
            app.PulseWidthKnob.Value = 0.1;
            app.PulseAttenuationKnobLabel = uilabel(app.PulsesignalPanel);
            app.PulseAttenuationKnobLabel.Text = 'Pulse Attenuation';
            app.PulseAttenuationKnob = uiknob(app.PulsesignalPanel, 'continuous');
            app.PulseAttenuationKnob.Limits = [0 1];
            app.PulseAttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @PulseAttenuationKnobValueChanged, true);
            app.PulseAttenuationKnob.Value = 0.5;
            app.Pulse_WidthText = uieditfield(app.PulsesignalPanel, 'numeric');
            app.Pulse_WidthText.ValueChangedFcn = createCallbackFcn(app, @Pulse_WidthTextValueChanged, true);
            app.Pulse_WidthText.Value = 0.1;
            app.Pulse_AttenText = uieditfield(app.PulsesignalPanel, 'numeric');
            app.Pulse_AttenText.ValueChangedFcn = createCallbackFcn(app, @Pulse_AttenTextValueChanged, true);
            app.Pulse_AttenText.Value = 0.5;
            app.Delta_tEditFieldLabel = uilabel(app.Panel_2);
            app.Delta_tEditFieldLabel.Text = 'Delta_t';
            app.Delta_tEditField = uieditfield(app.Panel_2, 'numeric');
            app.Delta_tEditField.Value = 0.01;
            app.Length_tEditFieldLabel = uilabel(app.Panel_2);
            app.Length_tEditFieldLabel.Text = 'Length_t';
            app.Length_tEditField = uieditfield(app.Panel_2, 'numeric');
            app.Length_tEditField.Value = 6.28318530717959;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Correlation_final_190806

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end