classdef pulse_compression190914 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        Chirp1redPanel                matlab.ui.container.Panel
        GainKnobLabel                 matlab.ui.control.Label
        GainKnob                      matlab.ui.control.Knob
        ChangingTimeKnobLabel         matlab.ui.control.Label
        ChangingTimeKnob              matlab.ui.control.Knob
        StartingFrequencyKnobLabel    matlab.ui.control.Label
        StartingFrequencyKnob         matlab.ui.control.Knob
        ChangingFrequencyKnobLabel    matlab.ui.control.Label
        ChangingFrequencyKnob         matlab.ui.control.Knob
        Chirp1Gain                    matlab.ui.control.NumericEditField
        Chirp1ChangingTime            matlab.ui.control.NumericEditField
        Chirp1StartingFre             matlab.ui.control.NumericEditField
        Chirp1ChangingFre             matlab.ui.control.NumericEditField
        GHzLabel                      matlab.ui.control.Label
        GHzLabel_2                    matlab.ui.control.Label
        nsLabel                       matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        Chirp2bluePanel               matlab.ui.container.Panel
        GainKnob_2Label               matlab.ui.control.Label
        GainKnob_2                    matlab.ui.control.Knob
        ChangingTimeKnob_2Label       matlab.ui.control.Label
        ChangingTimeKnob_2            matlab.ui.control.Knob
        StartingFrequencyKnob_2Label  matlab.ui.control.Label
        StartingFrequencyKnob_2       matlab.ui.control.Knob
        ChangingFrequencyKnob_2Label  matlab.ui.control.Label
        ChangingFrequencyKnob_2       matlab.ui.control.Knob
        Chirp2Gain                    matlab.ui.control.NumericEditField
        Chirp2ChangingTime            matlab.ui.control.NumericEditField
        Chirp2StartingFre             matlab.ui.control.NumericEditField
        Chirp2ChangingFre             matlab.ui.control.NumericEditField
        nsLabel_2                     matlab.ui.control.Label
        GHzLabel_3                    matlab.ui.control.Label
        GHzLabel_4                    matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        delta_tEditFieldLabel         matlab.ui.control.Label
        delta_tEditField              matlab.ui.control.NumericEditField
        pulse_widthEditFieldLabel     matlab.ui.control.Label
        pulse_widthEditField          matlab.ui.control.NumericEditField
        nsLabel_3                     matlab.ui.control.Label
        nsLabel_4                     matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        StandardDeviationKnobLabel    matlab.ui.control.Label
        StandardDeviationKnob         matlab.ui.control.Knob
        NoiseKnobLabel                matlab.ui.control.Label
        NoiseKnob                     matlab.ui.control.DiscreteKnob
        SDtext                        matlab.ui.control.NumericEditField
        noisetext                     matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%*********************************** 초기값 설정 ***********************************

            delta_t = app.delta_tEditField.Value;
            pulse_width = app.pulse_widthEditField.Value;
            
            gain1 = app.Chirp1Gain.Value;
            gain2 = app.Chirp2Gain.Value;
            startf1 = app.Chirp1StartingFre.Value;
            startf2 = app.Chirp2StartingFre.Value;
            changef1 = app.Chirp1ChangingFre.Value;
            changef2 = app.Chirp2ChangingFre.Value;
            time1 = app.Chirp1ChangingTime.Value;
            time2 = app.Chirp2ChangingTime.Value;
            noise = app.noisetext.Value;
            noiseSD = app.SDtext.Value;
%****************************************************************************            
            t = 0 : delta_t : pulse_width;      % 0 : 0.01 : 10 [ns]
            N = length(t);      
            df = (1/delta_t)/N;
            f = 0 : df : (1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);
            
%----------------------------------------------------------------------------

           % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ];
           % r = r; % + randn(1,length(r)); gaussian noise
           r1 = [ zeros(1,N), y1, zeros(1,N) ];
           r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
           
           % r = r1 + r2; 
           r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*y1';
                end
                
%------------------------ correlation processor ---------------------------

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);
            
%--------------------------- Stretch Processor ----------------------------

            %% window : kaiser
            
            As = 50; % 60[dB]
            beta = 0.1102*(As-8.7) + 0.3; % As >= 50
            % beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21) % 21 < As < 50 
            
            kai = (kaiser(N,beta))';
            Xt = (y1 + y2) .* kai;
            Xsp = fft(Xt);
           
            
%--------------------------------------------------------------------------

            plot(app.UIAxes,t,y1,'r');
            hold(app.UIAxes);
            plot(app.UIAxes,t,y2,'b');
            
            plot(app.UIAxes2,f,Xc1,'r');
            hold(app.UIAxes2);
            plot(app.UIAxes2,f,Xc2,'b');
            
            plot(app.UIAxes3,(0:length(c)-1)*delta_t, abs(c));
            plot(app.UIAxes4, t, abs(Xsp));
  
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = app.GainKnob.Value;
            app.Chirp1Gain.Value = value;
        end

        % Value changed function: Chirp1Gain
        function Chirp1GainValueChanged(app, event)
            value = app.Chirp1Gain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: ChangingTimeKnob
        function ChangingTimeKnobValueChanged(app, event)
            value = app.ChangingTimeKnob.Value;
            app.Chirp1ChangingTime.Value = value;
        end

        % Value changed function: Chirp1ChangingTime
        function Chirp1ChangingTimeValueChanged(app, event)
            value = app.Chirp1ChangingTime.Value;
            app.ChangingTimeKnob.Value = value;
        end

        % Value changed function: StartingFrequencyKnob
        function StartingFrequencyKnobValueChanged(app, event)
            value = app.StartingFrequencyKnob.Value;
            app.Chirp1StartingFre.Value = value;
        end

        % Value changed function: Chirp1StartingFre
        function Chirp1StartingFreValueChanged(app, event)
            value = app.Chirp1StartingFre.Value;
            app.StartingFrequencyKnob.Value = value;
        end

        % Value changed function: ChangingFrequencyKnob
        function ChangingFrequencyKnobValueChanged(app, event)
            value = app.ChangingFrequencyKnob.Value;
            app.Chirp1ChangingFre.Value = value;
        end

        % Value changed function: Chirp1ChangingFre
        function Chirp1ChangingFreValueChanged(app, event)
            value = app.Chirp1ChangingFre.Value;
            app.ChangingFrequencyKnob.Value = value;
        end

        % Value changed function: GainKnob_2
        function GainKnob_2ValueChanged(app, event)
            value = app.GainKnob_2.Value;
            app.Chirp2Gain.Value = value;
        end

        % Value changed function: Chirp2Gain
        function Chirp2GainValueChanged(app, event)
            value = app.Chirp2Gain.Value;
            app.GainKnob_2.Value = value;
        end

        % Value changed function: ChangingTimeKnob_2
        function ChangingTimeKnob_2ValueChanged(app, event)
            value = app.ChangingTimeKnob_2.Value;
            app.Chirp2ChangingTime.Value = value;
        end

        % Value changed function: Chirp2ChangingTime
        function Chirp2ChangingTimeValueChanged(app, event)
            value = app.Chirp2ChangingTime.Value;
            app.ChangingTimeKnob_2.Value = value;
        end

        % Value changed function: StartingFrequencyKnob_2
        function StartingFrequencyKnob_2ValueChanged(app, event)
            value = app.StartingFrequencyKnob_2.Value;
            app.Chirp2StartingFre.Value = value;
        end

        % Value changed function: Chirp2StartingFre
        function Chirp2StartingFreValueChanged(app, event)
            value = app.Chirp2StartingFre.Value;
            app.StartingFrequencyKnob_2.Value = value;
        end

        % Value changed function: ChangingFrequencyKnob_2
        function ChangingFrequencyKnob_2ValueChanged(app, event)
            value = app.ChangingFrequencyKnob_2.Value;
            app.Chirp2ChangingFre.Value = value;
        end

        % Value changed function: Chirp2ChangingFre
        function Chirp2ChangingFreValueChanged(app, event)
            value = app.Chirp2ChangingFre.Value;
            app.ChangingFrequencyKnob_2.Value = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%*********************************** start button *********************************  

            delta_t = app.delta_tEditField.Value;
            pulse_width = app.pulse_widthEditField.Value;
            
            gain1 = app.Chirp1Gain.Value;
            gain2 = app.Chirp2Gain.Value;
            startf1 = app.Chirp1StartingFre.Value;
            startf2 = app.Chirp2StartingFre.Value;
            changef1 = app.Chirp1ChangingFre.Value;
            changef2 = app.Chirp2ChangingFre.Value;
            time1 = app.Chirp1ChangingTime.Value;
            time2 = app.Chirp2ChangingTime.Value;
            noise = app.noisetext.Value;
            noiseSD = app.SDtext.Value;
%**********************************************************************            
            t = 0 : delta_t : pulse_width;
            N = length(t);
            df = (1/delta_t)/N;
            f = 0:df:(1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);  
            
%-----------------------------------------------------------------------
            
            % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ];
            % r = r; % + randn(1,length(r)); gaussian noise
            r1 = [ zeros(1,N), y1, zeros(1,N) ];
            r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
            
            % r = r1 + r2;
            r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*y1';
                end
                
%------------------------ correlation processor ------------------------------  

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);

%--------------------------- Stretch Processor ----------------------------

            %% window : kaiser
            
            As = 50; % 60[dB]
            beta = 0.1102*(As-8.7) + 0.3; % As >= 50
            % beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21) % 21 < As < 50 
            
            kai = (kaiser(N,beta))';
            Xt = (y1 + y2) .* kai;
            Xsp = fft(Xt);
           
            
%--------------------------------------------------------------------------           
            
            plot(app.UIAxes,t,y1,'r');
            hold(app.UIAxes);
            plot(app.UIAxes,t,y2,'b');
            
            plot(app.UIAxes2,f,Xc1,'r');
            hold(app.UIAxes2);
            plot(app.UIAxes2,f,Xc2,'b');
            
            plot(app.UIAxes3,(0:length(c)-1)*delta_t, abs(c));
            plot(app.UIAxes4, t, abs(Xsp));
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
%************************************ reset button **********************************
            delta_t = 0.01;
            app.delta_tEditField.Value = delta_t;
            pulse_width = 10;
            app.pulse_widthEditField.Value = pulse_width;
            
            gain1 = 1;
            gain2 = 1;
            app.GainKnob.Value = gain1;
            app.GainKnob_2.Value = gain2;
            app.Chirp1Gain.Value = gain1;
            app.Chirp2Gain.Value = gain2;
            
            startf1 = 1;
            startf2 = 1;
            app.StartingFrequencyKnob.Value = startf1;
            app.StartingFrequencyKnob_2.Value = startf2;
            app.Chirp1StartingFre.Value = startf1;
            app.Chirp2StartingFre.Value = startf2;
            
            changef1 = 2;
            changef2 = 2;
            app.ChangingFrequencyKnob.Value = changef1;
            app.ChangingFrequencyKnob_2.Value = changef2;
            app.Chirp1ChangingFre.Value = changef1;
            app.Chirp2ChangingFre.Value = changef2;
            
            time1 = 1;
            time2 = 1;
            app.ChangingTimeKnob.Value = time1;
            app.ChangingTimeKnob_2.Value = time2;
            app.Chirp1ChangingTime.Value = time1;
            app.Chirp2ChangingTime.Value = time2;
            
            noise = 'Off';
            app.NoiseKnob.Value = 'Off';
            app.noisetext.Value = 'Off';
            noiseSD = 1;
            app.SDtext.Value = 1;
            app.StandardDeviationKnob.Value = 1;
%*******************************************************************************            
            t = 0 : delta_t : pulse_width;
            N = length(t);
            df = (1/delta_t)/N;
            f = 0:df:(1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);
            
%----------------------------------------------------------------------------------   

            % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ]; 
            % r = r; % + randn(1,length(r)); gaussian noise
            r1 = [ zeros(1,N), y1, zeros(1,N) ];
            r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
             
            % r = r1 + r2;
            r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*y1';
                end
                
%--------------------------- correlation processor -------------------------------

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);
            
%--------------------------- Stretch Processor ----------------------------

            %% window : kaiser
            
            As = 50; % 60[dB]
            beta = 0.1102*(As-8.7) + 0.3; % As >= 50
            % beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21) % 21 < As < 50 
            
            kai = (kaiser(N,beta))';
            Xt = (y1 + y2) .* kai;
            Xsp = fft(Xt);
           
            
%--------------------------------------------------------------------------     
            
            plot(app.UIAxes,t,y1,'r');
            hold(app.UIAxes);
            plot(app.UIAxes,t,y2,'b');
            
            plot(app.UIAxes2,f,Xc1,'r');
            hold(app.UIAxes2);
            plot(app.UIAxes2,f,Xc2,'b');
            
            plot(app.UIAxes3,(0:length(c)-1)*delta_t, abs(c));
            plot(app.UIAxes4, t, abs(Xsp));
            
        end

        % Value changed function: NoiseKnob
        function NoiseKnobValueChanged(app, event)
            value = app.NoiseKnob.Value;
            app.noisetext.Value = value;
        end

        % Value changed function: noisetext
        function noisetextValueChanged(app, event)
            value = app.noisetext.Value;
            app.NoiseKnob.Value = value;
        end

        % Value changed function: StandardDeviationKnob
        function StandardDeviationKnobValueChanged(app, event)
            value = app.StandardDeviationKnob.Value;
            app.SDtext.Value = value;
        end

        % Value changed function: SDtext
        function SDtextValueChanged(app, event)
            value = app.SDtext.Value;
            app.StandardDeviationKnob.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Chirp signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Fast Fourier Transform')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.Chirp1redPanel = uipanel(app.UIFigure);
            app.Chirp1redPanel.Title = 'Chirp 1 (red)';
            app.GainKnobLabel = uilabel(app.Chirp1redPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.Chirp1redPanel, 'continuous');
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.ChangingTimeKnobLabel = uilabel(app.Chirp1redPanel);
            app.ChangingTimeKnobLabel.Text = 'Changing Time';
            app.ChangingTimeKnob = uiknob(app.Chirp1redPanel, 'continuous');
            app.ChangingTimeKnob.ValueChangedFcn = createCallbackFcn(app, @ChangingTimeKnobValueChanged, true);
            app.StartingFrequencyKnobLabel = uilabel(app.Chirp1redPanel);
            app.StartingFrequencyKnobLabel.Text = 'Starting Frequency';
            app.StartingFrequencyKnob = uiknob(app.Chirp1redPanel, 'continuous');
            app.StartingFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @StartingFrequencyKnobValueChanged, true);
            app.ChangingFrequencyKnobLabel = uilabel(app.Chirp1redPanel);
            app.ChangingFrequencyKnobLabel.Text = 'Changing Frequency';
            app.ChangingFrequencyKnob = uiknob(app.Chirp1redPanel, 'continuous');
            app.ChangingFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @ChangingFrequencyKnobValueChanged, true);
            app.Chirp1Gain = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1Gain.ValueChangedFcn = createCallbackFcn(app, @Chirp1GainValueChanged, true);
            app.Chirp1Gain.Value = 1;
            app.Chirp1ChangingTime = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1ChangingTime.ValueChangedFcn = createCallbackFcn(app, @Chirp1ChangingTimeValueChanged, true);
            app.Chirp1ChangingTime.Value = 1;
            app.Chirp1StartingFre = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1StartingFre.ValueChangedFcn = createCallbackFcn(app, @Chirp1StartingFreValueChanged, true);
            app.Chirp1StartingFre.Value = 1;
            app.Chirp1ChangingFre = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1ChangingFre.ValueChangedFcn = createCallbackFcn(app, @Chirp1ChangingFreValueChanged, true);
            app.Chirp1ChangingFre.Value = 2;
            app.GHzLabel = uilabel(app.Chirp1redPanel);
            app.GHzLabel.Text = '[GHz]';
            app.GHzLabel_2 = uilabel(app.Chirp1redPanel);
            app.GHzLabel_2.Text = '[GHz]';
            app.nsLabel = uilabel(app.Chirp1redPanel);
            app.nsLabel.Text = '[ns]';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Chirp2bluePanel = uipanel(app.UIFigure);
            app.Chirp2bluePanel.Title = 'Chirp 2 (blue)';
            app.GainKnob_2Label = uilabel(app.Chirp2bluePanel);
            app.GainKnob_2Label.Text = 'Gain';
            app.GainKnob_2 = uiknob(app.Chirp2bluePanel, 'continuous');
            app.GainKnob_2.ValueChangedFcn = createCallbackFcn(app, @GainKnob_2ValueChanged, true);
            app.ChangingTimeKnob_2Label = uilabel(app.Chirp2bluePanel);
            app.ChangingTimeKnob_2Label.Text = 'Changing Time';
            app.ChangingTimeKnob_2 = uiknob(app.Chirp2bluePanel, 'continuous');
            app.ChangingTimeKnob_2.ValueChangedFcn = createCallbackFcn(app, @ChangingTimeKnob_2ValueChanged, true);
            app.StartingFrequencyKnob_2Label = uilabel(app.Chirp2bluePanel);
            app.StartingFrequencyKnob_2Label.Text = 'Starting Frequency';
            app.StartingFrequencyKnob_2 = uiknob(app.Chirp2bluePanel, 'continuous');
            app.StartingFrequencyKnob_2.ValueChangedFcn = createCallbackFcn(app, @StartingFrequencyKnob_2ValueChanged, true);
            app.ChangingFrequencyKnob_2Label = uilabel(app.Chirp2bluePanel);
            app.ChangingFrequencyKnob_2Label.Text = 'Changing Frequency';
            app.ChangingFrequencyKnob_2 = uiknob(app.Chirp2bluePanel, 'continuous');
            app.ChangingFrequencyKnob_2.ValueChangedFcn = createCallbackFcn(app, @ChangingFrequencyKnob_2ValueChanged, true);
            app.Chirp2Gain = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2Gain.ValueChangedFcn = createCallbackFcn(app, @Chirp2GainValueChanged, true);
            app.Chirp2Gain.Value = 1;
            app.Chirp2ChangingTime = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2ChangingTime.ValueChangedFcn = createCallbackFcn(app, @Chirp2ChangingTimeValueChanged, true);
            app.Chirp2ChangingTime.Value = 1;
            app.Chirp2StartingFre = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2StartingFre.ValueChangedFcn = createCallbackFcn(app, @Chirp2StartingFreValueChanged, true);
            app.Chirp2StartingFre.Value = 1;
            app.Chirp2ChangingFre = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2ChangingFre.ValueChangedFcn = createCallbackFcn(app, @Chirp2ChangingFreValueChanged, true);
            app.Chirp2ChangingFre.Value = 2;
            app.nsLabel_2 = uilabel(app.Chirp2bluePanel);
            app.nsLabel_2.Text = '[ns]';
            app.GHzLabel_3 = uilabel(app.Chirp2bluePanel);
            app.GHzLabel_3.Text = '[GHz]';
            app.GHzLabel_4 = uilabel(app.Chirp2bluePanel);
            app.GHzLabel_4.Text = '[GHz]';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Correlation Processor')
            xlabel(app.UIAxes3, 'Wave')
            ylabel(app.UIAxes3, 'Amplitude')
            app.delta_tEditFieldLabel = uilabel(app.UIFigure);
            app.delta_tEditFieldLabel.Text = 'delta_t';
            app.delta_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.delta_tEditField.Value = 0.01;
            app.pulse_widthEditFieldLabel = uilabel(app.UIFigure);
            app.pulse_widthEditFieldLabel.Text = 'pulse_width';
            app.pulse_widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.pulse_widthEditField.Value = 10;
            app.nsLabel_3 = uilabel(app.UIFigure);
            app.nsLabel_3.Text = '[ns]';
            app.nsLabel_4 = uilabel(app.UIFigure);
            app.nsLabel_4.Text = '[ns]';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Stretch Processor')
            xlabel(app.UIAxes4, 'Wave')
            ylabel(app.UIAxes4, 'Amplitude')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.StandardDeviationKnobLabel = uilabel(app.Panel);
            app.StandardDeviationKnobLabel.Text = 'Standard Deviation';
            app.StandardDeviationKnob = uiknob(app.Panel, 'continuous');
            app.StandardDeviationKnob.Limits = [0 10];
            app.StandardDeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationKnobValueChanged, true);
            app.StandardDeviationKnob.Value = 1;
            app.NoiseKnobLabel = uilabel(app.Panel);
            app.NoiseKnobLabel.Text = 'Noise';
            app.NoiseKnob = uiknob(app.Panel, 'discrete');
            app.NoiseKnob.Items = {'Off', 'Gaussian'};
            app.NoiseKnob.ValueChangedFcn = createCallbackFcn(app, @NoiseKnobValueChanged, true);
            app.SDtext = uieditfield(app.Panel, 'numeric');
            app.SDtext.ValueChangedFcn = createCallbackFcn(app, @SDtextValueChanged, true);
            app.SDtext.Value = 1;
            app.noisetext = uieditfield(app.Panel, 'text');
            app.noisetext.ValueChangedFcn = createCallbackFcn(app, @noisetextValueChanged, true);
            app.noisetext.Value = 'Off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pulse_compression190914

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end