classdef pulse_compression190929 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxesChirp1                matlab.ui.control.UIAxes
        UIAxesTotal                 matlab.ui.control.UIAxes
        Chirp1redPanel              matlab.ui.container.Panel
        Chirp1Gain                  matlab.ui.control.NumericEditField
        Chirp1ChangingTime          matlab.ui.control.NumericEditField
        Chirp1StartingFre           matlab.ui.control.NumericEditField
        Chirp1ChangingFre           matlab.ui.control.NumericEditField
        GHzLabel                    matlab.ui.control.Label
        GHzLabel_2                  matlab.ui.control.Label
        nsLabel                     matlab.ui.control.Label
        GainLabel                   matlab.ui.control.Label
        ChangingTimeLabel           matlab.ui.control.Label
        StartingFrequencyLabel      matlab.ui.control.Label
        ChangingFrequencyLabel      matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        Chirp2bluePanel             matlab.ui.container.Panel
        Chirp2Gain                  matlab.ui.control.NumericEditField
        Chirp2ChangingTime          matlab.ui.control.NumericEditField
        Chirp2StartingFre           matlab.ui.control.NumericEditField
        Chirp2ChangingFre           matlab.ui.control.NumericEditField
        nsLabel_2                   matlab.ui.control.Label
        GHzLabel_3                  matlab.ui.control.Label
        GHzLabel_4                  matlab.ui.control.Label
        GainLabel_2                 matlab.ui.control.Label
        ChangingTimeLabel_2         matlab.ui.control.Label
        StartingFrequencyLabel_2    matlab.ui.control.Label
        ChangingFrequencyLabel_2    matlab.ui.control.Label
        UIAxesCorrelationProcessor  matlab.ui.control.UIAxes
        UIAxesCostascode            matlab.ui.control.UIAxes
        NoisePanel                  matlab.ui.container.Panel
        StandardDeviationKnobLabel  matlab.ui.control.Label
        StandardDeviationKnob       matlab.ui.control.Knob
        NoiseKnobLabel              matlab.ui.control.Label
        NoiseKnob                   matlab.ui.control.DiscreteKnob
        SDtext                      matlab.ui.control.NumericEditField
        noisetext                   matlab.ui.control.EditField
        ParameterPanel              matlab.ui.container.Panel
        nsLabel_3                   matlab.ui.control.Label
        delta_tEditFieldLabel       matlab.ui.control.Label
        delta_tEditField            matlab.ui.control.NumericEditField
        nsLabel_4                   matlab.ui.control.Label
        pulse_widthEditFieldLabel   matlab.ui.control.Label
        pulse_widthEditField        matlab.ui.control.NumericEditField
        WindowPanel                 matlab.ui.container.Panel
        WindowKnobLabel             matlab.ui.control.Label
        WindowKnob                  matlab.ui.control.DiscreteKnob
        windowChooseText            matlab.ui.control.EditField
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        WindowAmplitudeEditField    matlab.ui.control.NumericEditField
        KaiserAsLabel               matlab.ui.control.Label
        KaiserAsEditField           matlab.ui.control.NumericEditField
        KaiserBetaEditFieldLabel    matlab.ui.control.Label
        KaiserBetaEditField         matlab.ui.control.NumericEditField
        dBLabel                     matlab.ui.control.Label
        UIAxesChirp2                matlab.ui.control.UIAxes
        UIAxesWindow                matlab.ui.control.UIAxes
        UIAxesStretchProcessor      matlab.ui.control.UIAxes
        UIAxesFrequencyHopping      matlab.ui.control.UIAxes
        OutputPanel                 matlab.ui.container.Panel
        Delta_tEditFieldLabel       matlab.ui.control.Label
        Delta_tEditField            matlab.ui.control.NumericEditField
        nsLabel_5                   matlab.ui.control.Label
        DistanceEditFieldLabel      matlab.ui.control.Label
        DistanceEditField           matlab.ui.control.NumericEditField
        KmLabel                     matlab.ui.control.Label
        ErrorRateEditFieldLabel     matlab.ui.control.Label
        ErrorRateEditField          matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%*********************************** 초기값 설정 ***********************************

            delta_t = app.delta_tEditField.Value;
            pulse_width = app.pulse_widthEditField.Value;
            
            gain1 = app.Chirp1Gain.Value;
            gain2 = app.Chirp2Gain.Value;
            startf1 = app.Chirp1StartingFre.Value;
            startf2 = app.Chirp2StartingFre.Value;
            changef1 = app.Chirp1ChangingFre.Value;
            changef2 = app.Chirp2ChangingFre.Value;
            time1 = app.Chirp1ChangingTime.Value;
            time2 = app.Chirp2ChangingTime.Value;
            noise = app.noisetext.Value;
            noiseSD = app.SDtext.Value;
            
            windowAmp = app.WindowAmplitudeEditField.Value;
            beta = app.KaiserBetaEditField.Value;
            As = app.KaiserAsEditField.Value;
%****************************************************************************            
            t = 0 : delta_t : pulse_width;      % 0 : 0.01 : 10 [ns]
            N = length(t);      
            df = (1/delta_t)/N;
            f = 0 : df : (1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);
            
%----------------------------------------------------------------------------

           % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ];
           % r = r; % + randn(1,length(r)); gaussian noise
           r1 = [ zeros(1,N), y1, zeros(1,N) ];
           r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
           
           % r = r1 + r2; 
           r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*(y1+y2)';
                end
                
%------------------------ correlation processor ---------------------------

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);
            
%--------------------------- Stretch Processor ----------------------------
    %@@@@@@@@@@@@@@@@@@@ rectangular @@@@@@@@@@@@@@@@@@@@

            rect=zeros(1,length(t)); %length(c)
            for i=1:length(t) %length(t)+N+1
                if t(i) > 0 && t(i) < pulse_width
                    rect(i) = 1; 
                end
            end
            
            rect_yt1 = y1.*rect;
            rect_yt2 = y2.*rect;
            
            rect_Xt1 = fft(rect_yt1);
            rect_Xt2 = fft(rect_yt2);
            
            rect_Xt = rect_Xt1 .* rect_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@ hanning @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hanning
        % 1/2 .* (1 - cos(2*pi*n/(M-1)))
        hanning = 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
    
        hanning_yt1 = y1 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
        hanning_yt2 = y2 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));

        hanning_Xt1 = fft(hanning_yt1);
        hanning_Xt2 = fft(hanning_yt2);

        hanning_Xt = hanning_Xt1 .* hanning_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ hamming @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hamming 수정 필요
        % 0.54 - 0.46 * cos(2*pi*n/(M-1))
        hamming = 0.54 - 0.46 * cos(2*pi*t/(length(t)-1));

        hamming_yt1 = y1 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));
        hamming_yt2 = y2 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));

        hamming_Xt1 = fft(hamming_yt1);
        hamming_Xt2 = fft(hamming_yt2);

        hamming_Xt = hamming_Xt1 .* hamming_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ blackman @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % blackman 수정필요
        % 0.42 - 0.5 * cos(2*pi*n/(M-1)) + 0.08 * cos(4*pi*n/(M-1))
        blackman = 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1));

        blackman_yt1 = y1 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));
        blackman_yt2 = y2 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));

        blackman_Xt1 = fft(blackman_yt1);
        blackman_Xt2 = fft(blackman_yt2);

        blackman_Xt = blackman_Xt1 .* blackman_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@@ kaiser @@@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % kaiser
        % i0*[ (alpa * sqrt({(M-1)/2)}^2 - {n - (M-1)/2)}^2) ) ] / i0*[(alpa * {(M-1)/2)}^2)]
        As = 60; % 60[dB]
        if (As >= 50)
        beta = 0.1102*(As-8.7) + 0.3; % As >= 50
        elseif (As < 50)
        beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21); % 21 < As < 50
        end

        kai = (kaiser(length(t),beta))';

        kaiser_yt1 = y1 .* kai;
        kaiser_yt2 = y2 .* kai;

        kaiser_Xt1 = fft(kaiser_yt1);
        kaiser_Xt2 = fft(kaiser_yt2);

        kaiser_Xt = kaiser_Xt1 .* kaiser_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@
    
        if isequal(app.windowChooseText.Value, 'Off')
            window = 1;
            graph = (y1+y2)*1;
        elseif isequal(app.windowChooseText.Value, 'Rect')
            window = windowAmp * rect;
            graph = windowAmp * rect_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hanning')
            window = windowAmp * hanning;
            graph = windowAmp * hanning_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hamming')
            window = windowAmp * hamming;
            graph = windowAmp * hamming_Xt;
        elseif isequal(app.windowChooseText.Value, 'Blackman')
            window = windowAmp * blackman;
            graph = windowAmp * blackman_Xt;
        elseif isequal(app.windowChooseText.Value, 'Kaiser')
            window = windowAmp * kai;
            graph = windowAmp * kaiser_Xt;
        end
    
%-------------------------------- Graphic -----------------------------------

            plot(app.UIAxesChirp1,t,y1,'b');
            plot(app.UIAxesChirp2,t,y2,'b');
            
            plot(app.UIAxesTotal,t,y1+y2,'b');
            plot(app.UIAxesWindow,t,window,'b');
            
            plot(app.UIAxesCorrelationProcessor,(0:length(c)-1)*delta_t, abs(c),'b');
            plot(app.UIAxesStretchProcessor,(0:length(t)-1)*delta_t,abs(graph));
            
            %plot(app.UIAxesCostascode,);
            %plot(app.UIAxesFrequencyHopping,);

  
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%*********************************** start button *********************************  

            delta_t = app.delta_tEditField.Value;
            pulse_width = app.pulse_widthEditField.Value;
            
            gain1 = app.Chirp1Gain.Value;
            gain2 = app.Chirp2Gain.Value;
            startf1 = app.Chirp1StartingFre.Value;
            startf2 = app.Chirp2StartingFre.Value;
            changef1 = app.Chirp1ChangingFre.Value;
            changef2 = app.Chirp2ChangingFre.Value;
            time1 = app.Chirp1ChangingTime.Value;
            time2 = app.Chirp2ChangingTime.Value;
            noise = app.noisetext.Value;
            noiseSD = app.SDtext.Value;
            
            windowAmp = app.WindowAmplitudeEditField.Value;
            
%**********************************************************************            
            t = 0 : delta_t : pulse_width;
            N = length(t);
            df = (1/delta_t)/N;
            f = 0:df:(1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);  
            
%-----------------------------------------------------------------------
            
            % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ];
            % r = r; % + randn(1,length(r)); gaussian noise
            r1 = [ zeros(1,N), y1, zeros(1,N) ];
            r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
            
            % r = r1 + r2;
            r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*(y1+y2)';
                end
                
%------------------------ correlation processor ------------------------------  

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);

%--------------------------- Stretch Processor ----------------------------
    %@@@@@@@@@@@@@@@@@@@ rectangular @@@@@@@@@@@@@@@@@@@@

            rect=zeros(1,length(t)); %length(c)
            for i=1:length(t) %length(t)+N+1
                if t(i) > 0 && t(i) < pulse_width
                    rect(i) = 1; 
                end
            end
            
            rect_yt1 = y1.*rect;
            rect_yt2 = y2.*rect;
            
            rect_Xt1 = fft(rect_yt1);
            rect_Xt2 = fft(rect_yt2);
            
            rect_Xt = rect_Xt1 .* rect_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@ hanning @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hanning
        % 1/2 .* (1 - cos(2*pi*n/(M-1)))
        hanning = 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
    
        hanning_yt1 = y1 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
        hanning_yt2 = y2 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));

        hanning_Xt1 = fft(hanning_yt1);
        hanning_Xt2 = fft(hanning_yt2);

        hanning_Xt = hanning_Xt1 .* hanning_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ hamming @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hamming 수정 필요
        % 0.54 - 0.46 * cos(2*pi*n/(M-1))
        hamming = 0.54 - 0.46 * cos(2*pi*t/(length(t)-1));

        hamming_yt1 = y1 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));
        hamming_yt2 = y2 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));

        hamming_Xt1 = fft(hamming_yt1);
        hamming_Xt2 = fft(hamming_yt2);

        hamming_Xt = hamming_Xt1 .* hamming_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ blackman @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % blackman 수정필요
        % 0.42 - 0.5 * cos(2*pi*n/(M-1)) + 0.08 * cos(4*pi*n/(M-1))
        blackman = 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1));

        blackman_yt1 = y1 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));
        blackman_yt2 = y2 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));

        blackman_Xt1 = fft(blackman_yt1);
        blackman_Xt2 = fft(blackman_yt2);

        blackman_Xt = blackman_Xt1 .* blackman_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@@ kaiser @@@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % kaiser
        % i0*[ (alpa * sqrt({(M-1)/2)}^2 - {n - (M-1)/2)}^2) ) ] / i0*[(alpa * {(M-1)/2)}^2)]
        As = app.KaiserAsEditField.Value; % 60[dB]
        if (As >= 50)
        beta = 0.1102*(As-8.7) + 0.3; % As >= 50
        elseif (As < 50)
        beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21); % 21 < As < 50
        end
        app.KaiserBetaEditField.Value = beta;
        
        % beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21) % 21 < As < 50 

        kai = (kaiser(length(t),beta))';

        kaiser_yt1 = y1 .* kai;
        kaiser_yt2 = y2 .* kai;

        kaiser_Xt1 = fft(kaiser_yt1);
        kaiser_Xt2 = fft(kaiser_yt2);

        kaiser_Xt = kaiser_Xt1 .* kaiser_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@
    
        if isequal(app.windowChooseText.Value, 'Off')
            window = 1;
            graph = (y1+y2)*1;
        elseif isequal(app.windowChooseText.Value, 'Rect')
            window = windowAmp * rect;
            graph = windowAmp * rect_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hanning')
            window = windowAmp * hanning;
            graph = windowAmp * hanning_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hamming')
            window = windowAmp * hamming;
            graph = windowAmp * hamming_Xt;
        elseif isequal(app.windowChooseText.Value, 'Blackman')
            window = windowAmp * blackman;
            graph = windowAmp * blackman_Xt;
        elseif isequal(app.windowChooseText.Value, 'Kaiser')
            window = windowAmp * kai;
            graph = windowAmp * kaiser_Xt;
        end
    
%-------------------------------- Graphic -----------------------------------

            plot(app.UIAxesChirp1,t,y1,'b');
            plot(app.UIAxesChirp2,t,y2,'b');
            
            plot(app.UIAxesTotal,t,y1+y2,'b');
            plot(app.UIAxesWindow,t,window,'b');
            
            plot(app.UIAxesCorrelationProcessor,(0:length(c)-1)*delta_t, abs(c),'b');
            plot(app.UIAxesStretchProcessor,(0:length(t)-1)*delta_t,abs(graph));
            
            %plot(app.UIAxesCostascode,);
            %plot(app.UIAxesFrequencyHopping,);

            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
%************************************ reset button **********************************
            delta_t = 0.01;
            app.delta_tEditField.Value = delta_t;
            pulse_width = 10;
            app.pulse_widthEditField.Value = pulse_width;
            
            gain1 = 1;
            gain2 = 1;
            app.Chirp1Gain.Value = gain1;
            app.Chirp2Gain.Value = gain2;
            
            startf1 = 1;
            startf2 = 1;
            app.Chirp1StartingFre.Value = startf1;
            app.Chirp2StartingFre.Value = startf2;
            
            changef1 = 2;
            changef2 = 2;
            app.Chirp1ChangingFre.Value = changef1;
            app.Chirp2ChangingFre.Value = changef2;
            
            time1 = 1;
            time2 = 1;
            app.Chirp1ChangingTime.Value = time1;
            app.Chirp2ChangingTime.Value = time2;
            
            noise = 'Off';
            app.noisetext.Value = 'Off';
            noiseSD = 1;
            app.SDtext.Value = 1;
            
            windowAmp = 1;
            app.WindowAmplitudeEditField.Value = windowAmp;
            
            As = 60;
            beta = 0.1102 * (As - 8.7) + 0.3;
            app.KaiserAsEditField.Value = As;
            app.KaiserBetaEditField.Value = beta;
%*******************************************************************************            
            t = 0 : delta_t : pulse_width;
            N = length(t);
            df = (1/delta_t)/N;
            f = 0:df:(1/delta_t)-df;
            
            if isequal(noise,'Off')
                number = 0;
            elseif isequal(noise,'Gaussian')
                number = 1;
            end
            
            % chirp(샘플링, 시작주파수, 참조시간, 순시주파수)
            y1 = gain1 * chirp(t, startf1, time1, changef1); 
            y2 = gain2 * chirp(t, startf2, time2, changef2);
            
%----------------------------------------------------------------------------------   

            % r = [ zeros(1,N), y1, zeros(1,N), y2, zeros(1,N) ]; 
            % r = r; % + randn(1,length(r)); gaussian noise
            r1 = [ zeros(1,N), y1, zeros(1,N) ];
            r2 = [ zeros(1,N+50), y2, zeros(1,N-50) ];
             
            % r = r1 + r2;
            r = (r1 + r2) + randn(1,length(r1 + r2))*number*noiseSD;    
 
            ct1 = length(r)-N;
                for a = 0:ct1
                   c(a+1) = r(a+1:a+N)*(y1+y2)';
                end
                
%--------------------------- correlation processor -------------------------------

            Xc1 = fft(y1);
            Xc2 = fft(y2);
            Xc = Xc1 .* Xc2;
            ifft(Xc);
            
%--------------------------- Stretch Processor ----------------------------
    %@@@@@@@@@@@@@@@@@@@ rectangular @@@@@@@@@@@@@@@@@@@@

            rect=zeros(1,length(t)); %length(c)
            for i=1:length(t) %length(t)+N+1
                if t(i) > 0 && t(i) < pulse_width
                    rect(i) = 1; 
                end
            end
            
            rect_yt1 = y1.*rect;
            rect_yt2 = y2.*rect;
            
            rect_Xt1 = fft(rect_yt1);
            rect_Xt2 = fft(rect_yt2);
            
            rect_Xt = rect_Xt1 .* rect_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@ hanning @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hanning
        % 1/2 .* (1 - cos(2*pi*n/(M-1)))
        hanning = 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
    
        hanning_yt1 = y1 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));
        hanning_yt2 = y2 .* 1/2 .* (1 - cos(2*pi*t/(length(t)-1)));

        hanning_Xt1 = fft(hanning_yt1);
        hanning_Xt2 = fft(hanning_yt2);

        hanning_Xt = hanning_Xt1 .* hanning_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ hamming @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % hamming 수정 필요
        % 0.54 - 0.46 * cos(2*pi*n/(M-1))
        hamming = 0.54 - 0.46 * cos(2*pi*t/(length(t)-1));

        hamming_yt1 = y1 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));
        hamming_yt2 = y2 .* (0.54 - 0.46 * cos(2*pi*t/(length(t)-1)));

        hamming_Xt1 = fft(hamming_yt1);
        hamming_Xt2 = fft(hamming_yt2);

        hamming_Xt = hamming_Xt1 .* hamming_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@ blackman @@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % blackman 수정필요
        % 0.42 - 0.5 * cos(2*pi*n/(M-1)) + 0.08 * cos(4*pi*n/(M-1))
        blackman = 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1));

        blackman_yt1 = y1 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));
        blackman_yt2 = y2 .* (0.42 - (0.5 .* cos(2*pi*t/(length(t)-1))) + (0.08 .* cos(4*pi*t/(length(t)-1))));

        blackman_Xt1 = fft(blackman_yt1);
        blackman_Xt2 = fft(blackman_yt2);

        blackman_Xt = blackman_Xt1 .* blackman_Xt2;
    
    %@@@@@@@@@@@@@@@@@@@@@@ kaiser @@@@@@@@@@@@@@@@@@@@@@@@
        % (0 <= n <= M - 1)
        % kaiser
        % i0*[ (alpa * sqrt({(M-1)/2)}^2 - {n - (M-1)/2)}^2) ) ] / i0*[(alpa * {(M-1)/2)}^2)]
        As = 60; % 60[dB]
        beta = 0.1102*(As-8.7) + 0.3; % As >= 50
        % beta = 0.5842*(As-21)^0.4 + 0.07886*(As-21) % 21 < As < 50 

        kai = (kaiser(length(t),beta))';

        kaiser_yt1 = y1 .* kai;
        kaiser_yt2 = y2 .* kai;

        kaiser_Xt1 = fft(kaiser_yt1);
        kaiser_Xt2 = fft(kaiser_yt2);

        kaiser_Xt = kaiser_Xt1 .* kaiser_Xt2;
            
    %@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@@
    
        if isequal(app.windowChooseText.Value, 'Off')
            window = 1;
            graph = (y1+y2)*1;
        elseif isequal(app.windowChooseText.Value, 'Rect')
            window = windowAmp * rect;
            graph = windowAmp * rect_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hanning')
            window = windowAmp * hanning;
            graph = windowAmp * hanning_Xt;
        elseif isequal(app.windowChooseText.Value, 'Hamming')
            window = windowAmp * hamming;
            graph = windowAmp * hamming_Xt;
        elseif isequal(app.windowChooseText.Value, 'Blackman')
            window = windowAmp * blackman;
            graph = windowAmp * blackman_Xt;
        elseif isequal(app.windowChooseText.Value, 'Kaiser')
            window = windowAmp * kai;
            graph = windowAmp * kaiser_Xt;
        end
    
%-------------------------------- Graphic -----------------------------------

            plot(app.UIAxesChirp1,t,y1,'b');
            plot(app.UIAxesChirp2,t,y2,'b');
            
            plot(app.UIAxesTotal,t,y1+y2,'b');
            plot(app.UIAxesWindow,t,window,'b');
            
            plot(app.UIAxesCorrelationProcessor,(0:length(c)-1)*delta_t, abs(c),'b');
            plot(app.UIAxesStretchProcessor,(0:length(t)-1)*delta_t,abs(graph));
            
            %plot(app.UIAxesCostascode,);
            %plot(app.UIAxesFrequencyHopping,);

            
        end

        % Value changed function: NoiseKnob
        function NoiseKnobValueChanged(app, event)
            value = app.NoiseKnob.Value;
            app.noisetext.Value = value;
        end

        % Value changed function: noisetext
        function noisetextValueChanged(app, event)
            value = app.noisetext.Value;
            app.NoiseKnob.Value = value;
        end

        % Value changed function: StandardDeviationKnob
        function StandardDeviationKnobValueChanged(app, event)
            value = app.StandardDeviationKnob.Value;
            app.SDtext.Value = value;
        end

        % Value changed function: SDtext
        function SDtextValueChanged(app, event)
            value = app.SDtext.Value;
            app.StandardDeviationKnob.Value = value;
        end

        % Value changed function: WindowKnob
        function WindowKnobValueChanged(app, event)
            value = app.WindowKnob.Value;
            app.windowChooseText.Value = value;
        end

        % Value changed function: windowChooseText
        function windowChooseTextValueChanged(app, event)
            value = app.windowChooseText.Value;
            app.WindowKnob.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesChirp1 = uiaxes(app.UIFigure);
            title(app.UIAxesChirp1, 'Chirp signal 1')
            xlabel(app.UIAxesChirp1, 'Second [ns]')
            ylabel(app.UIAxesChirp1, 'Amplitude')
            app.UIAxesTotal = uiaxes(app.UIFigure);
            title(app.UIAxesTotal, 'Total signal')
            xlabel(app.UIAxesTotal, 'Second [ns]')
            ylabel(app.UIAxesTotal, 'Amplitude')
            app.Chirp1redPanel = uipanel(app.UIFigure);
            app.Chirp1redPanel.Title = 'Chirp 1 (red)';
            app.Chirp1Gain = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1Gain.Value = 1;
            app.Chirp1ChangingTime = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1ChangingTime.Value = 1;
            app.Chirp1StartingFre = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1StartingFre.Value = 1;
            app.Chirp1ChangingFre = uieditfield(app.Chirp1redPanel, 'numeric');
            app.Chirp1ChangingFre.Value = 2;
            app.GHzLabel = uilabel(app.Chirp1redPanel);
            app.GHzLabel.Text = '[GHz]';
            app.GHzLabel_2 = uilabel(app.Chirp1redPanel);
            app.GHzLabel_2.Text = '[GHz]';
            app.nsLabel = uilabel(app.Chirp1redPanel);
            app.nsLabel.Text = '[ns]';
            app.GainLabel = uilabel(app.Chirp1redPanel);
            app.GainLabel.Text = 'Gain';
            app.ChangingTimeLabel = uilabel(app.Chirp1redPanel);
            app.ChangingTimeLabel.Text = 'Changing Time';
            app.StartingFrequencyLabel = uilabel(app.Chirp1redPanel);
            app.StartingFrequencyLabel.Text = 'Starting Frequency';
            app.ChangingFrequencyLabel = uilabel(app.Chirp1redPanel);
            app.ChangingFrequencyLabel.Text = 'Changing Frequency';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Chirp2bluePanel = uipanel(app.UIFigure);
            app.Chirp2bluePanel.Title = 'Chirp 2 (blue)';
            app.Chirp2Gain = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2Gain.Value = 1;
            app.Chirp2ChangingTime = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2ChangingTime.Value = 1;
            app.Chirp2StartingFre = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2StartingFre.Value = 1;
            app.Chirp2ChangingFre = uieditfield(app.Chirp2bluePanel, 'numeric');
            app.Chirp2ChangingFre.Value = 2;
            app.nsLabel_2 = uilabel(app.Chirp2bluePanel);
            app.nsLabel_2.Text = '[ns]';
            app.GHzLabel_3 = uilabel(app.Chirp2bluePanel);
            app.GHzLabel_3.Text = '[GHz]';
            app.GHzLabel_4 = uilabel(app.Chirp2bluePanel);
            app.GHzLabel_4.Text = '[GHz]';
            app.GainLabel_2 = uilabel(app.Chirp2bluePanel);
            app.GainLabel_2.Text = 'Gain';
            app.ChangingTimeLabel_2 = uilabel(app.Chirp2bluePanel);
            app.ChangingTimeLabel_2.Text = 'Changing Time';
            app.StartingFrequencyLabel_2 = uilabel(app.Chirp2bluePanel);
            app.StartingFrequencyLabel_2.Text = 'Starting Frequency';
            app.ChangingFrequencyLabel_2 = uilabel(app.Chirp2bluePanel);
            app.ChangingFrequencyLabel_2.Text = 'Changing Frequency';
            app.UIAxesCorrelationProcessor = uiaxes(app.UIFigure);
            title(app.UIAxesCorrelationProcessor, 'Correlation Processor')
            xlabel(app.UIAxesCorrelationProcessor, 'Wave')
            ylabel(app.UIAxesCorrelationProcessor, 'Amplitude')
            app.UIAxesCostascode = uiaxes(app.UIFigure);
            title(app.UIAxesCostascode, 'Costas Code')
            xlabel(app.UIAxesCostascode, 'Wave')
            ylabel(app.UIAxesCostascode, 'Amplitude')
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.StandardDeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandardDeviationKnobLabel.Text = 'Standard Deviation';
            app.StandardDeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandardDeviationKnob.Limits = [0 10];
            app.StandardDeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationKnobValueChanged, true);
            app.StandardDeviationKnob.Value = 1;
            app.NoiseKnobLabel = uilabel(app.NoisePanel);
            app.NoiseKnobLabel.Text = 'Noise';
            app.NoiseKnob = uiknob(app.NoisePanel, 'discrete');
            app.NoiseKnob.Items = {'Off', 'Gaussian'};
            app.NoiseKnob.ValueChangedFcn = createCallbackFcn(app, @NoiseKnobValueChanged, true);
            app.SDtext = uieditfield(app.NoisePanel, 'numeric');
            app.SDtext.ValueChangedFcn = createCallbackFcn(app, @SDtextValueChanged, true);
            app.SDtext.Value = 1;
            app.noisetext = uieditfield(app.NoisePanel, 'text');
            app.noisetext.ValueChangedFcn = createCallbackFcn(app, @noisetextValueChanged, true);
            app.noisetext.Value = 'Off';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.nsLabel_3 = uilabel(app.ParameterPanel);
            app.nsLabel_3.Text = '[ns]';
            app.delta_tEditFieldLabel = uilabel(app.ParameterPanel);
            app.delta_tEditFieldLabel.Text = 'delta_t';
            app.delta_tEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.delta_tEditField.Value = 0.01;
            app.nsLabel_4 = uilabel(app.ParameterPanel);
            app.nsLabel_4.Text = '[ns]';
            app.pulse_widthEditFieldLabel = uilabel(app.ParameterPanel);
            app.pulse_widthEditFieldLabel.Text = 'pulse_width';
            app.pulse_widthEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.pulse_widthEditField.Value = 10;
            app.WindowPanel = uipanel(app.UIFigure);
            app.WindowPanel.Title = 'Window';
            app.WindowKnobLabel = uilabel(app.WindowPanel);
            app.WindowKnobLabel.Text = 'Window';
            app.WindowKnob = uiknob(app.WindowPanel, 'discrete');
            app.WindowKnob.Items = {'Off', 'Rect', 'Hanning', 'Hamming', 'Blackman', 'Kaiser'};
            app.WindowKnob.ValueChangedFcn = createCallbackFcn(app, @WindowKnobValueChanged, true);
            app.windowChooseText = uieditfield(app.WindowPanel, 'text');
            app.windowChooseText.ValueChangedFcn = createCallbackFcn(app, @windowChooseTextValueChanged, true);
            app.windowChooseText.Value = 'Off';
            app.AmplitudeEditFieldLabel = uilabel(app.WindowPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.WindowAmplitudeEditField = uieditfield(app.WindowPanel, 'numeric');
            app.WindowAmplitudeEditField.Value = 1;
            app.KaiserAsLabel = uilabel(app.WindowPanel);
            app.KaiserAsLabel.Text = 'Kaiser ( As )';
            app.KaiserAsEditField = uieditfield(app.WindowPanel, 'numeric');
            app.KaiserAsEditField.Value = 60;
            app.KaiserBetaEditFieldLabel = uilabel(app.WindowPanel);
            app.KaiserBetaEditFieldLabel.Text = 'Kaiser ( Beta )';
            app.KaiserBetaEditField = uieditfield(app.WindowPanel, 'numeric');
            app.KaiserBetaEditField.Value = 5.95326;
            app.dBLabel = uilabel(app.WindowPanel);
            app.dBLabel.Text = '[dB]';
            app.UIAxesChirp2 = uiaxes(app.UIFigure);
            title(app.UIAxesChirp2, 'Chirp signal 2')
            xlabel(app.UIAxesChirp2, 'Second [ns]')
            ylabel(app.UIAxesChirp2, 'Amplitude')
            app.UIAxesWindow = uiaxes(app.UIFigure);
            title(app.UIAxesWindow, 'Window')
            xlabel(app.UIAxesWindow, 'Frequency [GHz]')
            ylabel(app.UIAxesWindow, 'Ampitude')
            app.UIAxesStretchProcessor = uiaxes(app.UIFigure);
            title(app.UIAxesStretchProcessor, 'Stretch Processor')
            xlabel(app.UIAxesStretchProcessor, 'Wave')
            ylabel(app.UIAxesStretchProcessor, 'Amplitude')
            app.UIAxesFrequencyHopping = uiaxes(app.UIFigure);
            title(app.UIAxesFrequencyHopping, 'Frequency Hopping')
            xlabel(app.UIAxesFrequencyHopping, 'Frequency [GHz]')
            ylabel(app.UIAxesFrequencyHopping, 'Amplitude')
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Delta_tEditFieldLabel = uilabel(app.OutputPanel);
            app.Delta_tEditFieldLabel.Text = 'Delta_t';
            app.Delta_tEditField = uieditfield(app.OutputPanel, 'numeric');
            app.nsLabel_5 = uilabel(app.OutputPanel);
            app.nsLabel_5.Text = '[ns]';
            app.DistanceEditFieldLabel = uilabel(app.OutputPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.OutputPanel, 'numeric');
            app.KmLabel = uilabel(app.OutputPanel);
            app.KmLabel.Text = '[Km]';
            app.ErrorRateEditFieldLabel = uilabel(app.OutputPanel);
            app.ErrorRateEditFieldLabel.Text = 'Error Rate';
            app.ErrorRateEditField = uieditfield(app.OutputPanel, 'numeric');
            app.Label = uilabel(app.OutputPanel);
            app.Label.Text = '[%]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pulse_compression190929

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end