classdef Analog_Pulse_Compression191018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SignalSotcos2pifotmu2t2Panel    matlab.ui.container.Panel
        Chirp1Panel                     matlab.ui.container.Panel
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        MuEditFieldLabel                matlab.ui.control.Label
        MuEditField                     matlab.ui.control.NumericEditField
        foEditFieldLabel                matlab.ui.control.Label
        foEditField                     matlab.ui.control.NumericEditField
        Delta_tauEditFieldLabel         matlab.ui.control.Label
        Delta_tauEditField              matlab.ui.control.NumericEditField
        GHzLabel                        matlab.ui.control.Label
        nsLabel                         matlab.ui.control.Label
        BandWidthTauLabel_2             matlab.ui.control.Label
        Delta_tEditFieldLabel           matlab.ui.control.Label
        Delta_tEditField                matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel        matlab.ui.control.Label
        PulseWidthEditField             matlab.ui.control.NumericEditField
        Chirp2Panel                     matlab.ui.container.Panel
        GainEditField_2Label            matlab.ui.control.Label
        GainEditField_2                 matlab.ui.control.NumericEditField
        MuEditField_2Label              matlab.ui.control.Label
        MuEditField_2                   matlab.ui.control.NumericEditField
        foEditField_2Label              matlab.ui.control.Label
        foEditField_2                   matlab.ui.control.NumericEditField
        Delta_tauEditField_2Label       matlab.ui.control.Label
        Delta_tauEditField_2            matlab.ui.control.NumericEditField
        BandWidthTauLabel               matlab.ui.control.Label
        GHzLabel_3                      matlab.ui.control.Label
        nsLabel_3                       matlab.ui.control.Label
        nsLabel_2                       matlab.ui.control.Label
        GHzLabel_2                      matlab.ui.control.Label
        NoisePanel                      matlab.ui.container.Panel
        SelectionKnobLabel              matlab.ui.control.Label
        SelectionKnob                   matlab.ui.control.DiscreteKnob
        StandardDeviationKnobLabel      matlab.ui.control.Label
        StandardDeviationKnob           matlab.ui.control.Knob
        noiseSelection_text             matlab.ui.control.EditField
        StandardDeviationEditField      matlab.ui.control.NumericEditField
        WindowPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        RectagularTab                   matlab.ui.container.Tab
        RectLampLabel                   matlab.ui.control.Label
        RectLamp                        matlab.ui.control.Lamp
        RectSwitch                      matlab.ui.control.ToggleSwitch
        WidthKnobLabel                  matlab.ui.control.Label
        Rect_WidthKnob                  matlab.ui.control.Knob
        AmplitudeKnobLabel              matlab.ui.control.Label
        Rect_AmplitudeKnob              matlab.ui.control.Knob
        Rect_AmplitudeEditField         matlab.ui.control.NumericEditField
        Rect_WidthEditField             matlab.ui.control.NumericEditField
        HanningTab                      matlab.ui.container.Tab
        HanningLampLabel                matlab.ui.control.Label
        HanningLamp                     matlab.ui.control.Lamp
        HanningSwitch                   matlab.ui.control.ToggleSwitch
        AmplitudeKnobLabel_3            matlab.ui.control.Label
        Hanning_AmplitudeKnob           matlab.ui.control.Knob
        Hanning_AmplitudeEditField      matlab.ui.control.NumericEditField
        HammingTab                      matlab.ui.container.Tab
        HammingLampLabel                matlab.ui.control.Label
        HammingLamp                     matlab.ui.control.Lamp
        HammingSwitch                   matlab.ui.control.ToggleSwitch
        AmplitudeKnobLabel_4            matlab.ui.control.Label
        Hamming_AmplitudeKnob           matlab.ui.control.Knob
        Hamming_AmplitudeEditField      matlab.ui.control.NumericEditField
        BlackmanTab                     matlab.ui.container.Tab
        BlackmanLampLabel               matlab.ui.control.Label
        BlackmanLamp                    matlab.ui.control.Lamp
        BlackmanSwitch                  matlab.ui.control.ToggleSwitch
        AmplitudeKnob_2Label            matlab.ui.control.Label
        Blackman_AmplitudeKnob          matlab.ui.control.Knob
        Blackman_AmplitudeEditField     matlab.ui.control.NumericEditField
        KaiserTab                       matlab.ui.container.Tab
        KaiserLampLabel                 matlab.ui.control.Label
        KaiserLamp                      matlab.ui.control.Lamp
        KaiserSwitch                    matlab.ui.control.ToggleSwitch
        Kaiser_AmplitudeEditField       matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        Kaiser_BetaEditField            matlab.ui.control.NumericEditField
        AsEditFieldLabel                matlab.ui.control.Label
        Kaiser_AsEditField              matlab.ui.control.NumericEditField
        AmplitudeKnobLabel_5            matlab.ui.control.Label
        Kaiser_AmplitudeKnob            matlab.ui.control.Knob
        dBLabel                         matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        CorrelationProcessorPanel       matlab.ui.container.Panel
        ErrorRateEditFieldLabel         matlab.ui.control.Label
        Correlation_ErrorRateEditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        DistanceEditFieldLabel          matlab.ui.control.Label
        Correlation_DistanceEditField   matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        Time_DifferenceEditFieldLabel   matlab.ui.control.Label
        Correlation_TimeDiffEditField   matlab.ui.control.NumericEditField
        nsLabel_4                       matlab.ui.control.Label
        StretchProcessorPanel           matlab.ui.container.Panel
        ErrorRateEditField_2Label       matlab.ui.control.Label
        Stretch_ErrorRateEditField      matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        DistanceEditField_2Label        matlab.ui.control.Label
        Stretch_DistanceEditField       matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        Time_DifferenceEditField_2Label  matlab.ui.control.Label
        Stretch_TimeDiffEditField       matlab.ui.control.NumericEditField
        nsLabel_5                       matlab.ui.control.Label
        Send_DataButton                 matlab.ui.control.Button
        UIAxes_Chirp1                   matlab.ui.control.UIAxes
        UIAxes_Chirp2                   matlab.ui.control.UIAxes
        UIAxes_Total                    matlab.ui.control.UIAxes
        UIAxes_Window                   matlab.ui.control.UIAxes
        UIAxes_CorrelationPro           matlab.ui.control.UIAxes
        UIAxes_StretchPro               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%****************************** Default *********************************

    % signal parameter
    dt = app.Delta_tEditField.Value;                % dt = 0.01 [ns]
    pulse_width = app.PulseWidthEditField.Value;    % pulse_width = 10 [ns]
    
    t = 0 : dt : pulse_width;                       % 0 : 0.01 : 10 [ns]
    N_t = length(t);                                % N_t = 1001
    df = (1/dt)/N_t;                                % df = 0.1 [GHz]
    f = 0 : df : (1/dt)-df;                         % 0 : 0.1 : 100 [GHz]
    N_f = length(f);                                % N_f = 1001
    
    % chirp signal 1 parameter
    chirp1_gain = app.GainEditField.Value;
    chirp1_mu = app.MuEditField.Value;
    chirp1_fo = app.foEditField.Value;
    chirp1_delta_tau = app.Delta_tauEditField.Value;
    
    % chirp signal 2 parameter
    chirp2_gain = app.GainEditField_2.Value;
    chirp2_mu = app.MuEditField_2.Value;
    chirp2_fo = app.foEditField_2.Value;
    chirp2_delta_tau = app.Delta_tauEditField_2.Value;
    
    % rect window
    rect_gain = app.Rect_AmplitudeEditField.Value;
    rect_width = app.Rect_WidthEditField.Value;
    
    % hanning window
    hanning_gain = app.Hanning_AmplitudeEditField.Value;
    
    % hamming window
    hamming_gain = app.Hanning_AmplitudeEditField.Value;
    
    % blackman window
    blackman_gain = app.Blackman_AmplitudeEditField.Value;
    
    % kaiser window
    kaiser_gain = app.Kaiser_AmplitudeEditField.Value;
    kaiser_As = app.Kaiser_AsEditField.Value;
    kaiser_beta = app.Kaiser_BetaEditField.Value;
    
%-------------------------------- Noise --------------------------------
    
    noiseSD = app.StandardDeviationEditField.Value;
    if isequal(app.noiseSelection_text.Value,'Off')
        noiseAmp = 0;
    elseif isequal(app.noiseSelection_text.Value,'Gaussian')
        noiseAmp = 1;
    end    
    
%------------------------------- Signal 1 -------------------------------
% st2 = chirp2_gain.*cos(2*pi.*chirp2_fo*t*chirp2_delta_tau + 2*pi.*chirp2_mu*chirp2_delta_tau*t - pi.*chirp2_mu*(chirp2_delta_tau)^2);
% 단순히 cos graph가 나온다....?

    st1 = chirp1_gain.*cos(2*pi.*(chirp1_fo*(t - chirp1_delta_tau) + (chirp1_mu/2).*(t - chirp1_delta_tau).^2));
    plot(app.UIAxes_Chirp1,t,st1,'b')
    
%------------------------------- Signal 2 -------------------------------
 
    st2 = chirp2_gain.*cos(2*pi.*(chirp2_fo*(t - chirp2_delta_tau) + (chirp2_mu/2).*(t - chirp2_delta_tau).^2));
    plot(app.UIAxes_Chirp2,t,st2,'b')
    
%----------------------------- Total signal -----------------------------

    st = (st1 + st2) + randn(1,length(st1+st2))*noiseAmp*noiseSD;
    plot(app.UIAxes_Total,t,st,'b')    
    
%-------------------------------- Window --------------------------------

    % rect window
    if isequal(app.RectSwitch.Value,'On')
       app.HanningSwitch.Value = 'Off';
       app.HammingSwitch.Value = 'Off';
       app.BlackmanSwitch.Value = 'Off';
       app.KaiserSwitch.Value = 'Off';
       
       rect_func=zeros(1,length(t));
            for i=1:length(t)
                if t(i) > 0 && t(i) < rect_width
                    rect_func(i) = rect_gain; 
                end
            end
       plot(app.UIAxes_Window,t,rect_func)
       axis(app.UIAxes_Window,[-1 11 0 2])
       
    % hanning window     
    elseif isequal(app.HanningSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hanning = hanning_gain*( 1/2 .* (1 - cos(2*pi*t/(length(t)-1))) );
        hanning_func = hanning_gain*hanning(N_t);
        plot(app.UIAxes_Window,t,hanning_func)
        
    % hamming window
    elseif isequal(app.HammingSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hamming = hamming_gain*( 0.54 - 0.46 * cos(2*pi*t/(length(t)-1)) );
        hamming_func = hamming_gain*hamming(N_t);
        plot(app.UIAxes_Window,t,hamming_func)
        
    % blackman window
    elseif isequal(app.BlackmanSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % blackman = blackman_gain*( 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1)) );
        blackman_func = blackman_gain*blackman(N_t);
        plot(app.UIAxes_Window,t,blackman_func)
        
    % kaiser window
    elseif isequal(app.KaiserSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        
        % kaiser = kaiser_io*( (kaiser_alpa * sqrt({(length(t)-1)/2}^2 - {t-(length(t)-1)/2}^2)) ) / kaiser_io*((kaiser_alpa * {(length(t)-1)/2}^2));
        kaiser_func = kaiser_gain.*(kaiser(length(t),kaiser_beta));
        plot(app.UIAxes_Window,t,kaiser_func)
    end
    
%------------------------ Correlation processor ------------------------

    % rt0 = [ zeros(1,N), st1, zeros(1,N), st2, zeros(1,N) ];
    % rt = rt0; % + randn(1,length(rt0)); gaussian noise
    rt1 = [ zeros(1,N_t), st1, zeros(1,N_t) ];
    rt2 = [ zeros(1,N_t+100), st2, zeros(1,N_t-100) ];
           
    % rt = rt1 + rt2; 
    rt = (rt1 + rt2) + randn(1,length(rt1+rt2))*noiseAmp*noiseSD;    
 
    ct1 = length(rt)-N_t;
        for a = 0:ct1
            c(a+1) = rt(a+1:a+N_t)*(st1+st2)';
        end
                
    Xct1 = fft(st1);
    Xct2 = fft(st2);
    fft_Xct = Xct1 .* Xct2;
    Xct = ifft(fft_Xct);
    
    plot(app.UIAxes_CorrelationPro,(0:length(c)-1)*dt, abs(c),'b')
    % plot(app.UIAxes_CorrelationPro,t,Xct,'b')
%-------------------------- Stretch processor ---------------------------
% LPF 통과한 signal st1, st2 ---> cos signal
st_lpf1 = chirp1_gain*cos((2*pi.*chirp1_fo*t*chirp1_delta_tau) + (2*pi.*chirp1_mu*chirp1_delta_tau*t) - (pi.*chirp1_mu*(chirp1_delta_tau)^2));
st_lpf2 = chirp2_gain*cos((2*pi.*chirp2_fo*t*chirp2_delta_tau) + (2*pi.*chirp2_mu*chirp2_delta_tau*t) - (pi.*chirp2_mu*(chirp2_delta_tau)^2));
st_lpf = st_lpf1 + st_lpf2;

    if isequal(app.RectSwitch.Value,'On')
        yt = st_lpf .* rect_func;
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HanningSwitch.Value,'On')
        yt = st_lpf .* hanning_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HammingSwitch.Value,'On')
        yt = st_lpf .* hamming_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.BlackmanSwitch.Value,'On')
        yt = st_lpf .* blackman_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.KaiserSwitch.Value,'On')
        yt = st_lpf .* kaiser_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
    end
%------------------------------ 분해능 확인 ------------------------------
    % correlation processor
    cp_resolution1 = c(1:length(t)+50);
    [m,k1] = max(cp_resolution1);                            % m = 최댓값, k1 = sample number
    cp_resolution2 = c(1:length(t)-50);          
    [n,k2] = max(cp_resolution2);                            % n = 최댓값, k2 = sample number
    if (m>n)
        if(chirp1_mu==chirp2_mu)
            time_diff = (k1-k2)*dt;                                  % [ns], dt = 0.01
        elseif(chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m<n)
        if (chirp1_mu==chirp2_mu)
            time_diff = (k2-k1)*dt;                                  % [ns], dt = 0.01
        elseif (chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m==n)
        time_diff = 0;
    end
    D_out = (time_diff/2)*3*10^8*10^-9;                      % [m] (nano second 단위)
    
    % stretch processor
    [m1,h1] = max(abs(Yct));
    sp_resolution1 = h1;
    ryct = Yct(1:h1-1);
    [m2,h2] = max(ryct);
    sp_resolution2 = h2;
    if(m1>m2)
        S_time_diff = (sp_resolution1-sp_resolution2)*dt;
    elseif (m1<m2)
        S_time_diff = (sp_resolution2-sp_resolution1)*dt;
    elseif (m1==m2)
        S_time_diff = 0;
    end
    S_D_out = (S_time_diff/2)*3*10^8*10^-9; 
%-------------------------------- output ---------------------------------
    % correlation processor
    app.Correlation_TimeDiffEditField.Value = time_diff;     % [ns], 시간차
    app.Correlation_DistanceEditField.Value = D_out;         % [m], 거리   

    % stretch processor
    app.Stretch_TimeDiffEditField.Value = S_time_diff;       % [ns]   
    app.Stretch_DistanceEditField.Value = S_D_out;           % [m]
    
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%***************************** Start Button *****************************

    %signal parameter
    dt = app.Delta_tEditField.Value;                % dt = 0.01 [ns]
    pulse_width = app.PulseWidthEditField.Value;    % pulse_width = 10 [ns]
    
    t = 0 : dt : pulse_width;                       % 0 : 0.01 : 10 [ns]
    N_t = length(t);                                % N_t = 1001
    df = (1/dt)/N_t;                                % df = 0.1 [GHz]
    f = 0 : df : (1/dt)-df;                         % 0 : 0.1 : 100 [GHz]
    N_f = length(f);                                % N_f = 1001
    
    %chirp signal 1 parameter
    chirp1_gain = app.GainEditField.Value;
    chirp1_mu = app.MuEditField.Value;
    chirp1_fo = app.foEditField.Value;
    chirp1_delta_tau = app.Delta_tauEditField.Value;
    
    %chirp signal 2 parameter
    chirp2_gain = app.GainEditField_2.Value;
    chirp2_mu = app.MuEditField_2.Value;
    chirp2_fo = app.foEditField_2.Value;
    chirp2_delta_tau = app.Delta_tauEditField_2.Value;
    
    % rect window
    rect_gain = app.Rect_AmplitudeEditField.Value;
    rect_width = app.Rect_WidthEditField.Value;
    
    % hanning window
    hanning_gain = app.Hanning_AmplitudeEditField.Value;
    
    % hamming window
    hamming_gain = app.Hanning_AmplitudeEditField.Value;
    
    % blackman window
    blackman_gain = app.Blackman_AmplitudeEditField.Value;
    
    % kaiser window
    kaiser_gain = app.Kaiser_AmplitudeEditField.Value;
    kaiser_As = app.Kaiser_AsEditField.Value;
    kaiser_beta = app.Kaiser_BetaEditField.Value;

%-------------------------------- Noise --------------------------------
    
    noiseSD = app.StandardDeviationEditField.Value;
    if isequal(app.noiseSelection_text.Value,'Off')
        noiseAmp = 0;
    elseif isequal(app.noiseSelection_text.Value,'Gaussian')
        noiseAmp = 1;
    end
    
%------------------------------- Signal 1 -------------------------------
% st2 = chirp2_gain.*cos(2*pi.*chirp2_fo*t*chirp2_delta_tau + 2*pi.*chirp2_mu*chirp2_delta_tau*t - pi.*chirp2_mu*(chirp2_delta_tau)^2);
% cos graph

    st1 = chirp1_gain.*cos(2*pi.*(chirp1_fo*(t - chirp1_delta_tau) + (chirp1_mu/2).*(t - chirp1_delta_tau).^2));
    plot(app.UIAxes_Chirp1,t,st1,'b')
    
%------------------------------- Signal 2 -------------------------------
    
    st2 = chirp2_gain.*cos(2*pi.*(chirp2_fo*(t - chirp2_delta_tau) + (chirp2_mu/2).*(t - chirp2_delta_tau).^2));
    plot(app.UIAxes_Chirp2,t,st2,'b')

%----------------------------- Total signal -----------------------------

    st = (st1 + st2) + randn(1,length(st1+st2))*noiseAmp*noiseSD;
    plot(app.UIAxes_Total,t,st,'b')

%-------------------------------- Window --------------------------------
    % rect window
    if isequal(app.RectSwitch.Value,'On')
       app.HanningSwitch.Value = 'Off';
       app.HammingSwitch.Value = 'Off';
       app.BlackmanSwitch.Value = 'Off';
       app.KaiserSwitch.Value = 'Off';
       
       rect_func=zeros(1,length(t));
            for i=1:length(t)
                if t(i) > 0 && t(i) < rect_width
                    rect_func(i) = rect_gain; 
                end
            end
       plot(app.UIAxes_Window,t,rect_func)
       axis(app.UIAxes_Window,[-1 11 0 2])
       
    % hanning window     
    elseif isequal(app.HanningSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hanning = hanning_gain*( 1/2 .* (1 - cos(2*pi*t/(length(t)-1))) );
        hanning_func = hanning_gain*hanning(N_t);
        plot(app.UIAxes_Window,t,hanning_func)
        
    % hamming window
    elseif isequal(app.HammingSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hamming = hamming_gain*( 0.54 - 0.46 * cos(2*pi*t/(length(t)-1)) );
        hamming_func = hamming_gain*hamming(N_t);
        plot(app.UIAxes_Window,t,hamming_func)
        
    % blackman window
    elseif isequal(app.BlackmanSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % blackman = blackman_gain*( 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1)) );
        blackman_func = blackman_gain*blackman(N_t);
        plot(app.UIAxes_Window,t,blackman_func)
        
    % kaiser window
    elseif isequal(app.KaiserSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        
        % kaiser = kaiser_io*( (kaiser_alpa * sqrt({(length(t)-1)/2}^2 - {t-(length(t)-1)/2}^2)) ) / kaiser_io*((kaiser_alpa * {(length(t)-1)/2}^2));
        kaiser_func = kaiser_gain.*(kaiser(length(t),kaiser_beta));
        plot(app.UIAxes_Window,t,kaiser_func)
    end

%------------------------ Correlation processor ------------------------

    % rt0 = [ zeros(1,N), st1, zeros(1,N), st2, zeros(1,N) ];
    % rt = rt0; % + randn(1,length(rt0)); gaussian noise
    rt1 = [ zeros(1,N_t), st1, zeros(1,N_t) ];
    rt2 = [ zeros(1,N_t+100), st2, zeros(1,N_t-100) ];
           
    % rt = rt1 + rt2; 
    rt = (rt1 + rt2) + randn(1,length(rt1+rt2))*noiseAmp*noiseSD;    
 
    ct1 = length(rt)-N_t;
        for a = 0:ct1
            c(a+1) = rt(a+1:a+N_t)*(st1+st2)';
        end
                
    Xct1 = fft(st1);
    Xct2 = fft(st2);
    Xct = Xct1 .* Xct2;
    ifft(Xct);
    
    plot(app.UIAxes_CorrelationPro,(0:length(c)-1)*dt, abs(c),'b')
    
%-------------------------- Stretch processor ---------------------------
% LPF 통과한 signal st1, st2 ---> cos signal
st_lpf1 = chirp1_gain*cos((2*pi.*chirp1_fo*t*chirp1_delta_tau) + (2*pi.*chirp1_mu*chirp1_delta_tau*t) - (pi.*chirp1_mu*(chirp1_delta_tau)^2));
st_lpf2 = chirp2_gain*cos((2*pi.*chirp2_fo*t*chirp2_delta_tau) + (2*pi.*chirp2_mu*chirp2_delta_tau*t) - (pi.*chirp2_mu*(chirp2_delta_tau)^2));
st_lpf = st_lpf1 + st_lpf2;

    if isequal(app.RectSwitch.Value,'On')
        yt = st_lpf .* rect_func;
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HanningSwitch.Value,'On')
        yt = st_lpf .* hanning_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HammingSwitch.Value,'On')
        yt = st_lpf .* hamming_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.BlackmanSwitch.Value,'On')
        yt = st_lpf .* blackman_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.KaiserSwitch.Value,'On')
        yt = st_lpf .* kaiser_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
    end
%------------------------------ 분해능 확인 ------------------------------
    % correlation processor
    rt_resolution1 = c(1:length(t)+50);
    [m,k1] = max(rt_resolution1);                            % m = 최댓값, k1 = sample number
    rt_resolution2 = c(1:length(t)-50);          
    [n,k2] = max(rt_resolution2);                            % n = 최댓값, k2 = sample number
    if (m>n)
        if(chirp1_mu==chirp2_mu)
            time_diff = (k1-k2)*dt;                                  % [ns], dt = 0.01
        elseif(chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m<n)
        if(chirp1_mu==chirp2_mu)
            time_diff = (k2-k1)*dt;                                  % [ns], dt = 0.01
        elseif(chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m==n)
        time_diff = 0;
    end
    D_out = (time_diff/2)*3*10^8*10^-9;                      % [m] (nano second 단위)
    
    % stretch processor
    [m1,h1] = max(abs(Yct));
    sp_resolution1 = h1;
    ryct = Yct(1:h1-1);
    [m2,h2] = max(ryct);
    sp_resolution2 = h2;
    if(m1>m2)
        S_time_diff = (sp_resolution1-sp_resolution2)*dt;
    elseif (m1<m2)
        S_time_diff = (sp_resolution2-sp_resolution1)*dt;
    elseif (m1==m2)
        S_time_diff = 0;
    end
    S_D_out = (S_time_diff/2)*3*10^8*10^-9;
%-------------------------------- output ---------------------------------
    % correlation processor
    app.Correlation_TimeDiffEditField.Value = time_diff;     % [ns], 시간차
    app.Correlation_DistanceEditField.Value = D_out;         % [m], 거리   

    % stretch processor
    app.Stretch_TimeDiffEditField.Value = S_time_diff;       % [ns]   
    app.Stretch_DistanceEditField.Value = S_D_out;           % [m]
    
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%***************************** Reset Button ******************************

    %signal parameter
    app.Delta_tEditField.Value = 0.01;
    app.PulseWidthEditField.Value = 10;
    dt = app.Delta_tEditField.Value;                % dt = 0.01 [ns]
    pulse_width = app.PulseWidthEditField.Value;    % pulse_width = 10 [ns]
    
    t = 0 : dt : pulse_width;                       % 0 : 0.01 : 10 [ns]
    N_t = length(t);                                % N_t = 1001
    df = (1/dt)/N_t;                                % df = 0.1 [GHz]
    f = 0 : df : (1/dt)-df;                         % 0 : 0.1 : 100 [GHz]
    N_f = length(f);                                % N_f = 1001
    
    %chirp signal 1 parameter
    app.GainEditField.Value = 1;
    app.MuEditField.Value = 1;
    app.foEditField.Value = 1;
    app.Delta_tauEditField.Value = 0.5;
    chirp1_gain = app.GainEditField.Value;
    chirp1_mu = app.MuEditField.Value;
    chirp1_fo = app.foEditField.Value;
    chirp1_delta_tau = app.Delta_tauEditField.Value;
    
    %chirp signal 2 parameter
    app.GainEditField_2.Value = 1;
    app.MuEditField_2.Value = 1;
    app.foEditField_2.Value = 1;
    app.Delta_tauEditField_2.Value = 1;
    chirp2_gain = app.GainEditField_2.Value;
    chirp2_mu = app.MuEditField_2.Value;
    chirp2_fo = app.foEditField_2.Value;
    chirp2_delta_tau = app.Delta_tauEditField_2.Value;
    
    % window parameter
    app.RectSwitch.Value = 'On';
    app.HanningSwitch.Value = 'Off';
    app.HammingSwitch.Value = 'Off';
    app.BlackmanSwitch.Value = 'Off';
    app.KaiserSwitch.Value = 'Off';
    app.RectLamp.Color = [0 1 0];
    app.HanningLamp.Color = [1 0 0];
    app.HammingLamp.Color = [1 0 0];
    app.BlackmanLamp.Color = [1 0 0];
    app.KaiserLamp.Color = [1 0 0];
    
    % rect window
    app.Rect_AmplitudeEditField.Value = 1;
    app.Rect_WidthEditField.Value = 10;
    rect_gain = app.Rect_AmplitudeEditField.Value;
    rect_width = app.Rect_WidthEditField.Value;
    
    % hanning window
    app.Hanning_AmplitudeEditField.Value = 1;
    hanning_gain = app.Hanning_AmplitudeEditField.Value;
    
    % hamming window
    app.Hamming_AmplitudeEditField.Value = 1;
    hamming_gain = app.Hanning_AmplitudeEditField.Value;
    
    % blackman window
    app.Blackman_AmplitudeEditField.Value = 1;
    blackman_gain = app.Blackman_AmplitudeEditField.Value;
    
    % kaiser window
    app.Kaiser_AmplitudeEditField.Value = 1;
    app.Kaiser_AsEditField.Value = 50;
    kaiser_gain = app.Kaiser_AmplitudeEditField.Value;
    kaiser_As = app.Kaiser_AsEditField.Value;
    kaiser_beta = app.Kaiser_BetaEditField.Value;
    
%-------------------------------- Noise --------------------------------
    noiseAmp = 0;   
    app.SelectionKnob.Value = 'Off';
    app.noiseSelection_text.Value = 'Off';
    app.StandardDeviationEditField.Value = 1;
    app.StandardDeviationKnob.Value = 1;
    noiseSD = app.StandardDeviationEditField.Value;
    
%------------------------------- Signal 1 -------------------------------
% st2 = chirp2_gain.*cos(2*pi.*chirp2_fo*t*chirp2_delta_tau + 2*pi.*chirp2_mu*chirp2_delta_tau*t - pi.*chirp2_mu*(chirp2_delta_tau)^2);
% cos graph

    st1 = chirp1_gain.*cos(2*pi.*(chirp1_fo*(t - chirp1_delta_tau) + (chirp1_mu/2).*(t - chirp1_delta_tau).^2));
    plot(app.UIAxes_Chirp1,t,st1,'b')
    
%------------------------------- Signal 2 -------------------------------
    
    st2 = chirp2_gain.*cos(2*pi.*(chirp2_fo*(t - chirp2_delta_tau) + (chirp2_mu/2).*(t - chirp2_delta_tau).^2));
    plot(app.UIAxes_Chirp2,t,st2,'b')

%----------------------------- Total signal ------------------------------

    st = (st1 + st2) + randn(1,length(st1+st2))*noiseAmp*noiseSD;
    plot(app.UIAxes_Total,t,st,'b')

%-------------------------------- Window --------------------------------
    % rect window
    if isequal(app.RectSwitch.Value,'On')
       app.HanningSwitch.Value = 'Off';
       app.HammingSwitch.Value = 'Off';
       app.BlackmanSwitch.Value = 'Off';
       app.KaiserSwitch.Value = 'Off';
       
       rect_func=zeros(1,length(t));
            for i=1:length(t)
                if t(i) > 0 && t(i) < rect_width
                    rect_func(i) = rect_gain; 
                end
            end
       plot(app.UIAxes_Window,t,rect_func)
       axis(app.UIAxes_Window,[-1 11 0 2])
       
    % hanning window     
    elseif isequal(app.HanningSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hanning = hanning_gain*( 1/2 .* (1 - cos(2*pi*t/(length(t)-1))) );
        hanning_func = hanning_gain*hanning(N_t);
        plot(app.UIAxes_Window,t,hanning_func)
        
    % hamming window
    elseif isequal(app.HammingSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % hamming = hamming_gain*( 0.54 - 0.46 * cos(2*pi*t/(length(t)-1)) );
        hamming_func = hamming_gain*hamming(N_t);
        plot(app.UIAxes_Window,t,hamming_func)
        
    % blackman window
    elseif isequal(app.BlackmanSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.KaiserSwitch.Value = 'Off';
        
        % blackman = blackman_gain*( 0.42 - 0.5 * cos(2*pi*t/(length(t)-1)) + 0.08 * cos(4*pi*t/(length(t)-1)) );
        blackman_func = blackman_gain*blackman(N_t);
        plot(app.UIAxes_Window,t,blackman_func)
        
    % kaiser window
    elseif isequal(app.KaiserSwitch.Value,'On')
        app.RectSwitch.Value = 'Off';
        app.HanningSwitch.Value = 'Off';
        app.HammingSwitch.Value = 'Off';
        app.BlackmanSwitch.Value = 'Off';
        
        % kaiser = kaiser_io*( (kaiser_alpa * sqrt({(length(t)-1)/2}^2 - {t-(length(t)-1)/2}^2)) ) / kaiser_io*((kaiser_alpa * {(length(t)-1)/2}^2));
        kaiser_func = kaiser_gain.*(kaiser(length(t),kaiser_beta));
        plot(app.UIAxes_Window,t,kaiser_func)
    end

%------------------------ Correlation processor ------------------------

    % rt0 = [ zeros(1,N), st1, zeros(1,N), st2, zeros(1,N) ];
    % rt = rt0; % + randn(1,length(rt0)); gaussian noise
    rt1 = [ zeros(1,N_t), st1, zeros(1,N_t) ];
    rt2 = [ zeros(1,N_t+100), st2, zeros(1,N_t-100) ];
           
    % rt = rt1 + rt2; 
    rt = (rt1 + rt2) + randn(1,length(rt1+rt2))*noiseAmp*noiseSD;    
 
    ct1 = length(rt)-N_t;
        for a = 0:ct1
            c(a+1) = rt(a+1:a+N_t)*(st1+st2)';
        end
                
    Xct1 = fft(st1);
    Xct2 = fft(st2);
    Xct = Xct1 .* Xct2;
    ifft(Xct);
    
    plot(app.UIAxes_CorrelationPro,(0:length(c)-1)*dt, abs(c),'b')
    
%-------------------------- Stretch processor ---------------------------
% LPF 통과한 signal st1, st2 ---> cos signal
st_lpf1 = chirp1_gain*cos((2*pi.*chirp1_fo*t*chirp1_delta_tau) + (2*pi.*chirp1_mu*chirp1_delta_tau*t) - (pi.*chirp1_mu*(chirp1_delta_tau)^2));
st_lpf2 = chirp2_gain*cos((2*pi.*chirp2_fo*t*chirp2_delta_tau) + (2*pi.*chirp2_mu*chirp2_delta_tau*t) - (pi.*chirp2_mu*(chirp2_delta_tau)^2));
st_lpf = st_lpf1 + st_lpf2;

    if isequal(app.RectSwitch.Value,'On')
        yt = st_lpf .* rect_func;
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HanningSwitch.Value,'On')
        yt = st_lpf .* hanning_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.HammingSwitch.Value,'On')
        yt = st_lpf .* hamming_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.BlackmanSwitch.Value,'On')
        yt = st_lpf .* blackman_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
        
    elseif isequal(app.KaiserSwitch.Value,'On')
        yt = st_lpf .* kaiser_func';
        Yct = fft(yt);
        plot(app.UIAxes_StretchPro,f,abs(Yct),'b')
    end
%------------------------------ 분해능 확인 ------------------------------
    % correlation processor
    rt_resolution1 = c(1:length(t)+50);
    [m,k1] = max(rt_resolution1);                            % m = 최댓값, k1 = sample number
    rt_resolution2 = c(1:length(t)-50);          
    [n,k2] = max(rt_resolution2);                            % n = 최댓값, k2 = sample number
    if (m>n)
        if(chirp1_mu==chirp2_mu)
            time_diff = (k1-k2)*dt;                                  % [ns], dt = 0.01
        elseif(chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m<n)
        if(chirp1_mu==chirp2_mu)
            time_diff = (k2-k1)*dt;                                  % [ns], dt = 0.01
        elseif(chirp1_mu~=chirp2_mu)
            time_diff = 0;
        end
    elseif (m==n)
        time_diff = 0;
    end
    D_out = (time_diff/2)*3*10^8*10^-9;                      % [m] (nano second 단위)
    
    % stretch processor
    [m1,h1] = max(abs(Yct));
    sp_resolution1 = h1;
    ryct = Yct(1:h1-1);
    [m2,h2] = max(ryct);
    sp_resolution2 = h2;
    if(m1>m2)
        S_time_diff = (sp_resolution1-sp_resolution2)*dt;
    elseif (m1<m2)
        S_time_diff = (sp_resolution2-sp_resolution1)*dt;
    elseif (m1==m2)
        S_time_diff = 0;
    end
    S_D_out = (S_time_diff/2)*3*10^8*10^-9;
%-------------------------------- output ---------------------------------
    % correlation processor
    app.Correlation_TimeDiffEditField.Value = time_diff;     % [ns], 시간차
    app.Correlation_DistanceEditField.Value = D_out;         % [m], 거리   

    % stretch processor
    app.Stretch_TimeDiffEditField.Value = S_time_diff;       % [ns]   
    app.Stretch_DistanceEditField.Value = S_D_out;           % [m]
    
        end

        % Button pushed function: Send_DataButton
        function Send_DataButtonPushed(app, event)
%************************** Send data to App2 ****************************


        end

        % Value changed function: SelectionKnob
        function SelectionKnobValueChanged(app, event)
            value = app.SelectionKnob.Value;
            app.noiseSelection_text.Value = value;
        end

        % Value changed function: noiseSelection_text
        function noiseSelection_textValueChanged(app, event)
            value = app.noiseSelection_text.Value;
            app.SelectionKnob.Value = value;
        end

        % Value changed function: Rect_AmplitudeKnob
        function Rect_AmplitudeKnobValueChanged(app, event)
            value = app.Rect_AmplitudeKnob.Value;
            app.Rect_AmplitudeEditField.Value = value;
        end

        % Value changed function: Rect_AmplitudeEditField
        function Rect_AmplitudeEditFieldValueChanged(app, event)
            value = app.Rect_AmplitudeEditField.Value;
            app.Rect_AmplitudeKnob.Value = value;
        end

        % Value changed function: Rect_WidthKnob
        function Rect_WidthKnobValueChanged(app, event)
            value = app.Rect_WidthKnob.Value;
            app.Rect_WidthEditField.Value = value;
        end

        % Value changed function: Rect_WidthEditField
        function Rect_WidthEditFieldValueChanged(app, event)
            value = app.Rect_WidthEditField.Value;
            app.Rect_WidthKnob.Value = value;
        end

        % Value changed function: Hanning_AmplitudeKnob
        function Hanning_AmplitudeKnobValueChanged(app, event)
            value = app.Hanning_AmplitudeKnob.Value;
            app.Hanning_AmplitudeEditField.Value = value;
        end

        % Value changed function: Hanning_AmplitudeEditField
        function Hanning_AmplitudeEditFieldValueChanged(app, event)
            value = app.Hanning_AmplitudeEditField.Value;
            app.Hanning_AmplitudeKnob.Value = value;
        end

        % Value changed function: Hamming_AmplitudeKnob
        function Hamming_AmplitudeKnobValueChanged(app, event)
            value = app.Hamming_AmplitudeKnob.Value;
            app.Hamming_AmplitudeEditField.Value = value;
        end

        % Value changed function: Hamming_AmplitudeEditField
        function Hamming_AmplitudeEditFieldValueChanged(app, event)
            value = app.Hamming_AmplitudeEditField.Value;
            app.Hamming_AmplitudeKnob.Value = value;
        end

        % Value changed function: Blackman_AmplitudeKnob
        function Blackman_AmplitudeKnobValueChanged(app, event)
            value = app.Blackman_AmplitudeKnob.Value;
            app.Blackman_AmplitudeEditField.Value = value;
        end

        % Value changed function: Blackman_AmplitudeEditField
        function Blackman_AmplitudeEditFieldValueChanged(app, event)
            value = app.Blackman_AmplitudeEditField.Value;
            app.Blackman_AmplitudeKnob.Value = value;
        end

        % Value changed function: Kaiser_AmplitudeKnob
        function Kaiser_AmplitudeKnobValueChanged(app, event)
            value = app.Kaiser_AmplitudeKnob.Value;
            app.Kaiser_AmplitudeEditField.Value = value;
        end

        % Value changed function: Kaiser_AmplitudeEditField
        function Kaiser_AmplitudeEditFieldValueChanged(app, event)
            value = app.Kaiser_AmplitudeEditField.Value;
            app.Kaiser_AmplitudeKnob.Value = value;
        end

        % Value changed function: RectSwitch
        function RectSwitchValueChanged(app, event)
            value = app.RectSwitch.Value;
            if isequal(value,'On')
                app.RectLamp.Color = [0 1 0];
                app.HanningSwitch.Value = 'Off';
                app.HammingSwitch.Value = 'Off';
                app.BlackmanSwitch.Value = 'Off';
                app.KaiserSwitch.Value = 'Off';
                app.HanningLamp.Color = [1 0 0];
                app.HammingLamp.Color = [1 0 0];
                app.BlackmanLamp.Color = [1 0 0];
                app.KaiserLamp.Color = [1 0 0];
            elseif isequal(value,'Off')
                app.RectLamp.Color = [1 0 0];
            end
        end

        % Value changed function: HanningSwitch
        function HanningSwitchValueChanged(app, event)
            value = app.HanningSwitch.Value;
            if isequal(value,'On')
                app.HanningLamp.Color = [0 1 0];
                app.RectSwitch.Value = 'Off';
                app.HammingSwitch.Value = 'Off';
                app.BlackmanSwitch.Value = 'Off';
                app.KaiserSwitch.Value = 'Off';
                app.RectLamp.Color = [1 0 0];
                app.HammingLamp.Color = [1 0 0];
                app.BlackmanLamp.Color = [1 0 0];
                app.KaiserLamp.Color = [1 0 0];
            elseif isequal(value,'Off')
                app.HanningLamp.Color = [1 0 0];
            end
        end

        % Value changed function: HammingSwitch
        function HammingSwitchValueChanged(app, event)
            value = app.HammingSwitch.Value;
            if isequal(value,'On')
                app.HammingLamp.Color = [0 1 0];
                app.RectSwitch.Value = 'Off';
                app.HanningSwitch.Value = 'Off';
                app.BlackmanSwitch.Value = 'Off';
                app.KaiserSwitch.Value = 'Off';
                app.RectLamp.Color = [1 0 0];
                app.HanningLamp.Color = [1 0 0];
                app.BlackmanLamp.Color = [1 0 0];
                app.KaiserLamp.Color = [1 0 0];
            elseif isequal(value,'Off')
                app.HammingLamp.Color = [1 0 0];
            end
        end

        % Value changed function: BlackmanSwitch
        function BlackmanSwitchValueChanged(app, event)
            value = app.BlackmanSwitch.Value;
            if isequal(value,'On')
                app.BlackmanLamp.Color = [0 1 0];
                app.RectSwitch.Value = 'Off';
                app.HanningSwitch.Value = 'Off';
                app.HammingSwitch.Value = 'Off';
                app.KaiserSwitch.Value = 'Off';
                app.RectLamp.Color = [1 0 0];
                app.HanningLamp.Color = [1 0 0];
                app.HammingLamp.Color = [1 0 0];
                app.KaiserLamp.Color = [1 0 0];
            elseif isequal(value,'Off')
                app.BlackmanLamp.Color = [1 0 0];
            end
        end

        % Value changed function: KaiserSwitch
        function KaiserSwitchValueChanged(app, event)
            value = app.KaiserSwitch.Value;
            if isequal(value,'On')
                app.KaiserLamp.Color = [0 1 0];
                app.RectSwitch.Value = 'Off';
                app.HanningSwitch.Value = 'Off';
                app.HammingSwitch.Value = 'Off';
                app.BlackmanSwitch.Value = 'Off';
                app.RectLamp.Color = [1 0 0];
                app.HanningLamp.Color = [1 0 0];
                app.HammingLamp.Color = [1 0 0];
                app.BlackmanLamp.Color = [1 0 0];
            elseif isequal(value,'Off')
                app.KaiserLamp.Color = [1 0 0];
            end
        end

        % Value changed function: StandardDeviationKnob
        function StandardDeviationKnobValueChanged(app, event)
            value = app.StandardDeviationKnob.Value;
            app.StandardDeviationEditField.Value = value;
        end

        % Value changed function: StandardDeviationEditField
        function StandardDeviationEditFieldValueChanged(app, event)
            value = app.StandardDeviationEditField.Value;
            app.StandardDeviationKnob.Value = value;
        end

        % Value changed function: Kaiser_AsEditField
        function Kaiser_AsEditFieldValueChanged(app, event)
            As = app.Kaiser_AsEditField.Value;
            if (As >= 50)
                app.Kaiser_BetaEditField.Value = 0.1102*(As-8.7) + 0.3; % As >= 50
            elseif ((21 < As) && (As < 50))
                app.Kaiser_BetaEditField.Value = 0.5842*(As-21)^0.4 + 0.07886*(As-21); % 21 < As < 50
            elseif (As <= 21)
                app.Kaiser_BetaEditField.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SignalSotcos2pifotmu2t2Panel = uipanel(app.UIFigure);
            app.SignalSotcos2pifotmu2t2Panel.Title = 'Signal : So(t) = cos(2*pi(fo*t + (mu/2)*t^2))';
            app.Chirp1Panel = uipanel(app.SignalSotcos2pifotmu2t2Panel);
            app.Chirp1Panel.Title = '------------------------------------------ Chirp 1 ----------------------------------------';
            app.GainEditFieldLabel = uilabel(app.Chirp1Panel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.Chirp1Panel, 'numeric');
            app.GainEditField.Value = 1;
            app.MuEditFieldLabel = uilabel(app.Chirp1Panel);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.Chirp1Panel, 'numeric');
            app.MuEditField.Value = 1;
            app.foEditFieldLabel = uilabel(app.Chirp1Panel);
            app.foEditFieldLabel.Text = 'fo';
            app.foEditField = uieditfield(app.Chirp1Panel, 'numeric');
            app.foEditField.Value = 1;
            app.Delta_tauEditFieldLabel = uilabel(app.Chirp1Panel);
            app.Delta_tauEditFieldLabel.Text = 'Delta_tau';
            app.Delta_tauEditField = uieditfield(app.Chirp1Panel, 'numeric');
            app.Delta_tauEditField.Value = 0.5;
            app.GHzLabel = uilabel(app.Chirp1Panel);
            app.GHzLabel.Text = '[GHz]';
            app.nsLabel = uilabel(app.Chirp1Panel);
            app.nsLabel.Text = '[ns]';
            app.BandWidthTauLabel_2 = uilabel(app.Chirp1Panel);
            app.BandWidthTauLabel_2.Text = '[Band Width / Tau'']';
            app.Delta_tEditFieldLabel = uilabel(app.SignalSotcos2pifotmu2t2Panel);
            app.Delta_tEditFieldLabel.Text = 'Delta_t';
            app.Delta_tEditField = uieditfield(app.SignalSotcos2pifotmu2t2Panel, 'numeric');
            app.Delta_tEditField.Value = 0.01;
            app.PulseWidthEditFieldLabel = uilabel(app.SignalSotcos2pifotmu2t2Panel);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.PulseWidthEditField = uieditfield(app.SignalSotcos2pifotmu2t2Panel, 'numeric');
            app.PulseWidthEditField.Value = 10;
            app.Chirp2Panel = uipanel(app.SignalSotcos2pifotmu2t2Panel);
            app.Chirp2Panel.Title = '------------------------------------------ Chirp 2 ----------------------------------------';
            app.GainEditField_2Label = uilabel(app.Chirp2Panel);
            app.GainEditField_2Label.Text = 'Gain';
            app.GainEditField_2 = uieditfield(app.Chirp2Panel, 'numeric');
            app.GainEditField_2.Value = 1;
            app.MuEditField_2Label = uilabel(app.Chirp2Panel);
            app.MuEditField_2Label.Text = 'Mu';
            app.MuEditField_2 = uieditfield(app.Chirp2Panel, 'numeric');
            app.MuEditField_2.Value = 1;
            app.foEditField_2Label = uilabel(app.Chirp2Panel);
            app.foEditField_2Label.Text = 'fo';
            app.foEditField_2 = uieditfield(app.Chirp2Panel, 'numeric');
            app.foEditField_2.Value = 1;
            app.Delta_tauEditField_2Label = uilabel(app.Chirp2Panel);
            app.Delta_tauEditField_2Label.Text = 'Delta_tau';
            app.Delta_tauEditField_2 = uieditfield(app.Chirp2Panel, 'numeric');
            app.Delta_tauEditField_2.Value = 1;
            app.BandWidthTauLabel = uilabel(app.Chirp2Panel);
            app.BandWidthTauLabel.Text = '[Band Width / Tau'']';
            app.GHzLabel_3 = uilabel(app.Chirp2Panel);
            app.GHzLabel_3.Text = '[GHz]';
            app.nsLabel_3 = uilabel(app.Chirp2Panel);
            app.nsLabel_3.Text = '[ns]';
            app.nsLabel_2 = uilabel(app.SignalSotcos2pifotmu2t2Panel);
            app.nsLabel_2.Text = '[ns]';
            app.GHzLabel_2 = uilabel(app.SignalSotcos2pifotmu2t2Panel);
            app.GHzLabel_2.Text = '[GHz]';
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.SelectionKnobLabel = uilabel(app.NoisePanel);
            app.SelectionKnobLabel.Text = 'Selection';
            app.SelectionKnob = uiknob(app.NoisePanel, 'discrete');
            app.SelectionKnob.Items = {'Off', 'Gaussian'};
            app.SelectionKnob.ValueChangedFcn = createCallbackFcn(app, @SelectionKnobValueChanged, true);
            app.StandardDeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandardDeviationKnobLabel.Text = 'Standard Deviation';
            app.StandardDeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandardDeviationKnob.Limits = [0 10];
            app.StandardDeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationKnobValueChanged, true);
            app.StandardDeviationKnob.Value = 1;
            app.noiseSelection_text = uieditfield(app.NoisePanel, 'text');
            app.noiseSelection_text.ValueChangedFcn = createCallbackFcn(app, @noiseSelection_textValueChanged, true);
            app.noiseSelection_text.Value = 'Off';
            app.StandardDeviationEditField = uieditfield(app.NoisePanel, 'numeric');
            app.StandardDeviationEditField.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationEditFieldValueChanged, true);
            app.StandardDeviationEditField.Value = 1;
            app.WindowPanel = uipanel(app.UIFigure);
            app.WindowPanel.Title = 'Window';
            app.TabGroup = uitabgroup(app.WindowPanel);
            app.RectagularTab = uitab(app.TabGroup);
            app.RectagularTab.Title = 'Rectagular';
            app.RectLampLabel = uilabel(app.RectagularTab);
            app.RectLampLabel.Text = 'Rect Lamp';
            app.RectLamp = uilamp(app.RectagularTab);
            app.RectSwitch = uiswitch(app.RectagularTab, 'toggle');
            app.RectSwitch.ValueChangedFcn = createCallbackFcn(app, @RectSwitchValueChanged, true);
            app.RectSwitch.Value = 'On';
            app.WidthKnobLabel = uilabel(app.RectagularTab);
            app.WidthKnobLabel.Text = 'Width';
            app.Rect_WidthKnob = uiknob(app.RectagularTab, 'continuous');
            app.Rect_WidthKnob.ValueChangedFcn = createCallbackFcn(app, @Rect_WidthKnobValueChanged, true);
            app.Rect_WidthKnob.Value = 10;
            app.AmplitudeKnobLabel = uilabel(app.RectagularTab);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.Rect_AmplitudeKnob = uiknob(app.RectagularTab, 'continuous');
            app.Rect_AmplitudeKnob.Limits = [0 10];
            app.Rect_AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @Rect_AmplitudeKnobValueChanged, true);
            app.Rect_AmplitudeKnob.Value = 1;
            app.Rect_AmplitudeEditField = uieditfield(app.RectagularTab, 'numeric');
            app.Rect_AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Rect_AmplitudeEditFieldValueChanged, true);
            app.Rect_AmplitudeEditField.Value = 1;
            app.Rect_WidthEditField = uieditfield(app.RectagularTab, 'numeric');
            app.Rect_WidthEditField.ValueChangedFcn = createCallbackFcn(app, @Rect_WidthEditFieldValueChanged, true);
            app.Rect_WidthEditField.Value = 10;
            app.HanningTab = uitab(app.TabGroup);
            app.HanningTab.Title = 'Hanning';
            app.HanningLampLabel = uilabel(app.HanningTab);
            app.HanningLampLabel.Text = 'Hanning Lamp';
            app.HanningLamp = uilamp(app.HanningTab);
            app.HanningSwitch = uiswitch(app.HanningTab, 'toggle');
            app.HanningSwitch.ValueChangedFcn = createCallbackFcn(app, @HanningSwitchValueChanged, true);
            app.AmplitudeKnobLabel_3 = uilabel(app.HanningTab);
            app.AmplitudeKnobLabel_3.Text = 'Amplitude';
            app.Hanning_AmplitudeKnob = uiknob(app.HanningTab, 'continuous');
            app.Hanning_AmplitudeKnob.Limits = [0 10];
            app.Hanning_AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @Hanning_AmplitudeKnobValueChanged, true);
            app.Hanning_AmplitudeKnob.Value = 1;
            app.Hanning_AmplitudeEditField = uieditfield(app.HanningTab, 'numeric');
            app.Hanning_AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Hanning_AmplitudeEditFieldValueChanged, true);
            app.Hanning_AmplitudeEditField.Value = 1;
            app.HammingTab = uitab(app.TabGroup);
            app.HammingTab.Title = 'Hamming';
            app.HammingLampLabel = uilabel(app.HammingTab);
            app.HammingLampLabel.Text = 'Hamming Lamp';
            app.HammingLamp = uilamp(app.HammingTab);
            app.HammingSwitch = uiswitch(app.HammingTab, 'toggle');
            app.HammingSwitch.ValueChangedFcn = createCallbackFcn(app, @HammingSwitchValueChanged, true);
            app.AmplitudeKnobLabel_4 = uilabel(app.HammingTab);
            app.AmplitudeKnobLabel_4.Text = 'Amplitude';
            app.Hamming_AmplitudeKnob = uiknob(app.HammingTab, 'continuous');
            app.Hamming_AmplitudeKnob.Limits = [0 10];
            app.Hamming_AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @Hamming_AmplitudeKnobValueChanged, true);
            app.Hamming_AmplitudeKnob.Value = 1;
            app.Hamming_AmplitudeEditField = uieditfield(app.HammingTab, 'numeric');
            app.Hamming_AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Hamming_AmplitudeEditFieldValueChanged, true);
            app.Hamming_AmplitudeEditField.Value = 1;
            app.BlackmanTab = uitab(app.TabGroup);
            app.BlackmanTab.Title = 'Blackman';
            app.BlackmanLampLabel = uilabel(app.BlackmanTab);
            app.BlackmanLampLabel.Text = 'Blackman Lamp';
            app.BlackmanLamp = uilamp(app.BlackmanTab);
            app.BlackmanSwitch = uiswitch(app.BlackmanTab, 'toggle');
            app.BlackmanSwitch.ValueChangedFcn = createCallbackFcn(app, @BlackmanSwitchValueChanged, true);
            app.AmplitudeKnob_2Label = uilabel(app.BlackmanTab);
            app.AmplitudeKnob_2Label.Text = 'Amplitude';
            app.Blackman_AmplitudeKnob = uiknob(app.BlackmanTab, 'continuous');
            app.Blackman_AmplitudeKnob.Limits = [0 10];
            app.Blackman_AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @Blackman_AmplitudeKnobValueChanged, true);
            app.Blackman_AmplitudeKnob.Value = 1;
            app.Blackman_AmplitudeEditField = uieditfield(app.BlackmanTab, 'numeric');
            app.Blackman_AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Blackman_AmplitudeEditFieldValueChanged, true);
            app.Blackman_AmplitudeEditField.Value = 2;
            app.KaiserTab = uitab(app.TabGroup);
            app.KaiserTab.Title = 'Kaiser';
            app.KaiserLampLabel = uilabel(app.KaiserTab);
            app.KaiserLampLabel.Text = 'Kaiser Lamp';
            app.KaiserLamp = uilamp(app.KaiserTab);
            app.KaiserSwitch = uiswitch(app.KaiserTab, 'toggle');
            app.KaiserSwitch.ValueChangedFcn = createCallbackFcn(app, @KaiserSwitchValueChanged, true);
            app.Kaiser_AmplitudeEditField = uieditfield(app.KaiserTab, 'numeric');
            app.Kaiser_AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Kaiser_AmplitudeEditFieldValueChanged, true);
            app.Kaiser_AmplitudeEditField.Value = 1;
            app.BetaEditFieldLabel = uilabel(app.KaiserTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.Kaiser_BetaEditField = uieditfield(app.KaiserTab, 'numeric');
            app.Kaiser_BetaEditField.Value = 4.55126;
            app.AsEditFieldLabel = uilabel(app.KaiserTab);
            app.AsEditFieldLabel.Text = 'As';
            app.Kaiser_AsEditField = uieditfield(app.KaiserTab, 'numeric');
            app.Kaiser_AsEditField.ValueChangedFcn = createCallbackFcn(app, @Kaiser_AsEditFieldValueChanged, true);
            app.Kaiser_AsEditField.Value = 50;
            app.AmplitudeKnobLabel_5 = uilabel(app.KaiserTab);
            app.AmplitudeKnobLabel_5.Text = 'Amplitude';
            app.Kaiser_AmplitudeKnob = uiknob(app.KaiserTab, 'continuous');
            app.Kaiser_AmplitudeKnob.Limits = [0 10];
            app.Kaiser_AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @Kaiser_AmplitudeKnobValueChanged, true);
            app.Kaiser_AmplitudeKnob.Value = 1;
            app.dBLabel = uilabel(app.KaiserTab);
            app.dBLabel.Text = '[dB]';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.CorrelationProcessorPanel = uipanel(app.OutputPanel);
            app.CorrelationProcessorPanel.Title = 'Correlation Processor';
            app.ErrorRateEditFieldLabel = uilabel(app.CorrelationProcessorPanel);
            app.ErrorRateEditFieldLabel.Text = 'Error Rate';
            app.Correlation_ErrorRateEditField = uieditfield(app.CorrelationProcessorPanel, 'numeric');
            app.Label = uilabel(app.CorrelationProcessorPanel);
            app.Label.Text = '[%]';
            app.DistanceEditFieldLabel = uilabel(app.CorrelationProcessorPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.Correlation_DistanceEditField = uieditfield(app.CorrelationProcessorPanel, 'numeric');
            app.mLabel = uilabel(app.CorrelationProcessorPanel);
            app.mLabel.Text = '[m]';
            app.Time_DifferenceEditFieldLabel = uilabel(app.CorrelationProcessorPanel);
            app.Time_DifferenceEditFieldLabel.Text = 'Time_Difference';
            app.Correlation_TimeDiffEditField = uieditfield(app.CorrelationProcessorPanel, 'numeric');
            app.nsLabel_4 = uilabel(app.CorrelationProcessorPanel);
            app.nsLabel_4.Text = '[ns]';
            app.StretchProcessorPanel = uipanel(app.OutputPanel);
            app.StretchProcessorPanel.Title = 'Stretch Processor';
            app.ErrorRateEditField_2Label = uilabel(app.StretchProcessorPanel);
            app.ErrorRateEditField_2Label.Text = 'Error Rate';
            app.Stretch_ErrorRateEditField = uieditfield(app.StretchProcessorPanel, 'numeric');
            app.Label_2 = uilabel(app.StretchProcessorPanel);
            app.Label_2.Text = '[%]';
            app.DistanceEditField_2Label = uilabel(app.StretchProcessorPanel);
            app.DistanceEditField_2Label.Text = 'Distance';
            app.Stretch_DistanceEditField = uieditfield(app.StretchProcessorPanel, 'numeric');
            app.mLabel_2 = uilabel(app.StretchProcessorPanel);
            app.mLabel_2.Text = '[m]';
            app.Time_DifferenceEditField_2Label = uilabel(app.StretchProcessorPanel);
            app.Time_DifferenceEditField_2Label.Text = 'Time_Difference';
            app.Stretch_TimeDiffEditField = uieditfield(app.StretchProcessorPanel, 'numeric');
            app.nsLabel_5 = uilabel(app.StretchProcessorPanel);
            app.nsLabel_5.Text = '[ns]';
            app.Send_DataButton = uibutton(app.UIFigure, 'push');
            app.Send_DataButton.ButtonPushedFcn = createCallbackFcn(app, @Send_DataButtonPushed, true);
            app.Send_DataButton.Text = 'Send_Data';
            app.UIAxes_Chirp1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Chirp1, 'Chirp signal 1')
            xlabel(app.UIAxes_Chirp1, 'Time [ns]')
            ylabel(app.UIAxes_Chirp1, 'Amplitude')
            app.UIAxes_Chirp2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Chirp2, 'Chirp signal 2')
            xlabel(app.UIAxes_Chirp2, 'Time [ns]')
            ylabel(app.UIAxes_Chirp2, 'Amplitude')
            app.UIAxes_Total = uiaxes(app.UIFigure);
            title(app.UIAxes_Total, 'Total signal')
            xlabel(app.UIAxes_Total, 'Time [ns]')
            ylabel(app.UIAxes_Total, 'Amplitude')
            app.UIAxes_Window = uiaxes(app.UIFigure);
            title(app.UIAxes_Window, 'Window')
            xlabel(app.UIAxes_Window, 'Time [ns]')
            ylabel(app.UIAxes_Window, 'Amplitude')
            app.UIAxes_CorrelationPro = uiaxes(app.UIFigure);
            title(app.UIAxes_CorrelationPro, 'Correlation Processor')
            xlabel(app.UIAxes_CorrelationPro, 'Time [ns]')
            ylabel(app.UIAxes_CorrelationPro, 'Amplitude')
            app.UIAxes_StretchPro = uiaxes(app.UIFigure);
            title(app.UIAxes_StretchPro, 'Stretch Processor')
            xlabel(app.UIAxes_StretchPro, 'Frequency [GHz]')
            ylabel(app.UIAxes_StretchPro, 'Amplitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analog_Pulse_Compression191018

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end