classdef Digital_Pulse_Compression191018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        costas_UIAxes1                  matlab.ui.control.UIAxes
        costas_UIAxes2                  matlab.ui.control.UIAxes
        costas_hoppingUIAxes            matlab.ui.control.UIAxes
        barker_hoppingUIAxes            matlab.ui.control.UIAxes
        costas_correlationUIAxes        matlab.ui.control.UIAxes
        barker_correlationUIAxes        matlab.ui.control.UIAxes
        CostascodePanel                 matlab.ui.container.Panel
        BarkercodePanel                 matlab.ui.container.Panel
        CostascodeoutputPanel           matlab.ui.container.Panel
        TimeDiffereceEditFieldLabel     matlab.ui.control.Label
        Costas_TimeDiffereceEditField   matlab.ui.control.NumericEditField
        DistanceEditFieldLabel          matlab.ui.control.Label
        Costas_DistanceEditField        matlab.ui.control.NumericEditField
        CorrelationvalueEditFieldLabel  matlab.ui.control.Label
        Costas_CorrelationvalueEditField  matlab.ui.control.NumericEditField
        s1s1EditFieldLabel              matlab.ui.control.Label
        Costas_s1s1EditField            matlab.ui.control.NumericEditField
        rands2s2EditFieldLabel          matlab.ui.control.Label
        Costas_rands2s2EditField        matlab.ui.control.NumericEditField
        ErrorrateEditFieldLabel         matlab.ui.control.Label
        Costas_ErrorrateEditField       matlab.ui.control.NumericEditField
        BarkercodeoutputPanel           matlab.ui.container.Panel
        ButtonPanel                     matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%****************************** Default *****************************            
    % costas code
    v = [2 4 8 5 10 9 7 11 6 1 15 12 3 14 13];
    n = 15; % 벡터길이  
    plot(app.costas_UIAxes1, 1:n, v, 'Linestyle', 'none', 'Marker','*', ...
        'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b')
    axis equal
    grid on
    set(gca, ... % set : 그래픽 객체 속성 설정, gca : 좌표축 또는 차트 반환
        'XTick', 0.5:(n+0.5), ... % x축 눈금 설정
        'YTick', 0.5:(n+0.5), ... % y축 눈금 설정  
        'XLim', [0.5 (n+0.5)], ... % x축 길이제한 설정 
        'YLim', [0.5 (n+0.5)], ... % y축 길이제한 설정
        'XTickLabel', '', ...
        'YTickLabel', '', ...
        'GridLineStyle','-')

    xlabel('  0   1    2    3   4    5    6   7   8    9  10  11  12  13  14')
    ylabel('  1   2    3    4   5    6    7   8   9   10  11  12  13  14  15')

%-------------------------- Frequency Hopping -------------------------

    % v = [2 4 8 5 10 9 7 11 6 1 15 12 3 14 13];
    dt = 0.01;     %[μs]
    t = 0:dt:1-dt; %[μs]
    s1 = [];
    f0 = 1;        %[MHz]

    for i = 1:15
        s1 = [s1 cos(2*pi*f0*v(i)*t)];
    end
    plot(app.costas_hoppingUIAxes,s1)
    xlabel('s1')

%-----------------------------------------------------------------------

    v_rand = randperm(15); % random permutation
    dt = 0.01;      %[μs]
    t = 0:dt:1-dt;  %[μs]
    s2 = [];
    f0 = 1;         %[MHz]
    for i = 1:15
        s2 = [s2 cos(2*pi*f0*v_rand(i)*t)];
    end
    plot(app.costas_hoppingUIAxes,s2)
    xlabel('s2')

%------------------------------------------------------------------------

    N=length(s1); % length(s1) = length(s2)
    r1 = [ zeros(1,N), s1, zeros(1,N) ];
    r2 = [ zeros(1,N+100), s2, zeros(1,N-100) ];
    r = r1 + r2; 

    ct1 = length(r)-N; % 4500 - 1500
        for a = 0:ct1   % a = 0 : 3000
            c(a+1) = r(a+1:a+N)*(s1+s2)';
        end

    plot(app.costas_correlationUIAxes,(0:length(c)-1)*dt,c)
    
    y1 = s1*s2'     % s1과 rand function s2의 correlation
    y2 = s1*s1'     % s1과 correlation이 완벽히 일치할 때
    y3 = s2*s2'     % s2와 correlation이 완벽히 일치할 때
    app.Costas_CorrelationvalueEditField.Value = y1;
    app.Costas_s1s1EditField.Value = y2;
    app.Costas_rands2s2EditField.Value = y3;

%----------------------------- 분해능 확인 --------------------------------

    [m,k1] = max(c);                        % 첫번째 범위 max
    c2 = c(length(t) : end);                % 중간부터 끝까지 ...
    [n,k2] = max(c2);                       % 두번째 범위 max   
    time_diff = ((length(t)*2+k2-k1))*dt;   % [μs], dt = 0.01
    D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)

%------------------------------- output -----------------------------------

    app.Costas_TimeDiffereceEditField.Value = time_diff;    % 시간차
    app.Costas_DistanceEditField.Value = D_out;             % 거리        

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%*************************** Start button ***************************             
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%*************************** Reset button ***************************             
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.costas_UIAxes1 = uiaxes(app.UIFigure);
            title(app.costas_UIAxes1, 'Title')
            xlabel(app.costas_UIAxes1, 'X')
            ylabel(app.costas_UIAxes1, 'Y')
            app.costas_UIAxes2 = uiaxes(app.UIFigure);
            title(app.costas_UIAxes2, 'Title')
            xlabel(app.costas_UIAxes2, 'X')
            ylabel(app.costas_UIAxes2, 'Y')
            app.costas_hoppingUIAxes = uiaxes(app.UIFigure);
            title(app.costas_hoppingUIAxes, 'Title')
            xlabel(app.costas_hoppingUIAxes, 'X')
            ylabel(app.costas_hoppingUIAxes, 'Y')
            app.barker_hoppingUIAxes = uiaxes(app.UIFigure);
            title(app.barker_hoppingUIAxes, 'Title')
            xlabel(app.barker_hoppingUIAxes, 'X')
            ylabel(app.barker_hoppingUIAxes, 'Y')
            app.costas_correlationUIAxes = uiaxes(app.UIFigure);
            title(app.costas_correlationUIAxes, 'Title')
            xlabel(app.costas_correlationUIAxes, 'X')
            ylabel(app.costas_correlationUIAxes, 'Y')
            app.barker_correlationUIAxes = uiaxes(app.UIFigure);
            title(app.barker_correlationUIAxes, 'Title')
            xlabel(app.barker_correlationUIAxes, 'X')
            ylabel(app.barker_correlationUIAxes, 'Y')
            app.CostascodePanel = uipanel(app.UIFigure);
            app.CostascodePanel.Title = 'Costas code';
            app.BarkercodePanel = uipanel(app.UIFigure);
            app.BarkercodePanel.Title = 'Barker code';
            app.CostascodeoutputPanel = uipanel(app.UIFigure);
            app.CostascodeoutputPanel.Title = 'Costas code output';
            app.TimeDiffereceEditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.TimeDiffereceEditFieldLabel.Text = 'Time Differece';
            app.Costas_TimeDiffereceEditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.Costas_DistanceEditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.CorrelationvalueEditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.CorrelationvalueEditFieldLabel.Text = 'Correlation value';
            app.Costas_CorrelationvalueEditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.s1s1EditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.s1s1EditFieldLabel.Text = 's1 .* s1''';
            app.Costas_s1s1EditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.rands2s2EditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.rands2s2EditFieldLabel.Text = '(rand) s2 .* s2''';
            app.Costas_rands2s2EditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.ErrorrateEditFieldLabel = uilabel(app.CostascodeoutputPanel);
            app.ErrorrateEditFieldLabel.Text = 'Error rate';
            app.Costas_ErrorrateEditField = uieditfield(app.CostascodeoutputPanel, 'numeric');
            app.BarkercodeoutputPanel = uipanel(app.UIFigure);
            app.BarkercodeoutputPanel.Title = 'Barker code output';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.ButtonPanel.Title = 'Button';
            app.StartButton = uibutton(app.ButtonPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.ButtonPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digital_Pulse_Compression191018

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end