classdef Correlation_final_190819 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        InputPanel                      matlab.ui.container.Panel
        ChooseSignalButtonGroup         matlab.ui.container.ButtonGroup
        sinButton                       matlab.ui.control.RadioButton
        cosButton                       matlab.ui.control.RadioButton
        pulseButton                     matlab.ui.control.RadioButton
        chirpButton                     matlab.ui.control.RadioButton
        SelectedEditFieldLabel          matlab.ui.control.Label
        text                            matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        ChirpsignalPanel                matlab.ui.container.Panel
        FrequencyChangeKnobLabel        matlab.ui.control.Label
        ChirpFrequencyKnob              matlab.ui.control.Knob
        Chirp_FreText                   matlab.ui.control.NumericEditField
        TimeChangeKnobLabel             matlab.ui.control.Label
        ChirpTimeKnob                   matlab.ui.control.Knob
        Chirp_TimeText                  matlab.ui.control.NumericEditField
        sLabel_5                        matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        Delta_tEditFieldLabel           matlab.ui.control.Label
        Delta_tEditField                matlab.ui.control.NumericEditField
        Pulse_WidthEditFieldLabel       matlab.ui.control.Label
        Pulse_WidthEditField            matlab.ui.control.NumericEditField
        MHzLabel                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        SinusoidalsignalPanel           matlab.ui.container.Panel
        phaseText                       matlab.ui.control.NumericEditField
        PhaseKnobLabel                  matlab.ui.control.Label
        PhaseKnob                       matlab.ui.control.Knob
        Label_2                         matlab.ui.control.Label
        NoisePanel                      matlab.ui.container.Panel
        StandardDeviationKnobLabel      matlab.ui.control.Label
        StandardDeviationKnob           matlab.ui.control.Knob
        noiseSD                         matlab.ui.control.NumericEditField
        TypeKnobLabel                   matlab.ui.control.Label
        TypeKnob                        matlab.ui.control.DiscreteKnob
        type                            matlab.ui.control.EditField
        FreeSpacePathLossPanel          matlab.ui.container.Panel
        attenuationText                 matlab.ui.control.NumericEditField
        AttenuationKnobLabel            matlab.ui.control.Label
        AttenuationKnob                 matlab.ui.control.Knob
        SwitchLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.RockerSwitch
        SwitchText                      matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        WatervaporText                  matlab.ui.control.NumericEditField
        WaterVaporKnobLabel             matlab.ui.control.Label
        WaterVaporKnob                  matlab.ui.control.Knob
        mmhLabel                        matlab.ui.control.Label
        PulsesignalPanel                matlab.ui.container.Panel
        PulseKnobLabel                  matlab.ui.control.Label
        PulseKnob                       matlab.ui.control.Knob
        PulseText                       matlab.ui.control.NumericEditField
        sLabel_6                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        DopplerPanel                    matlab.ui.container.Panel
        DSwitchLabel                    matlab.ui.control.Label
        DSwitch                         matlab.ui.control.RockerSwitch
        DSwitchText                     matlab.ui.control.EditField
        InputDopplerFreqEditFieldLabel  matlab.ui.control.Label
        InputDopplerFreqEditField       matlab.ui.control.NumericEditField
        InputVrEditFieldLabel           matlab.ui.control.Label
        InputVrEditField                matlab.ui.control.NumericEditField
        MHzLabel_3                      matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        MHzLabel_4                      matlab.ui.control.Label
        OutputDopplerFreqEditFieldLabel  matlab.ui.control.Label
        OutputDopplerFreqEditField      matlab.ui.control.NumericEditField
        InputVrEditFieldLabel_2         matlab.ui.control.Label
        OutputVrEditField               matlab.ui.control.NumericEditField
        msLabel_2                       matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        mLabel                          matlab.ui.control.Label
        sampleLabel                     matlab.ui.control.Label
        sampleLabel_2                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DistancehatEditFieldLabel       matlab.ui.control.Label
        DistancehatEditField            matlab.ui.control.NumericEditField
        K1EditFieldLabel                matlab.ui.control.Label
        K1EditField                     matlab.ui.control.NumericEditField
        K2EditFieldLabel                matlab.ui.control.Label
        K2EditField                     matlab.ui.control.NumericEditField
        ErrorrateEditFieldLabel         matlab.ui.control.Label
        ErrorrateEditField              matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        TransmittimeEditFieldLabel      matlab.ui.control.Label
        TransmittimeEditField           matlab.ui.control.NumericEditField
        ReceivedtimeEditFieldLabel      matlab.ui.control.Label
        ReceivedtimeEditField           matlab.ui.control.NumericEditField
        AttenuationEditFieldLabel       matlab.ui.control.Label
        AttenuationHatEditField         matlab.ui.control.NumericEditField
        dBLabel                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%******************* 초기 신호값 A = 1, F = 1, attenuation = 0.5 ******************          
            
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Pulse_WidthEditField.Value;
            t = 0 : Delta_t : Length_t;
            
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value; 
            SD = app.noiseSD.Value;
            
            impulse = 0 : 1/f : L;
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            pulse = app.PulseText.Value;
            
            D_in = 3000;
            TL = D_in/150; % 시간차
            PTP = TL/Delta_t; % Peak to Peak, ex) TL/0.01
            
            app.type.Value = 'Gaussian';
            app.TypeKnob.Value = 'Gaussian';
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
%********************************* 자유공간 전파손실 ********************************

            attenuation_on = app.attenuationText.Value;
            pathloss = 20*log10(D_in) + 20*log10(f) + 20*log10((4*pi)/(3*10^8*10^-6)); % [dB], MHz의 경우 10^-6 곱함
            attenuation_off = 1/(10^(pathloss/10)); % dB 단위에서 다시 원래대로 환산

            app.Switch.Value = 'On';
            app.SwitchText.Value = 'On';

            if isequal(app.Switch.Value, 'On')
                attenuation = attenuation_on;
                app.AttenuationHatEditField.Value = 10*log10(1/attenuation); % [dB] ex) 1/2배 = 3dB 
                
            elseif isequal(app.Switch.Value,'Off')
                attenuation = attenuation_off;
                app.AttenuationHatEditField.Value = pathloss; % [dB]
            end  
            app.attenuationText.Value = attenuation;
            app.AttenuationKnob.Value = attenuation;
            
            
%********************************** signal 구분 ***********************************    

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
                    
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
                
            elseif isequal(app.text.Value, 'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',pulse); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = attenuation*A*pulstran(t,impulse,'rectpuls',pulse);
            
            elseif isequal(app.text.Value, 'chirp signal')
                y1 = A*chirp(t,f,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = attenuation*A*chirp(t,f,chirp_t,chirp_f);
            end
                        
%*********************************** correlation *************************************
            %*********************** K2 - K1 > L *************************************
            
            if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end    
            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
            end           
            
%************************** 거리 입력, 오차, 도착시간 *******************************

            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D_out)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 *********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            % start button
            
            A = app.GainEditField.Value;
            f = app.FrequencyEditField.Value;
            signal = app.text.Value;
            phase = app.PhaseKnob.Value;
            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
            
            SD = app.StandardDeviationKnob.Value;
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Pulse_WidthEditField.Value;
            t = 0 : Delta_t : Length_t;
            w = 2 * pi * f;
            L = length(t);
            D_in = app.DistanceEditField.Value;
            TL = D_in/150; % 시간차
            PTP = TL/Delta_t; % Peak to Peak, ex) TL/0.01
            
            impulse = 0 : 1/f : L;
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            pulse = app.PulseText.Value;
            
            f_d = app.InputDopplerFreqEditField.Value;
            

%********************************* 자유공간 전파손실 ********************************

            attenuation_on = app.attenuationText.Value;
            pathloss = 20*log10(D_in) + 20*log10(f) + 20*log10((4*pi)/(3*10^8*10^-6)); % [dB], MHz의 경우 10^-6 곱함
            attenuation_off = 1/(10^(pathloss/10)); % dB 단위에서 다시 원래대로 환산

            app.Switch.Value = app.SwitchText.Value;
            
            if isequal(app.Switch.Value, 'On')
                attenuation = attenuation_on;
                app.AttenuationHatEditField.Value = 10*log10(1/attenuation); %% [dB] ex) 1/2배 = 3dB       
                
            elseif isequal(app.Switch.Value,'Off')
                attenuation = attenuation_off;
                app.AttenuationHatEditField.Value = pathloss; % [dB]
            end  
            
                app.attenuationText.Value = attenuation;
                app.AttenuationKnob.Value = attenuation;           
           
%%************************************* sin signal *************************************     

            if isequal(signal, 'sin signal')
                    y1 = A*sin(w*t);
                    y2 = attenuation*A*sin(2*pi*(f+f_d)*t+(phase*2*pi/360));
                    plot(app.UIAxes,t,y1)
                    plot(app.UIAxes2,t,y2)
                    
            %***************************** k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                  
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
            %***************************** K2 - K1 < L *********************************  
            
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                          
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] delta_t = 0.01일 때 sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                end
                    
%%************************************** cos signal *************************************            
          
            elseif isequal(signal,'cos signal')
                        y1 = A*cos(w*t); 
                        y2 = attenuation*A*cos(2*pi*(f+f_d)*t+(phase*2*pi/360));
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        
            %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end
                    
%%*********************************** pulse signal *************************************

            elseif isequal(signal,'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',pulse); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = attenuation*A*pulstran(t,impulse,'rectpuls',pulse);
                plot(app.UIAxes,t,y1)
                plot(app.UIAxes2,t,y2) 
                
           %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end
                    
%%***************************************** chirp signal *************************************

            elseif isequal(signal, 'chirp signal')
                y1 = A*chirp(t,f,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = attenuation*A*chirp(t,f,chirp_t,chirp_f);
                plot(app.UIAxes,t,y1)
                plot(app.UIAxes2,t,y2)
                
             %******************************* k2 - k1 > L ********************************
            
                    if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end
                            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
                    end   

            end % final if
            
%********************************** 도플러 Frequency *********************************
           
            Sf = fft(y2);
            df = (1/Delta_t)/L; % Delta_t 10일 때 0.1
            
            f_sf = 0:df:(1/Delta_t)-df;
            
            L_f = 0.5*length(f_sf); % 주기 반복 되므로 반으로 자르기?
            [p,pk] = (max(Sf(1:L_f))); % 피크값 구하기
            
            app.OutputDopplerFreqEditField.Value = (pk - 10*f)/10; % MHz
            app.OutputVrEditField.Value = (3*10^2*((pk - 10*f)/10)/(2*f)); % m/μs
            
%******************************** 거리 입력, 오차, 도착시간 ****************************
          
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D_out)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;
            
%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
      
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % reset button
            
            app.GainEditField.Value = 1;
            app.FrequencyEditField.Value = 1;
            app.AttenuationKnob.Value = 0.5;
            app.attenuationText.Value = 0.5;
            app.PhaseKnob.Value = 0;
            app.phaseText.Value = 0;
            app.StandardDeviationKnob.Value = 1;
            app.noiseSD.Value = 1;
            app.TypeKnob.Value = 'Gaussian'
            app.type.Value = 'Gaussian';
       
            app.Chirp_FreText.Value = 2;
            app.ChirpFrequencyKnob.Value = 2;
            app.PulseText.Value = 0.1;
            app.PulseKnob.Value = 0.1;
             
            SD = app.noiseSD.Value;            
            if isequal(app.TypeKnob.Value, 'Off')
                number = 0;
            elseif isequal(app.TypeKnob.Value, 'Gaussian')
                number = 1;
            end
           
%*****************초기 신호값 A = 1, F = 1, attenuation = 0.5 ************          
            
            app.Delta_tEditField.Value = 0.01;            
            app.Pulse_WidthEditField.Value = 10;
            app.text.Value = 'sin signal';
            
            Delta_t = app.Delta_tEditField.Value;
            Length_t = app.Pulse_WidthEditField.Value;
            t = 0 : Delta_t : Length_t;
            f = app.FrequencyEditField.Value;
            w = 2 * pi * f;
            L = length(t);
            A = app.GainEditField.Value;
            attenuation = app.attenuationText.Value;
            
            SD = app.StandardDeviationKnob.Value;
            D_in = app.DistanceEditField.Value;
            TL = D_in/150; % 시간차
            PTP = TL/Delta_t; % Peak to Peak, ex) TL/0.01
            
            impulse = 0 : 1/f : L;     
            chirp_t = app.Chirp_TimeText.Value;
            chirp_f = app.Chirp_FreText.Value;
            
%********************************* 자유공간 전파손실 ********************************

            attenuation_on = app.attenuationText.Value;
            pathloss = 20*log10(D_in) + 20*log10(f) + 20*log10((4*pi)/(3*10^8*10^-6)); % [dB], MHz의 경우 10^-6 곱함
            attenuation_off = 1/(10^(pathloss/10)); % dB 단위에서 다시 원래대로 환산

            app.Switch.Value = 'On';
            app.SwitchText.Value = 'On';
            
            if isequal(app.Switch.Value, 'On')
                attenuation = attenuation_on;
                app.AttenuationHatEditField.Value = 10*log10(1/attenuation); % [dB] ex) 1/2배 = 3dB
                
            elseif isequal(app.Switch.Value,'Off')
                attenuation = attenuation_off;
                app.AttenuationHatEditField.Value = pathloss; % [dB]
            end  
            app.attenuationText.Value = attenuation;
            app.AttenuationKnob.Value = attenuation;
            
%***************************** signal 구분 *************************************      

            if isequal(app.text.Value, 'sin signal')
                y1 = A*sin(w*t);
                y2 = attenuation*A*sin(w*t);
            elseif isequal(app.text.Value, 'cos signal')
                y1 = A*cos(w*t);
                y2 = attenuation*A*cos(w*t);
            elseif isequal(app.text.Value, 'pulse signal')
                y1 = A*pulstran(t,impulse,'rectpuls',0.1); % pulstran( 시간, pulse 반복, '함수모양', 펄스 폭 )
                y2 = attenuation*A*pulstran(t,impulse,'rectpuls',0.1);
            
            elseif isequal(app.text.Value, 'chirp signal')
                y1 = A*chirp(t,f,chirp_t,chirp_f);  % chirp( 처음 시간, 처음 주파수, 참조시간, 순시주파수 )
                y2 = attenuation*A*chirp(t,f,chirp_t,chirp_f);
            end
            
%*********************************** correlation *************************************
            %*********************** K2 - K1 > L *************************************
            
            if(PTP >= L)
                        r = [ zeros(1,L), y1 , zeros(1,round(PTP)-L), y2, zeros(1,L) ];
                        r = r+ randn(1,length(r))*SD*number;
                        
                        ct1 = length(r)-L;
                        for a = 0:ct1
                            c(a+1) = r(a+1:a+L)*y1';
                        end
                    
                        [m,k1] = max(c);
                        c2 = c(length(t)*2 : end);
                        [n,k2] = max(c2);
                        time_diff = ((length(t)*2+k2-k1))*Delta_t; % [μs], delta_t = 0.01
                        D_out = (time_diff/2)*3*10^8*10^-6;     % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
    
             %******************************* K2 - K1 < L ******************************
             
                    elseif( PTP < L )
                        r = [ zeros(1,L), y1(1:round(PTP)), y1((round(PTP)+1):end)+y2(1:L-round(PTP)), y2((L-round(PTP)+1):end) , zeros(1, L)];
                        r = r + randn(1,length(r))*SD*number;
                        
                        ct1 = length(r) - L;
                            for a = 0:ct1
                                c(a+1) = r(a+1:a+L)*y1';
                            end
                        r2 = [ y2, zeros(1,L)];
                        ct2 = length(r2) - L;
                            for a = 0:ct2
                                c2(a+1) = r2(a+1:a+L)*y1'; 
                            end    
            
                        [m,k1] = max(c);
                        [n,k2] = max(c2);
                        time_diff = ((k2+length(t)+round(PTP)+1)-k1)*Delta_t; % [μs] sec (1초당 1샘플)
                        D_out = time_diff/2*3*10^8*10^-6; % [m/μs] (micro second로 단위변환)
                        
                        app.DistancehatEditField.Value = D_out;
                        plot(app.UIAxes,t,y1)
                        plot(app.UIAxes2,t,y2)
                        plot(app.UIAxes3,(0:length(r)-1)*Delta_t, r)
                        plot(app.UIAxes4,(0:length(c)-1)*Delta_t, c)
                        
            end 
            
%***************************** 거리 입력, 오차, 도착시간 ******************************

            app.DistanceEditField.Value = 3000;
            inputDistance = app.DistanceEditField.Value;
            app.ErrorrateEditField.Value = abs((inputDistance - D_out)/inputDistance)*100;
            app.ReceivedtimeEditField.Value = time_diff;

%************************************ K1, K2 계산 ***********************************

            app.K1EditField.Value = k1;
            app.K2EditField.Value = length(t)*2+k2;
           
        end

        % Selection changed function: ChooseSignalButtonGroup
        function ChooseSignalButtonGroupSelectionChanged(app, event)
          % choose signal type
            selectedButton = app.ChooseSignalButtonGroup.SelectedObject;
            value = selectedButton.Text 
            
            switch value
                case 'sin'
                    app.text.Value = 'sin signal'
                case 'cos'
                    app.text.Value = 'cos signal'
                case 'pulse'
                    app.text.Value = 'pulse signal'
                case 'chirp'
                    app.text.Value = 'chirp signal'
            end
        end

        % Value changed function: attenuationText
        function attenuationTextValueChanged(app, event)
            value = app.attenuationText.Value;
            app.AttenuationKnob.Value = value
        end

        % Value changed function: AttenuationKnob
        function AttenuationKnobValueChanged(app, event)
            value = app.AttenuationKnob.Value;
            app.attenuationText.Value = value
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            value = app.PhaseKnob.Value;
            app.phaseText.Value = value;
        end

        % Value changed function: phaseText
        function phaseTextValueChanged(app, event)
            value = app.phaseText.Value;
            app.PhaseKnob.Value = value;
        end

        % Value changed function: StandardDeviationKnob
        function StandardDeviationKnobValueChanged(app, event)
            value = app.StandardDeviationKnob.Value;
            app.noiseSD.Value = value;
        end

        % Value changed function: noiseSD
        function noiseSDValueChanged(app, event)
            value = app.noiseSD.Value;
            app.StandardDeviationKnob.Value = value;
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            value = app.type.Value;
            app.TypeKnob.Value = value;
        end

        % Value changed function: TypeKnob
        function TypeKnobValueChanged(app, event)
            value = app.TypeKnob.Value;
            switch value
                case 'Off'
                    app.type.Value = value;
                case 'Gaussian'
                    app.type.Value = value;
            end
               
        end

        % Value changed function: ChirpFrequencyKnob
        function ChirpFrequencyKnobValueChanged(app, event)
            value = app.ChirpFrequencyKnob.Value;
            app.Chirp_FreText.Value = value;
        end

        % Value changed function: Chirp_FreText
        function Chirp_FreTextValueChanged(app, event)
            value = app.Chirp_FreText.Value;
            app.ChirpFrequencyKnob.Value = value;
        end

        % Value changed function: ChirpTimeKnob
        function ChirpTimeKnobValueChanged(app, event)
            value = app.ChirpTimeKnob.Value;
            app.Chirp_TimeText.Value = value;
        end

        % Value changed function: Chirp_TimeText
        function Chirp_TimeTextValueChanged(app, event)
            value = app.Chirp_TimeText.Value;
            app.ChirpTimeKnob.Value = value;
        end

        % Value changed function: PulseKnob
        function PulseKnobValueChanged(app, event)
            value = app.PulseKnob.Value;
            app.PulseText.Value = value;
        end

        % Value changed function: PulseText
        function PulseTextValueChanged(app, event)
            value = app.PulseText.Value;
            app.PulseKnob.Value = value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
        switch value
            case 'Off'
                app.SwitchText.Value = value;
            case 'On'
                app.SwitchText.Value = value;
        end
            
        end

        % Value changed function: SwitchText
        function SwitchTextValueChanged(app, event)

            
        end

        % Value changed function: DSwitch
        function DSwitchValueChanged(app, event)
            value = app.DSwitch.Value;
        switch value
            case 'Off'
                app.DSwitchText.Value = value;
            case 'On'
                app.DSwitchText.Value = value;
        end
        end

        % Value changed function: InputVrEditField
        function InputVrEditFieldValueChanged(app, event)
            value = app.InputVrEditField.Value;
            
        end

        % Value changed function: WaterVaporKnob
        function WaterVaporKnobValueChanged(app, event)
            value = app.WaterVaporKnob.Value;
            app.WatervaporText.Value = value;
        end

        % Value changed function: WatervaporText
        function WatervaporTextValueChanged(app, event)
            value = app.WatervaporText.Value;
            app.WaterVaporKnob.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmit signal')
            xlabel(app.UIAxes, 'Wave')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Received signal')
            xlabel(app.UIAxes2, 'Wave')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Total signal')
            xlabel(app.UIAxes3, 'Wave')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Correlation')
            xlabel(app.UIAxes4, 'Wave')
            ylabel(app.UIAxes4, 'Amplitude')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.ChooseSignalButtonGroup = uibuttongroup(app.InputPanel);
            app.ChooseSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseSignalButtonGroupSelectionChanged, true);
            app.ChooseSignalButtonGroup.Title = 'Choose Signal';
            app.sinButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.cosButton.Text = 'cos';
            app.pulseButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.pulseButton.Text = 'pulse';
            app.chirpButton = uiradiobutton(app.ChooseSignalButtonGroup);
            app.chirpButton.Text = 'chirp';
            app.SelectedEditFieldLabel = uilabel(app.InputPanel);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.text = uieditfield(app.InputPanel, 'text');
            app.text.Value = 'sin signal';
            app.FrequencyEditFieldLabel = uilabel(app.InputPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.InputPanel, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.GainEditFieldLabel = uilabel(app.InputPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.InputPanel, 'numeric');
            app.GainEditField.Value = 1;
            app.ChirpsignalPanel = uipanel(app.InputPanel);
            app.ChirpsignalPanel.Title = 'Chirp signal';
            app.FrequencyChangeKnobLabel = uilabel(app.ChirpsignalPanel);
            app.FrequencyChangeKnobLabel.Text = 'Frequency Change';
            app.ChirpFrequencyKnob = uiknob(app.ChirpsignalPanel, 'continuous');
            app.ChirpFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @ChirpFrequencyKnobValueChanged, true);
            app.ChirpFrequencyKnob.Value = 2;
            app.Chirp_FreText = uieditfield(app.ChirpsignalPanel, 'numeric');
            app.Chirp_FreText.ValueChangedFcn = createCallbackFcn(app, @Chirp_FreTextValueChanged, true);
            app.Chirp_FreText.Value = 2;
            app.TimeChangeKnobLabel = uilabel(app.ChirpsignalPanel);
            app.TimeChangeKnobLabel.Text = 'Time Change';
            app.ChirpTimeKnob = uiknob(app.ChirpsignalPanel, 'continuous');
            app.ChirpTimeKnob.ValueChangedFcn = createCallbackFcn(app, @ChirpTimeKnobValueChanged, true);
            app.ChirpTimeKnob.Value = 1;
            app.Chirp_TimeText = uieditfield(app.ChirpsignalPanel, 'numeric');
            app.Chirp_TimeText.ValueChangedFcn = createCallbackFcn(app, @Chirp_TimeTextValueChanged, true);
            app.Chirp_TimeText.Value = 1;
            app.sLabel_5 = uilabel(app.ChirpsignalPanel);
            app.sLabel_5.Text = '[μs]';
            app.MHzLabel_2 = uilabel(app.ChirpsignalPanel);
            app.MHzLabel_2.Text = '[MHz]';
            app.Delta_tEditFieldLabel = uilabel(app.InputPanel);
            app.Delta_tEditFieldLabel.Text = 'Delta_t';
            app.Delta_tEditField = uieditfield(app.InputPanel, 'numeric');
            app.Delta_tEditField.Value = 0.01;
            app.Pulse_WidthEditFieldLabel = uilabel(app.InputPanel);
            app.Pulse_WidthEditFieldLabel.Text = 'Pulse_Width';
            app.Pulse_WidthEditField = uieditfield(app.InputPanel, 'numeric');
            app.Pulse_WidthEditField.Value = 10;
            app.MHzLabel = uilabel(app.InputPanel);
            app.MHzLabel.Text = '[MHz]';
            app.sLabel_3 = uilabel(app.InputPanel);
            app.sLabel_3.Text = '[μs]';
            app.mLabel_2 = uilabel(app.InputPanel);
            app.mLabel_2.Text = '[m]';
            app.DistanceEditFieldLabel = uilabel(app.InputPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.InputPanel, 'numeric');
            app.DistanceEditField.Value = 3000;
            app.SinusoidalsignalPanel = uipanel(app.InputPanel);
            app.SinusoidalsignalPanel.Title = 'Sinusoidal signal';
            app.phaseText = uieditfield(app.SinusoidalsignalPanel, 'numeric');
            app.phaseText.ValueChangedFcn = createCallbackFcn(app, @phaseTextValueChanged, true);
            app.PhaseKnobLabel = uilabel(app.SinusoidalsignalPanel);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.SinusoidalsignalPanel, 'continuous');
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            app.Label_2 = uilabel(app.SinusoidalsignalPanel);
            app.Label_2.Text = '[° ]';
            app.NoisePanel = uipanel(app.InputPanel);
            app.NoisePanel.Title = 'Noise';
            app.StandardDeviationKnobLabel = uilabel(app.NoisePanel);
            app.StandardDeviationKnobLabel.Text = 'Standard Deviation';
            app.StandardDeviationKnob = uiknob(app.NoisePanel, 'continuous');
            app.StandardDeviationKnob.Limits = [0 10];
            app.StandardDeviationKnob.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationKnobValueChanged, true);
            app.StandardDeviationKnob.Value = 1;
            app.noiseSD = uieditfield(app.NoisePanel, 'numeric');
            app.noiseSD.ValueChangedFcn = createCallbackFcn(app, @noiseSDValueChanged, true);
            app.noiseSD.Value = 1;
            app.TypeKnobLabel = uilabel(app.NoisePanel);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.NoisePanel, 'discrete');
            app.TypeKnob.Items = {'Off', 'Gaussian'};
            app.TypeKnob.ValueChangedFcn = createCallbackFcn(app, @TypeKnobValueChanged, true);
            app.TypeKnob.Value = 'Gaussian';
            app.type = uieditfield(app.NoisePanel, 'text');
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.type.Value = 'Gaussian';
            app.FreeSpacePathLossPanel = uipanel(app.InputPanel);
            app.FreeSpacePathLossPanel.Title = 'Free Space Path Loss';
            app.attenuationText = uieditfield(app.FreeSpacePathLossPanel, 'numeric');
            app.attenuationText.ValueChangedFcn = createCallbackFcn(app, @attenuationTextValueChanged, true);
            app.attenuationText.Value = 0.5;
            app.AttenuationKnobLabel = uilabel(app.FreeSpacePathLossPanel);
            app.AttenuationKnobLabel.Text = 'Attenuation';
            app.AttenuationKnob = uiknob(app.FreeSpacePathLossPanel, 'continuous');
            app.AttenuationKnob.Limits = [0 1];
            app.AttenuationKnob.ValueChangedFcn = createCallbackFcn(app, @AttenuationKnobValueChanged, true);
            app.AttenuationKnob.Value = 0.5;
            app.SwitchLabel = uilabel(app.FreeSpacePathLossPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.FreeSpacePathLossPanel, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.SwitchText = uieditfield(app.FreeSpacePathLossPanel, 'text');
            app.SwitchText.ValueChangedFcn = createCallbackFcn(app, @SwitchTextValueChanged, true);
            app.SwitchText.Value = 'On';
            app.Panel = uipanel(app.FreeSpacePathLossPanel);
            app.WatervaporText = uieditfield(app.Panel, 'numeric');
            app.WatervaporText.ValueChangedFcn = createCallbackFcn(app, @WatervaporTextValueChanged, true);
            app.WaterVaporKnobLabel = uilabel(app.Panel);
            app.WaterVaporKnobLabel.Text = 'Water Vapor';
            app.WaterVaporKnob = uiknob(app.Panel, 'continuous');
            app.WaterVaporKnob.ValueChangedFcn = createCallbackFcn(app, @WaterVaporKnobValueChanged, true);
            app.mmhLabel = uilabel(app.Panel);
            app.mmhLabel.Text = '[mm/h]';
            app.PulsesignalPanel = uipanel(app.InputPanel);
            app.PulsesignalPanel.Title = 'Pulse signal';
            app.PulseKnobLabel = uilabel(app.PulsesignalPanel);
            app.PulseKnobLabel.Text = 'Pulse';
            app.PulseKnob = uiknob(app.PulsesignalPanel, 'continuous');
            app.PulseKnob.Limits = [0 1];
            app.PulseKnob.ValueChangedFcn = createCallbackFcn(app, @PulseKnobValueChanged, true);
            app.PulseKnob.Value = 0.1;
            app.PulseText = uieditfield(app.PulsesignalPanel, 'numeric');
            app.PulseText.ValueChangedFcn = createCallbackFcn(app, @PulseTextValueChanged, true);
            app.PulseText.Value = 0.1;
            app.sLabel_6 = uilabel(app.PulsesignalPanel);
            app.sLabel_6.Text = '[μs]';
            app.sLabel_4 = uilabel(app.InputPanel);
            app.sLabel_4.Text = '[μs]';
            app.DopplerPanel = uipanel(app.InputPanel);
            app.DopplerPanel.Title = 'Doppler';
            app.DSwitchLabel = uilabel(app.DopplerPanel);
            app.DSwitchLabel.Text = 'D.Switch';
            app.DSwitch = uiswitch(app.DopplerPanel, 'rocker');
            app.DSwitch.ValueChangedFcn = createCallbackFcn(app, @DSwitchValueChanged, true);
            app.DSwitch.Value = 'On';
            app.DSwitchText = uieditfield(app.DopplerPanel, 'text');
            app.DSwitchText.Value = 'On';
            app.InputDopplerFreqEditFieldLabel = uilabel(app.DopplerPanel);
            app.InputDopplerFreqEditFieldLabel.Text = 'Input Doppler Freq';
            app.InputDopplerFreqEditField = uieditfield(app.DopplerPanel, 'numeric');
            app.InputVrEditFieldLabel = uilabel(app.DopplerPanel);
            app.InputVrEditFieldLabel.Text = 'Input Vr';
            app.InputVrEditField = uieditfield(app.DopplerPanel, 'numeric');
            app.InputVrEditField.ValueChangedFcn = createCallbackFcn(app, @InputVrEditFieldValueChanged, true);
            app.MHzLabel_3 = uilabel(app.DopplerPanel);
            app.MHzLabel_3.Text = '[MHz]';
            app.msLabel = uilabel(app.DopplerPanel);
            app.msLabel.Text = '[m/μs]';
            app.MHzLabel_4 = uilabel(app.DopplerPanel);
            app.MHzLabel_4.Text = '[MHz]';
            app.OutputDopplerFreqEditFieldLabel = uilabel(app.DopplerPanel);
            app.OutputDopplerFreqEditFieldLabel.Text = 'Output Doppler Freq';
            app.OutputDopplerFreqEditField = uieditfield(app.DopplerPanel, 'numeric');
            app.InputVrEditFieldLabel_2 = uilabel(app.DopplerPanel);
            app.InputVrEditFieldLabel_2.Text = 'Output Vr';
            app.OutputVrEditField = uieditfield(app.DopplerPanel, 'numeric');
            app.msLabel_2 = uilabel(app.DopplerPanel);
            app.msLabel_2.Text = '[m/μs]';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.mLabel = uilabel(app.OutputPanel);
            app.mLabel.Text = '[m]';
            app.sampleLabel = uilabel(app.OutputPanel);
            app.sampleLabel.Text = '[sample]';
            app.sampleLabel_2 = uilabel(app.OutputPanel);
            app.sampleLabel_2.Text = '[sample]';
            app.Label = uilabel(app.OutputPanel);
            app.Label.Text = '[%]';
            app.DistancehatEditFieldLabel = uilabel(app.OutputPanel);
            app.DistancehatEditFieldLabel.Text = 'Distance(hat)';
            app.DistancehatEditField = uieditfield(app.OutputPanel, 'numeric');
            app.K1EditFieldLabel = uilabel(app.OutputPanel);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.OutputPanel, 'numeric');
            app.K2EditFieldLabel = uilabel(app.OutputPanel);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.OutputPanel, 'numeric');
            app.ErrorrateEditFieldLabel = uilabel(app.OutputPanel);
            app.ErrorrateEditFieldLabel.Text = 'Error rate';
            app.ErrorrateEditField = uieditfield(app.OutputPanel, 'numeric');
            app.StartButton = uibutton(app.OutputPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.OutputPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.sLabel = uilabel(app.OutputPanel);
            app.sLabel.Text = '[μs]';
            app.sLabel_2 = uilabel(app.OutputPanel);
            app.sLabel_2.Text = '[μs]';
            app.TransmittimeEditFieldLabel = uilabel(app.OutputPanel);
            app.TransmittimeEditFieldLabel.Text = 'Transmit time';
            app.TransmittimeEditField = uieditfield(app.OutputPanel, 'numeric');
            app.ReceivedtimeEditFieldLabel = uilabel(app.OutputPanel);
            app.ReceivedtimeEditFieldLabel.Text = 'Received time';
            app.ReceivedtimeEditField = uieditfield(app.OutputPanel, 'numeric');
            app.AttenuationEditFieldLabel = uilabel(app.OutputPanel);
            app.AttenuationEditFieldLabel.Text = 'Attenuation';
            app.AttenuationHatEditField = uieditfield(app.OutputPanel, 'numeric');
            app.dBLabel = uilabel(app.OutputPanel);
            app.dBLabel.Text = '[dB]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Correlation_final_190819

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end