classdef app1023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        EditField_3Label         matlab.ui.control.Label
        gradient                 matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        Velocity                 matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        mLabel                   matlab.ui.control.Label
        kmhLabel                 matlab.ui.control.Label
        signalButtonGroup        matlab.ui.container.ButtonGroup
        sinButton                matlab.ui.control.RadioButton
        cosButton                matlab.ui.control.RadioButton
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        HzLabel                  matlab.ui.control.Label
        ReadyButton              matlab.ui.control.StateButton
        StartButton              matlab.ui.control.StateButton
        ResetButton              matlab.ui.control.StateButton
        Label_2                  matlab.ui.control.Label
        Pathlength               matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        ResultVelocity           matlab.ui.control.NumericEditField
        kmhLabel_2               matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        WarningMessage           matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %*********** 기본 설정 (경로, 샘플 )*****************
            Vel = app.Velocity.Value; % 설정 속도
            pathlength = app.Pathlength.Value; % 경로 길이?
            delta_t = 0.1*(Vel/50); % 샘플 간격
            grad = app.gradient.Value; % 기울기
            y_intercept = app.yEditField.Value; % y절편 
            t = -0.5*pathlength : delta_t : 0.5*pathlength; % sample 하나 당 10ms
            
            y1 = app.gradient.Value*t+y_intercept; % 비행물체 경로?
            
            %************** 원 그리기 **********************
            r= app.EditField.Value; % 최대탐지거리
            xc= 0;
            yc = 0;
            theta = linspace(0,2*pi);
            x = r*cos(theta) + xc;
            y = r*sin(theta) + yc;
            
            plot(app.UIAxes,t,y1,x,y,0,0,'o'); % 직선 경로와 원 그림 그리기
            %hold on
            %plot (0,0,'o')
            
            
            %************** 탐지 거리 내 경로 파악 *****************
            dc_t = 1;
            for d = 1: (1/delta_t)*pathlength+1  % 샘플 하나하나 거리 비교위한 for문
    
                if sqrt(t(d)^2+y1(d)^2) > r
                  continue 
                elseif sqrt(t(d)^2+y1(d)^2) < r    % 30m안에 들어오는지 파악
                    d_c(dc_t) = t(d); % 30m 안에 들어오는 샘플을 d_c(d)에 저장
                    dc_t = dc_t+1;
                end
         
            end
            
            %*********** 레이더 주기에 맞춰서 점찍기 ( 초기에는 4초에 한바퀴, 빔폭 고려x) ****************
            r_f = 40; %레이더 주기 [s]
 
            for d= 1 : dc_t-1  % 4초에 하나씩 추출 하기 위한 for문 
                if rem(d,r_f) == 0
                     d_c1(d/r_f) = d_c(d);  % d_c1에 추출한 점 하나씩 저장
                end
            end
            
            %plot(d_c1,-2*d_c1+50,'-o','MarkerEdgeColor','r','MarkerIndices',1:1:length(d_c1)); % 여기에 추출한 점만 뽑아서 plot 하고 싶으나 잘 생각이 안남 .
            %hold on
            %plot (x,y);
            %hold on
            %plot (0,0,'o');
            %title('400ms 마다 관찰한 화면')
            
            %************** r1 r2 를 이용한 상대속도 구하기 *********************
            r1 = sqrt((d_c1(1)-d_c1(2))^2+(y1(t == d_c1(1))-y1(t == d_c1(2)))^2);
            v1 = r1/(r_f*0.1) ; %[m/s]
            app.ResultVelocity.Value = v1*3.6*(50/8.05); % [km/h]
            
            %************** 거리 구하고 맞춰서 신호 생성하기 ************ (자유공간손실 등등 고려 X)
            f = app.FrequencyEditField.Value; %[Mhz} -> PRI 1ms  
            A = app.AmplitudeEditField.Value; % Amplitude

            xt = A*cos(2*pi*f*t); % 원신호
            L_xt = length(xt); % 원신호의 길이
            rt = [ zeros(1,L_xt), xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end) , zeros(1,L_xt-33+3*L_xt) , xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end)]; % 신호구간 만들기
            rt_n = rt+ randn(1,length(rt))*1;
     
            plot(app.UIAxes2,(0:length(rt)-1)*delta_t, rt_n);
            %title('신호 (자유공간손실고려 X), 중간 신호 생략')
            
            ct1 = length(rt)-L_xt;
             for a = 0:ct1
                c(a+1) = rt(a+1:a+L_xt)*xt';
             end

            plot(app.UIAxes3,(0:length(c)-1)*delta_t, c);  


        end

        % Value changed function: ReadyButton
        function ReadyButtonValueChanged(app, event)
            value = app.ReadyButton.Value;


            %*********** 기본 설정 (경로, 샘플 )*****************
            Vel = app.Velocity.Value; % 설정 속도
            pathlength = app.Pathlength.Value; % 경로 길이?
            delta_t = 0.1*Vel/50; % 샘플 간격
            grad = app.gradient.Value; % 기울기
            y_intercept = app.yEditField.Value; % y절편 
            t = -0.5*pathlength : delta_t : 0.5*pathlength; % sample 하나 당 10ms
            
            y1 = app.gradient.Value*t+y_intercept; % 비행물체 경로?
            
            %************** 원 그리기 **********************
            r= app.EditField.Value; % 최대탐지거리
            xc= 0;
            yc = 0;
            theta = linspace(0,2*pi);
            x = r*cos(theta) + xc;
            y = r*sin(theta) + yc;
            
            plot(app.UIAxes,t,y1,x,y,0,0,'o'); % 직선 경로와 원 그림 그리기
            %hold on
            %plot (0,0,'o')
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;


            %*********** 기본 설정 (경로, 샘플 )*****************
            Vel = app.Velocity.Value; % 설정 속도
            pathlength = app.Pathlength.Value; % 경로 길이?
            delta_t = 0.1*Vel/50; % 샘플 간격
            grad = app.gradient.Value; % 기울기
            y_intercept = app.yEditField.Value; % y절편 
            t = -0.5*pathlength : delta_t : 0.5*pathlength; % sample 하나 당 10ms
            
            y1 = app.gradient.Value*t+y_intercept; % 비행물체 경로?
            
            %************** 원 그리기 **********************
            r= app.EditField.Value; % 최대탐지거리
            xc= 0;
            yc = 0;
            theta = linspace(0,2*pi);
            x = r*cos(theta) + xc;
            y = r*sin(theta) + yc;       
            
            %************** 탐지 거리 내 경로 파악 *****************
            dc_t = 1;
            for d = 1: (1/delta_t)*pathlength+1  % 샘플 하나하나 거리 비교위한 for문
    
                if sqrt(t(d)^2+y1(d)^2) > r
                  continue 
                elseif sqrt(t(d)^2+y1(d)^2) < r    % 30m안에 들어오는지 파악
                    d_c(dc_t) = t(d); % 30m 안에 들어오는 샘플을 d_c(d)에 저장
                    dc_t = dc_t+1;
                end
         
            end
            
            %*********** 레이더 주기에 맞춰서 점찍기 ( 초기에는 4초에 한바퀴, 빔폭 고려x) ****************
            r_f = 40; % 레이더 주기 [s] -> 샘플 
 
            for d= 1 : dc_t-1  % 4초에 하나씩 추출 하기 위한 for문 
                if rem(d,r_f) == 0
                     d_c1(d/r_f) = d_c(d);  % d_c1에 추출한 점 하나씩 저장
                end
            end
            
            plot(app.UIAxes,x,y,0,0,'o',d_c1,app.gradient.Value*d_c1+app.yEditField.Value,'-o','MarkerEdgeColor','r','MarkerIndices',1:1:length(d_c1));
            
            %************** r1 r2 를 이용한 상대속도 구하기 *********************
            r1 = sqrt((d_c1(1)-d_c1(2))^2+(y1(t == d_c1(1))-y1(t == d_c1(2)))^2);
            v1 = r1/(r_f*0.1); %[m/s]
            vc = (app.Velocity.Value/3.6)/v1;
            app.ResultVelocity.Value = v1*3.6*vc; % [km/h]
            
            %************** 거리 구하고 맞춰서 신호 생성하기 ************ (자유공간손실 등등 고려 X)
            f = app.FrequencyEditField.Value; %[Mhz} -> PRI 1ms  
            A = app.AmplitudeEditField.Value; % Amplitude

            xt = A*cos(2*pi*f*t); % 원신호
            L_xt = length(xt); % 원신호의 길이
            rt = [ zeros(1,L_xt), xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end) , zeros(1,L_xt-33+3*L_xt) , xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end)]; % 신호구간 만들기
            rt_n = rt+ randn(1,length(rt))*1;
     
            plot(app.UIAxes2,(0:length(rt)-1)*delta_t, rt_n);
            %title('신호 (자유공간손실고려 X), 중간 신호 생략')
            
            ct1 = length(rt)-L_xt;
             for a = 0:ct1
                c(a+1) = rt(a+1:a+L_xt)*xt';
             end

            plot(app.UIAxes3,(0:length(c)-1)*delta_t, c); 
            
            if  (50 <= app.ResultVelocity.Value)
                app.WarningMessage.Value = '즉시 회피';
            elseif  (30 <= app.ResultVelocity.Value) & (app.ResultVelocity.Value < 50) 
                app.WarningMessage.Value = '충돌 주의';
            else
                app.WarningMessage.Value = '범위 내 탐지';
            end
            
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;


            app.EditField.Value = 50;
            app.Velocity.Value = 50;
            app.Pathlength.Value = 50;
            app.gradient.Value = -2;
            app.yEditField.Value = 50;
            app.AmplitudeEditField.Value = 1;
            app.FrequencyEditField.Value = 1;
            app.ResultVelocity.Value = 0;
            app.WarningMessage.Value = 0;
            
            %*********** 기본 설정 (경로, 샘플 )*****************
            Vel = app.Velocity.Value; % 설정 속도
            pathlength = app.Pathlength.Value; % 경로 길이?
            delta_t = 0.1*(Vel/50); % 샘플 간격
            grad = app.gradient.Value; % 기울기
            y_intercept = app.yEditField.Value; % y절편 
            t = -0.5*pathlength : delta_t : 0.5*pathlength; % sample 하나 당 10ms
            
            y1 = app.gradient.Value*t+y_intercept; % 비행물체 경로?
            
            %************** 원 그리기 **********************
            r= app.EditField.Value; % 최대탐지거리
            xc= 0;
            yc = 0;
            theta = linspace(0,2*pi);
            x = r*cos(theta) + xc;
            y = r*sin(theta) + yc;
            
            plot(app.UIAxes,t,y1,x,y,0,0,'o'); % 직선 경로와 원 그림 그리기
            %hold on
            %plot (0,0,'o')
            
            
            %************** 탐지 거리 내 경로 파악 *****************
            dc_t = 1;
            for d = 1: (1/delta_t)*pathlength+1  % 샘플 하나하나 거리 비교위한 for문
    
                if sqrt(t(d)^2+y1(d)^2) > r
                  continue 
                elseif sqrt(t(d)^2+y1(d)^2) < r    % 30m안에 들어오는지 파악
                    d_c(dc_t) = t(d); % 30m 안에 들어오는 샘플을 d_c(d)에 저장
                    dc_t = dc_t+1;
                end
         
            end
            
            %*********** 레이더 주기에 맞춰서 점찍기 ( 초기에는 4초에 한바퀴, 빔폭 고려x) ****************
            r_f = 40; %레이더 주기 [s]
 
            for d= 1 : dc_t-1  % 4초에 하나씩 추출 하기 위한 for문 
                if rem(d,r_f) == 0
                     d_c1(d/r_f) = d_c(d);  % d_c1에 추출한 점 하나씩 저장
                end
            end
            
            %plot(d_c1,-2*d_c1+50,'-o','MarkerEdgeColor','r','MarkerIndices',1:1:length(d_c1)); % 여기에 추출한 점만 뽑아서 plot 하고 싶으나 잘 생각이 안남 .
            %hold on
            %plot (x,y);
            %hold on
            %plot (0,0,'o');
            %title('400ms 마다 관찰한 화면')
            
            %************** r1 r2 를 이용한 상대속도 구하기 *********************
            r1 = sqrt((d_c1(1)-d_c1(2))^2+(y1(t == d_c1(1))-y1(t == d_c1(2)))^2);
            v1 = r1/(r_f*10^-3) ; %[m/s]
            v_km = v1*3.6; % [km/h]
            
            %************** 거리 구하고 맞춰서 신호 생성하기 ************ (자유공간손실 등등 고려 X)
            f = app.FrequencyEditField.Value; %[Mhz} -> PRI 1ms  
            A = app.AmplitudeEditField.Value; % Amplitude

            xt = A*cos(2*pi*f*t); % 원신호
            L_xt = length(xt); % 원신호의 길이
            rt = [ zeros(1,L_xt), xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end) , zeros(1,L_xt-33+3*L_xt) , xt(1:33) , xt(34:end)+0.5*xt(1:L_xt-33),0.5*xt(L_xt-34:end)]; % 신호구간 만들기
            rt_n = rt+ randn(1,length(rt))*1;
     
            plot(app.UIAxes2,(0:length(rt)-1)*delta_t, rt_n);
            %title('신호 (자유공간손실고려 X), 중간 신호 생략')
            
            ct1 = length(rt)-L_xt;
             for a = 0:ct1
                c(a+1) = rt(a+1:a+L_xt)*xt';
             end

            plot(app.UIAxes3,(0:length(c)-1)*delta_t, c);  
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '비행물체경로 & 탐지범위')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '최대탐지거리';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 50;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '기울기';
            app.gradient = uieditfield(app.UIFigure, 'numeric');
            app.gradient.Value = -2;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '설정 속도';
            app.Velocity = uieditfield(app.UIFigure, 'numeric');
            app.Velocity.Value = 50;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y절편';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Value = 50;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            app.kmhLabel = uilabel(app.UIFigure);
            app.kmhLabel.Text = '[km/h]';
            app.signalButtonGroup = uibuttongroup(app.UIFigure);
            app.signalButtonGroup.Title = 'signal';
            app.sinButton = uiradiobutton(app.signalButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.signalButtonGroup);
            app.cosButton.Text = 'cos';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '[Hz]';
            app.ReadyButton = uibutton(app.UIFigure, 'state');
            app.ReadyButton.ValueChangedFcn = createCallbackFcn(app, @ReadyButtonValueChanged, true);
            app.ReadyButton.Text = 'Ready';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '경로 길이?';
            app.Pathlength = uieditfield(app.UIFigure, 'numeric');
            app.Pathlength.Value = 100;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '상대속도';
            app.ResultVelocity = uieditfield(app.UIFigure, 'numeric');
            app.kmhLabel_2 = uilabel(app.UIFigure);
            app.kmhLabel_2.Text = '[km/h]';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '경고메시지';
            app.WarningMessage = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end